classdef Accumulator < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        AccumulatorSetupUIFigure       matlab.ui.Figure
        AccumulatorPanel               matlab.ui.container.Panel
        MaxAccumulatorVoltageLabel     matlab.ui.control.Label
        NumberofCellsLabel             matlab.ui.control.Label
        OverallCellWeightLabel         matlab.ui.control.Label
        Copyright2021BalticRacingbyEricDorniedenLabel  matlab.ui.control.Label
        CellDataPanel                  matlab.ui.container.Panel
        FinalDischargeVoltageVEditField  matlab.ui.control.NumericEditField
        FinalDischargeVoltageVLabel    matlab.ui.control.Label
        ChargingEndVoltageVEditField   matlab.ui.control.NumericEditField
        ChargingEndVoltageVEditFieldLabel  matlab.ui.control.Label
        InternalResistanceEditField_2  matlab.ui.control.NumericEditField
        BatteryCapacitywhLabel         matlab.ui.control.Label
        SaveCellButton                 matlab.ui.control.Button
        DeleteCellButton               matlab.ui.control.Button
        SelectCellDropDown             matlab.ui.control.DropDown
        SelectCellDropDownLabel        matlab.ui.control.Label
        NominalVoltageVEditField       matlab.ui.control.NumericEditField
        NominalVoltageVEditFieldLabel  matlab.ui.control.Label
        CellWeightgEditField           matlab.ui.control.NumericEditField
        CellWeightgEditFieldLabel      matlab.ui.control.Label
        InternalResistanceEditField    matlab.ui.control.NumericEditField
        InternalResistanceLabel        matlab.ui.control.Label
        CellCapacitymAhEditField       matlab.ui.control.NumericEditField
        CellCapacitymAhEditFieldLabel  matlab.ui.control.Label
        OverallCapacityLabel           matlab.ui.control.Label
        CellsinseriesEditField         matlab.ui.control.NumericEditField
        CellsinseriesEditFieldLabel    matlab.ui.control.Label
        CellsinparallelEditField       matlab.ui.control.NumericEditField
        CellsinparallelEditFieldLabel  matlab.ui.control.Label
        ResetButton                    matlab.ui.control.Button
        SaveButton                     matlab.ui.control.Button
    end

    
    properties (Access = private)
        CallingApp   % Main app object
    end
    
    methods (Access = private)
        
        function saveData(app)
            app.CallingApp.nominalVoltage = app.NominalVoltageVEditField.Value;
            app.CallingApp.capacity_cell = app.CellCapacitymAhEditField.Value;
            app.CallingApp.nZellen_Parallel = app.CellsinparallelEditField.Value;
            app.CallingApp.nZellen_Reihe = app.CellsinseriesEditField.Value;
            
        end
        
        function loadData(app)
            app.NominalVoltageVEditField.Value = app.CallingApp.nominalVoltage;
            app.CellCapacitymAhEditField.Value = app.CallingApp.capacity_cell;
            app.CellsinparallelEditField.Value = app.CallingApp.nZellen_Parallel;
            app.CellsinseriesEditField.Value = app.CallingApp.nZellen_Reihe;
            %app.CapacityCellEditField.Value = app.CallingApp.capacity_cell;
        end
        
        function updateCapacity(app)
            % Calculates Overall Capacity of the accumulator in Wh
            numberofCells = app.CellsinseriesEditField.Value * app.CellsinparallelEditField.Value;
            cellWeight = numberofCells * app.CellWeightgEditField.Value;
            batteryCapacity = app.CellCapacitymAhEditField.Value * app.NominalVoltageVEditField.Value * numberofCells/ 1000;
            overall_capacity = numberofCells * batteryCapacity;
            accumulatorVoltage = app.CellsinseriesEditField.Value * app.ChargingEndVoltageVEditField.Value; 

            app.OverallCapacityLabel.Text = "Overall Capacity " + overall_capacity + " [Wh]"; 
            app.NumberofCellsLabel.Text = "Number of Cells " + numberofCells + " [-]";
            app.OverallCellWeightLabel.Text = "Overall Cell Weight " + cellWeight + " [g]";
            app.MaxAccumulatorVoltageLabel.Text = "Max. Accumulator Voltage " + accumulatorVoltage + " [V]";
            app.BatteryCapacitywhLabel.Text = "Battery Capacity " + batteryCapacity + " [Wh]";
        end
    end
    
    methods (Access = public)
        
        function changeColorScheme(app) 
            PrimaryColor = app.CallingApp.PrimaryColor;
            OnPrimaryColor = app.CallingApp.OnPrimaryColor;   
            PrimaryVariantColor = app.CallingApp.PrimaryVariantColor;
            OnPrimaryVariantColor = app.CallingApp.OnPrimaryVariantColor;
            SecondaryColor = app.CallingApp.SecondaryColor;
            OnSecondaryColor = app.CallingApp.OnSecondaryColor;
            BackgroundColor = app.CallingApp.BackgroundColor;
            OnBackgroundColor = app.CallingApp.OnBackgroundColor;
            SurfaceColor = app.CallingApp.SurfaceColor;
            OnSurfaceColor = app.CallingApp.OnSurfaceColor;
            ErrorColor = app.CallingApp.ErrorColor;
            OnErrorColor = app.CallingApp.OnErrorColor;
            BorderType = app.CallingApp.BorderType;                 
            Host_Logo = app.CallingApp.Host_Logo;
            
            app.AccumulatorSetupUIFigure.Color = BackgroundColor;
            
            app.Copyright2021BalticRacingbyEricDorniedenLabel.FontColor = OnBackgroundColor;
            
            app.AccumulatorPanel.ForegroundColor = OnSurfaceColor;
            app.AccumulatorPanel.BackgroundColor = SurfaceColor;
            app.AccumulatorPanel.BorderType = BorderType;
            
            app.NominalVoltageVEditFieldLabel.FontColor = OnPrimaryColor;
            app.NominalVoltageVEditField.BackgroundColor = SurfaceColor;
            app.NominalVoltageVEditField.FontColor = OnSurfaceColor;
            
            app.CellCapacitymAhEditFieldLabel.FontColor = OnPrimaryColor;
            app.CellCapacitymAhEditField.BackgroundColor = SurfaceColor;
            app.CellCapacitymAhEditField.FontColor = OnSurfaceColor;
            
            app.CellsinparallelEditFieldLabel.FontColor = OnPrimaryColor;
            app.CellsinparallelEditField.BackgroundColor = SurfaceColor;
            app.CellsinparallelEditField.FontColor = OnSurfaceColor;
            
            app.CellsinseriesEditFieldLabel.FontColor = OnPrimaryColor;
            app.CellsinseriesEditField.BackgroundColor = SurfaceColor;
            app.CellsinseriesEditField.FontColor = OnSurfaceColor;            
            
            app.OverallCapacityLabel.FontColor = OnPrimaryColor;
            
            app.ResetButton.FontColor = OnPrimaryColor;
            app.ResetButton.BackgroundColor = PrimaryVariantColor;
            
            app.SaveButton.FontColor = OnPrimaryColor;
            app.SaveButton.BackgroundColor = PrimaryVariantColor;

            app.DeleteCellButton.FontColor = OnPrimaryColor;
            app.DeleteCellButton.BackgroundColor = PrimaryVariantColor;

            app.SaveCellButton.FontColor = OnPrimaryColor;
            app.SaveCellButton.BackgroundColor = PrimaryVariantColor;

            app.NumberofCellsLabel.FontColor = OnPrimaryColor;
            app.OverallCellWeightLabel.FontColor = OnPrimaryColor;
            app.MaxAccumulatorVoltageLabel.FontColor = OnPrimaryColor;

            app.ChargingEndVoltageVEditFieldLabel.FontColor = OnPrimaryColor;
            app.ChargingEndVoltageVEditField.BackgroundColor = SurfaceColor;
            app.ChargingEndVoltageVEditField.FontColor = OnSurfaceColor;

            app.FinalDischargeVoltageVLabel.FontColor = OnPrimaryColor;
            app.FinalDischargeVoltageVEditField.BackgroundColor = SurfaceColor;
            app.FinalDischargeVoltageVEditField.FontColor = OnSurfaceColor;

            app.InternalResistanceLabel.FontColor = OnPrimaryColor;
            app.InternalResistanceEditField.BackgroundColor = SurfaceColor;
            app.InternalResistanceEditField.FontColor = OnSurfaceColor;

            app.InternalResistanceEditField_2.BackgroundColor = SurfaceColor;
            app.InternalResistanceEditField_2.FontColor = OnSurfaceColor;

            app.CellWeightgEditFieldLabel.FontColor = OnPrimaryColor;
            app.CellWeightgEditField.BackgroundColor = SurfaceColor;
            app.CellWeightgEditField.FontColor = OnSurfaceColor;

            app.SelectCellDropDownLabel.FontColor = OnPrimaryColor;
            app.SelectCellDropDown.BackgroundColor = PrimaryColor;
            app.SelectCellDropDown.FontColor = OnPrimaryColor;

            app.BatteryCapacitywhLabel.FontColor = OnPrimaryColor;

            app.CellDataPanel.ForegroundColor = OnSurfaceColor;
            app.CellDataPanel.BackgroundColor = SurfaceColor;
                
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            % Store main app object
            app.CallingApp = mainapp;
            
            % Loads the Data from the mainapp
            loadData(app);       
            
            % updates the overall capacity of the accumulator
            updateCapacity(app);

            changeColorScheme(app);
        end

        % Close request function: AccumulatorSetupUIFigure
        function AccumulatorSetupUIFigureCloseRequest(app, event)
            saveData(app);
            
            % Disable Accumulator Options button while dialog is open
            app.CallingApp.AccumulatorButton.Enable = 'on';
            
            delete(app)      
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            saveData(app);
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            % Loads the Data from the mainapp
            loadData(app);
        end

        % Value changed function: CellsinseriesEditField
        function CellsinseriesEditFieldValueChanged(app, event)
            updateCapacity(app);
        end

        % Callback function
        function CapacityCellEditFieldValueChanged(app, event)
            updateCapacity(app);
        end

        % Value changed function: CellsinparallelEditField
        function CellsinparallelEditFieldValueChanged(app, event)
            updateCapacity(app);
        end

        % Button pushed function: SaveCellButton
        function SaveCellButtonPushed(app, event)
            prompt = {'Enter cell name'};
            dlgtitle = 'Input';
            dims = [1 35];
            definput = {'Name'};
            answer = inputdlg(prompt,dlgtitle,dims,definput)
        end

        % Value changed function: NominalVoltageVEditField
        function NominalVoltageVEditFieldValueChanged(app, event)
            updateCapacity(app);        
        end

        % Value changed function: CellCapacitymAhEditField
        function CellCapacitymAhEditFieldValueChanged(app, event)
            updateCapacity(app);   
        end

        % Value changed function: CellWeightgEditField
        function CellWeightgEditFieldValueChanged(app, event)
            updateCapacity(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.AccumulatorSetupUIFigure = uifigure('Visible', 'off');
            app.AccumulatorSetupUIFigure.Name = 'Accumulator Setup';
            app.AccumulatorSetupUIFigure.Icon = 'Baltic.png';
            app.AccumulatorSetupUIFigure.CloseRequestFcn = createCallbackFcn(app, @AccumulatorSetupUIFigureCloseRequest, true);
            app.AccumulatorPanel = uipanel(app.AccumulatorSetupUIFigure);
            app.AccumulatorPanel.Title = 'Accumulator';
            app.SaveButton = uibutton(app.AccumulatorPanel, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.ResetButton = uibutton(app.AccumulatorPanel, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.CellsinparallelEditFieldLabel = uilabel(app.AccumulatorPanel);
            app.CellsinparallelEditFieldLabel.Text = 'Cells in parallel';
            app.CellsinparallelEditField = uieditfield(app.AccumulatorPanel, 'numeric');
            app.CellsinparallelEditField.ValueChangedFcn = createCallbackFcn(app, @CellsinparallelEditFieldValueChanged, true);
            app.CellsinparallelEditField.Value = 5;
            app.CellsinseriesEditFieldLabel = uilabel(app.AccumulatorPanel);
            app.CellsinseriesEditFieldLabel.Text = 'Cells in series';
            app.CellsinseriesEditField = uieditfield(app.AccumulatorPanel, 'numeric');
            app.CellsinseriesEditField.ValueChangedFcn = createCallbackFcn(app, @CellsinseriesEditFieldValueChanged, true);
            app.CellsinseriesEditField.Value = 131;
            app.OverallCapacityLabel = uilabel(app.AccumulatorPanel);
            app.OverallCapacityLabel.Text = 'Overall Capacity';
            app.CellDataPanel = uipanel(app.AccumulatorPanel);
            app.CellDataPanel.Title = 'Cell Data';
            app.CellCapacitymAhEditFieldLabel = uilabel(app.CellDataPanel);
            app.CellCapacitymAhEditFieldLabel.Text = 'Cell Capacity [mAh]';
            app.CellCapacitymAhEditField = uieditfield(app.CellDataPanel, 'numeric');
            app.CellCapacitymAhEditField.ValueChangedFcn = createCallbackFcn(app, @CellCapacitymAhEditFieldValueChanged, true);
            app.CellCapacitymAhEditField.Value = 3000;
            app.InternalResistanceLabel = uilabel(app.CellDataPanel);
            app.InternalResistanceLabel.Text = 'Internal Resistance [Ω]';
            app.InternalResistanceEditField = uieditfield(app.CellDataPanel, 'numeric');
            app.InternalResistanceEditField.Value = 0.021;
            app.CellWeightgEditFieldLabel = uilabel(app.CellDataPanel);
            app.CellWeightgEditFieldLabel.Text = 'Cell Weight [g]';
            app.CellWeightgEditField = uieditfield(app.CellDataPanel, 'numeric');
            app.CellWeightgEditField.ValueChangedFcn = createCallbackFcn(app, @CellWeightgEditFieldValueChanged, true);
            app.CellWeightgEditField.Value = 46.6;
            app.NominalVoltageVEditFieldLabel = uilabel(app.CellDataPanel);
            app.NominalVoltageVEditFieldLabel.Text = 'Nominal Voltage [V]';
            app.NominalVoltageVEditField = uieditfield(app.CellDataPanel, 'numeric');
            app.NominalVoltageVEditField.ValueChangedFcn = createCallbackFcn(app, @NominalVoltageVEditFieldValueChanged, true);
            app.NominalVoltageVEditField.Value = 3.6;
            app.SelectCellDropDownLabel = uilabel(app.CellDataPanel);
            app.SelectCellDropDownLabel.Text = 'Select Cell';
            app.SelectCellDropDown = uidropdown(app.CellDataPanel);
            app.SelectCellDropDown.Items = {'VTC6'};
            app.SelectCellDropDown.Value = 'VTC6';
            app.DeleteCellButton = uibutton(app.CellDataPanel, 'push');
            app.DeleteCellButton.Tooltip = {'Deletes the currently selected Cell if more than one Cell type is remaining.'};
            app.DeleteCellButton.Text = 'Delete Cell';
            app.SaveCellButton = uibutton(app.CellDataPanel, 'push');
            app.SaveCellButton.ButtonPushedFcn = createCallbackFcn(app, @SaveCellButtonPushed, true);
            app.SaveCellButton.Text = 'Save Cell';
            app.BatteryCapacitywhLabel = uilabel(app.CellDataPanel);
            app.BatteryCapacitywhLabel.Text = 'Battery Capacity [wh]';
            app.InternalResistanceEditField_2 = uieditfield(app.CellDataPanel, 'numeric');
            app.InternalResistanceEditField_2.Value = 0.021;
            app.ChargingEndVoltageVEditFieldLabel = uilabel(app.CellDataPanel);
            app.ChargingEndVoltageVEditFieldLabel.Text = 'Charging-End-Voltage [V]';
            app.ChargingEndVoltageVEditField = uieditfield(app.CellDataPanel, 'numeric');
            app.ChargingEndVoltageVEditField.Value = 4.2;
            app.FinalDischargeVoltageVLabel = uilabel(app.CellDataPanel);
            app.FinalDischargeVoltageVLabel.Text = 'Final-Discharge-Voltage [V]';
            app.FinalDischargeVoltageVEditField = uieditfield(app.CellDataPanel, 'numeric');
            app.FinalDischargeVoltageVEditField.Value = 2.5;
            app.Copyright2021BalticRacingbyEricDorniedenLabel = uilabel(app.AccumulatorPanel);
            app.Copyright2021BalticRacingbyEricDorniedenLabel.Text = 'Copyright © 2021 Baltic Racing by Eric Dornieden';
            app.OverallCellWeightLabel = uilabel(app.AccumulatorPanel);
            app.OverallCellWeightLabel.Text = 'Overall Cell Weight';
            app.NumberofCellsLabel = uilabel(app.AccumulatorPanel);
            app.NumberofCellsLabel.Text = 'Number of Cells';
            app.MaxAccumulatorVoltageLabel = uilabel(app.AccumulatorPanel);
            app.MaxAccumulatorVoltageLabel.Text = 'Max. Accumulator Voltage';
            % Show the figure after all components are created
            app.AccumulatorSetupUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Accumulator(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.AccumulatorSetupUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.AccumulatorSetupUIFigure)
        end
    end
end