classdef Aerodynamik < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        AerodynamicSetupUIFigure      matlab.ui.Figure
        AeroParametersPanel           matlab.ui.container.Panel
        Copyright2021BalticRacingbyEricDorniedenLabel  matlab.ui.control.Label
        ConstantDownforceNEditField   matlab.ui.control.NumericEditField
        ConstantDownforceNEditFieldLabel  matlab.ui.control.Label
        SimulatewithDRSPanel          matlab.ui.container.Panel
        DRSRadiusEditField            matlab.ui.control.NumericEditField
        DRSRadiusEditFieldLabel       matlab.ui.control.Label
        clopenDRSEditField            matlab.ui.control.NumericEditField
        clopenDRSEditFieldLabel       matlab.ui.control.Label
        DRSSwitch                     matlab.ui.control.Switch
        DRSCheckBox                   matlab.ui.control.CheckBox
        DragCoeffc_wEditField         matlab.ui.control.NumericEditField
        DRSefficiencyEditField        matlab.ui.control.NumericEditField
        LDLabel                       matlab.ui.control.Label
        PlotAeroDataButton            matlab.ui.control.Button
        AeroLoadFrontEditField        matlab.ui.control.NumericEditField
        AeroLoadFrontLabel            matlab.ui.control.Label
        ResetButton                   matlab.ui.control.Button
        clEditField                   matlab.ui.control.NumericEditField
        clEditFieldLabel              matlab.ui.control.Label
        SaveButton                    matlab.ui.control.Button
        DownforcemultiplierEditField  matlab.ui.control.NumericEditField
        DownforcemultiplierEditFieldLabel  matlab.ui.control.Label
        Button_2                      matlab.ui.control.Button
        Button                        matlab.ui.control.Button
        UITable                       matlab.ui.control.Table
        AmEditField                   matlab.ui.control.NumericEditField
        AmEditFieldLabel              matlab.ui.control.Label
        cdEditField                   matlab.ui.control.NumericEditField
        cdEditFieldLabel              matlab.ui.control.Label
    end

    
    properties (Access = private)
        CallingApp   % Main app object
    end
    
    methods (Access = private)
        
        function saveData(app)
            app.CallingApp.c_w = app.cdEditField.Value;
            app.CallingApp.c_l = app.clEditField.Value;
            app.CallingApp.A = app.AmEditField.Value;
            app.CallingApp.downforce_multiplier = app.DownforcemultiplierEditField.Value;      
            app.CallingApp.aero_pv = app.AeroLoadFrontEditField.Value;
            app.CallingApp.DRS = app.DRSCheckBox.Value;
            value = app.DRSSwitch.Value;
            if value == "cd"
               app.CallingApp.c_d_DRS = app.DragCoeffc_wEditField.Value;
            else
               app.CallingApp.c_d_DRS = app.cdEditField.Value-(app.cdEditField.Value*app.DRSefficiencyEditField.Value);
            end
            app.CallingApp.c_l_DRS = app.clopenDRSEditField.Value;
            app.CallingApp.ConstantDownforce = app.ConstantDownforceNEditField.Value;
            app.CallingApp.DRS_Radius = app.DRSRadiusEditField.Value;
        end
        
        function loadData(app)
             % Loads Values from Mainapp
            app.cdEditField.Value = app.CallingApp.c_w;
            app.clEditField.Value = app.CallingApp.c_l;
            app.AmEditField.Value = app.CallingApp.A;
            app.DownforcemultiplierEditField.Value = app.CallingApp.downforce_multiplier; 
            app.AeroLoadFrontEditField.Value = app.CallingApp.aero_pv;
            app.DRSCheckBox.Value = app.CallingApp.DRS;
            app.DragCoeffc_wEditField.Value = app.CallingApp.c_d_DRS;
            app.clopenDRSEditField.Value = app.CallingApp.c_l_DRS;
            app.ConstantDownforceNEditField.Value = app.CallingApp.ConstantDownforce;
            app.DRSRadiusEditField.Value = app.CallingApp.DRS_Radius;
            
            if app.DRSCheckBox.Value
                DRSUpdateCheckBox(app)
            end
        end
        
        
        function updateLD(app)
            app.LDLabel.Text = "L/D      " + num2str(app.clEditField.Value*app.DownforcemultiplierEditField.Value/app.cdEditField.Value);
        end
        
        function DRSUpdateCheckBox(app)
            % Enable/Disable the DRS Edit fields
            value = app.DRSCheckBox.Value;
            if value
                app.DRSSwitch.Enable = true;
                app.clopenDRSEditField.Enable = true;
                app.clopenDRSEditFieldLabel.Enable = true;
                app.DRSRadiusEditField.Enable = true;
                app.DRSRadiusEditFieldLabel.Enable = true;
                DRSSwitchValueChanged(app);
            else
                 app.DRSSwitch.Enable = false;
                 app.DragCoeffc_wEditField.Enable = false;
                 app.DRSefficiencyEditField.Enable = false;
                 app.clopenDRSEditField.Enable = false;
                 app.clopenDRSEditFieldLabel.Enable = false;
                 app.DRSRadiusEditField.Enable = false;
                 app.DRSRadiusEditFieldLabel.Enable = false;
            end
        end
        
        function updateUITable(app)
            data = app.UITable.Data;
            
            rho_L = app.CallingApp.p_L/(app.CallingApp.R_L*(app.CallingApp.t_L+273.15));
            
            A = app.AmEditField.Value;
            c_d = app.cdEditField.Value;
            c_l = app.clEditField.Value;
            downforce_multiplier = app.DownforcemultiplierEditField.Value;
            aero_pv = app.AeroLoadFrontEditField.Value;
            
            for i = 1:size(data,1)
                data(i,2) = downforce_multiplier * c_l * A * rho_L * data(i,1)^2 / 2;
                data(i,3) = rho_L*data(i,1)^2/2*c_d*A;
            end
            
            app.UITable.Data = data;
        end
    end
    
    methods (Access = public)
        
        function changeColorScheme(app)
            PrimaryColor = app.CallingApp.PrimaryColor;
            OnPrimaryColor = app.CallingApp.OnPrimaryColor;   
            PrimaryVariantColor = app.CallingApp.PrimaryVariantColor;
            OnPrimaryVariantColor = app.CallingApp.OnPrimaryVariantColor;
            SecondaryColor = app.CallingApp.SecondaryColor;
            OnSecondaryColor = app.CallingApp.OnSecondaryColor;
            BackgroundColor = app.CallingApp.BackgroundColor;
            OnBackgroundColor = app.CallingApp.OnBackgroundColor;
            SurfaceColor = app.CallingApp.SurfaceColor;
            OnSurfaceColor = app.CallingApp.OnSurfaceColor;
            ErrorColor = app.CallingApp.ErrorColor;
            OnErrorColor = app.CallingApp.OnErrorColor;
            BorderType = app.CallingApp.BorderType;                 
            Host_Logo = app.CallingApp.Host_Logo;
            
            app.AerodynamicSetupUIFigure.Color = BackgroundColor;
            
            app.Copyright2021BalticRacingbyEricDorniedenLabel.FontColor = OnBackgroundColor;
            
            app.AeroParametersPanel.BackgroundColor = BackgroundColor;
            app.AeroParametersPanel.ForegroundColor = OnSurfaceColor;
            app.AeroParametersPanel.BorderType = BorderType;
            
            app.ResetButton.FontColor = OnPrimaryColor;
            app.ResetButton.BackgroundColor = PrimaryVariantColor;
            
            app.SaveButton.FontColor = OnPrimaryColor;
            app.SaveButton.BackgroundColor = PrimaryVariantColor;
            
            app.PlotAeroDataButton.FontColor = OnPrimaryColor;
            app.PlotAeroDataButton.BackgroundColor = PrimaryVariantColor;
            
            app.AmEditFieldLabel.FontColor = OnPrimaryColor;
            app.AmEditField.BackgroundColor = SurfaceColor;
            app.AmEditField.FontColor = OnSurfaceColor;
            
            app.cdEditFieldLabel.FontColor = OnPrimaryColor;
            app.cdEditField.BackgroundColor = SurfaceColor;
            app.cdEditField.FontColor = OnSurfaceColor;
            
            app.clEditFieldLabel.FontColor = OnPrimaryColor;
            app.clEditField.BackgroundColor = SurfaceColor;
            app.clEditField.FontColor = OnSurfaceColor;
            
            app.DownforcemultiplierEditFieldLabel.FontColor = OnPrimaryColor;
            app.DownforcemultiplierEditField.BackgroundColor = SurfaceColor;
            app.DownforcemultiplierEditField.FontColor = OnSurfaceColor;
            
            app.AeroLoadFrontLabel.FontColor = OnPrimaryColor;
            app.AeroLoadFrontEditField.BackgroundColor = SurfaceColor;
            app.AeroLoadFrontEditField.FontColor = OnSurfaceColor;
            
            app.ConstantDownforceNEditFieldLabel.FontColor = OnPrimaryColor;
            app.ConstantDownforceNEditField.BackgroundColor = SurfaceColor;
            app.ConstantDownforceNEditField.FontColor = OnSurfaceColor;
            
            app.DragCoeffc_wEditField.FontColor = OnPrimaryColor;
            app.DragCoeffc_wEditField.BackgroundColor = SurfaceColor;
            app.DRSefficiencyEditField.FontColor = OnPrimaryColor;
            app.DRSefficiencyEditField.BackgroundColor = SurfaceColor;
            
            app.clopenDRSEditFieldLabel.FontColor = OnPrimaryColor;
            app.clopenDRSEditField.BackgroundColor = SurfaceColor;
            app.clopenDRSEditField.FontColor = OnSurfaceColor;
            
            app.DRSRadiusEditFieldLabel.FontColor = OnPrimaryColor;
            app.DRSRadiusEditField.BackgroundColor = SurfaceColor;
            app.DRSRadiusEditField.FontColor = OnSurfaceColor;
            
            app.SimulatewithDRSPanel.BackgroundColor = SurfaceColor;
            app.SimulatewithDRSPanel.ForegroundColor = OnSurfaceColor;
            app.SimulatewithDRSPanel.BorderType = BorderType;
            
            app.DRSCheckBox.FontColor = OnPrimaryColor;
            app.LDLabel.FontColor = OnPrimaryColor;
            
            app.DRSSwitch.FontColor = OnPrimaryColor;
            
            app.Button.FontColor = OnPrimaryColor;
            app.Button.BackgroundColor = PrimaryVariantColor;
            app.Button_2.FontColor = OnPrimaryColor;
            app.Button_2.BackgroundColor = PrimaryVariantColor;
            
            if app.CallingApp.ColorSchemeID
                app.UITable.BackgroundColor = [0.502 0.502 0.502;0.149 0.149 0.149];
                app.UITable.ForegroundColor = "white";
            else
                app.UITable.BackgroundColor = [1.00 1.00 1.00;0.94 0.94 0.94];
                app.UITable.ForegroundColor = "black";
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            % Store main app object
            app.CallingApp = mainapp;    
            
            % DRS Enabler/Disabler
            DRSUpdateCheckBox(app);
            
            % Loads the data from the mainapp
            loadData(app);
            
            % Update Lift to Drag ratio
            updateLD(app);
            
            % Set UITable start Data
            app.UITable.Data = [5 0 0; 10 0 0;13.889 0 0; 15 0 0; 20 0 0; 25 0 0; 27.778 0 0];
            updateUITable(app);
            
            % Update Color Scheme
            changeColorScheme(app);
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            % Adds an entry to the UITable
            data = app.UITable.Data;
            app.UITable.Data = [data; [0 0 0]];
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            % Deletes the last entry in the UITable
            data = app.UITable.Data;
            data((size(data,1)),:) = [];
            app.UITable.Data = data;
        end

        % Close request function: AerodynamicSetupUIFigure
        function AerodynamicSetupUIFigureCloseRequest(app, event)
            % Saves all entered data
            saveData(app);
            
            % Disable Aerodynamik Options button while dialog is open
            app.CallingApp.AerodynamikButton.Enable = 'on';
            
            delete(app)            
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            saveData(app);
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            loadData(app);
        end

        % Button pushed function: PlotAeroDataButton
        function PlotAeroDataButtonPushed(app, event)
            
            A = app.AmEditField.Value;
            c_w = app.cdEditField.Value;
            c_l = app.clEditField.Value;
            downforce_multiplier = app.DownforcemultiplierEditField.Value;
            aero_pv = app.AeroLoadFrontEditField.Value;
            
            plotAeroData(A,c_w,c_l,downforce_multiplier,aero_pv, app.CallingApp.t_L, app.CallingApp.p_L, app.CallingApp.R_L);
        end

        % Value changed function: cdEditField
        function cdEditFieldValueChanged(app, event)
            app.updateLD();   
            updateUITable(app);
        end

        % Value changed function: clEditField
        function clEditFieldValueChanged(app, event)
            app.updateLD();      
            updateUITable(app);
        end

        % Value changed function: DRSSwitch
        function DRSSwitchValueChanged(app, event)
            value = app.DRSSwitch.Value;
            if  value == "cd"
             app.DragCoeffc_wEditField.Enable = true;
             app.DRSefficiencyEditField.Enable = false;
            else
             app.DragCoeffc_wEditField.Enable = false;
             app.DRSefficiencyEditField.Enable = true;
            end
        end

        % Value changed function: DRSCheckBox
        function DRSCheckBoxValueChanged(app, event)
            DRSUpdateCheckBox(app)
        end

        % Callback function
        function UpdateTableButtonPushed(app, event)
            data = app.UITable.Data;
            
            rho_L = app.CallingApp.p_L/(app.CallingApp.R_L*(app.CallingApp.t_L+273.15));
            
            A = app.AmEditField.Value;
            c_d = app.cdEditField.Value;
            c_l = app.clEditField.Value;
            downforce_multiplier = app.DownforcemultiplierEditField.Value;
            aero_pv = app.AeroLoadFrontEditField.Value;
            
            for i = 1:size(data,1)
                data(i,2) = downforce_multiplier * c_l * A * rho_L * data(i,1)^2 / 2;
                data(i,3) = rho_L*data(i,1)^2/2*c_d*A;
            end
            
            app.UITable.Data = data;
        end

        % Cell edit callback: UITable
        function UITableCellEdit(app, event)
            updateUITable(app);
        end

        % Value changed function: AmEditField
        function AmEditFieldValueChanged(app, event)
            updateUITable(app);
        end

        % Value changed function: DownforcemultiplierEditField
        function DownforcemultiplierEditFieldValueChanged(app, event)
            app.updateLD();
            updateUITable(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.AerodynamicSetupUIFigure = uifigure('Visible', 'off');
            app.AerodynamicSetupUIFigure.Name = 'Aerodynamic Setup';
            app.AerodynamicSetupUIFigure.Icon = 'Baltic.png';
            app.AerodynamicSetupUIFigure.CloseRequestFcn = createCallbackFcn(app, @AerodynamicSetupUIFigureCloseRequest, true);
            app.AeroParametersPanel = uipanel(app.AerodynamicSetupUIFigure);
            app.AeroParametersPanel.Title = 'Aero Parameters';
            app.cdEditFieldLabel = uilabel(app.AeroParametersPanel);
            app.cdEditFieldLabel.Text = 'cd [-]';
            app.cdEditField = uieditfield(app.AeroParametersPanel, 'numeric');
            app.cdEditField.ValueChangedFcn = createCallbackFcn(app, @cdEditFieldValueChanged, true);
            app.AmEditFieldLabel = uilabel(app.AeroParametersPanel);
            app.AmEditFieldLabel.Text = 'A [m²]';
            app.AmEditField = uieditfield(app.AeroParametersPanel, 'numeric');
            app.AmEditField.ValueChangedFcn = createCallbackFcn(app, @AmEditFieldValueChanged, true);
            app.UITable = uitable(app.AeroParametersPanel);
            app.UITable.ColumnName = {'Speed [m/s]'; 'Downforce [N]'; 'Drag [N]'};
            app.UITable.RowName = {};
            app.UITable.ColumnEditable = [true false false];
            app.UITable.CellEditCallback = createCallbackFcn(app, @UITableCellEdit, true);
            app.Button = uibutton(app.AeroParametersPanel, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '+';
            app.Button_2 = uibutton(app.AeroParametersPanel, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = '-';
            app.DownforcemultiplierEditFieldLabel = uilabel(app.AeroParametersPanel);
            app.DownforcemultiplierEditFieldLabel.Text = 'Downforce multiplier [-]';
            app.DownforcemultiplierEditField = uieditfield(app.AeroParametersPanel, 'numeric');
            app.DownforcemultiplierEditField.Limits = [0 Inf];
            app.DownforcemultiplierEditField.ValueChangedFcn = createCallbackFcn(app, @DownforcemultiplierEditFieldValueChanged, true);
            app.DownforcemultiplierEditField.Value = 1;
            app.SaveButton = uibutton(app.AeroParametersPanel, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.clEditFieldLabel = uilabel(app.AeroParametersPanel);
            app.clEditFieldLabel.Text = 'cl [-]';
            app.clEditField = uieditfield(app.AeroParametersPanel, 'numeric');
            app.clEditField.ValueChangedFcn = createCallbackFcn(app, @clEditFieldValueChanged, true);
            app.ResetButton = uibutton(app.AeroParametersPanel, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.AeroLoadFrontLabel = uilabel(app.AeroParametersPanel);
            app.AeroLoadFrontLabel.Text = 'Aero Load Front [-]';
            app.AeroLoadFrontEditField = uieditfield(app.AeroParametersPanel, 'numeric');
            app.AeroLoadFrontEditField.Limits = [0 1];
            app.AeroLoadFrontEditField.Value = 0.5;
            app.PlotAeroDataButton = uibutton(app.AeroParametersPanel, 'push');
            app.PlotAeroDataButton.ButtonPushedFcn = createCallbackFcn(app, @PlotAeroDataButtonPushed, true);
            app.PlotAeroDataButton.Text = 'Plot Aero Data';
            app.LDLabel = uilabel(app.AeroParametersPanel);
            app.LDLabel.Text = 'L/D';
            app.SimulatewithDRSPanel = uipanel(app.AeroParametersPanel);
            app.SimulatewithDRSPanel.Title = 'Simulate with DRS';
            app.DRSefficiencyEditField = uieditfield(app.SimulatewithDRSPanel, 'numeric');
            app.DRSefficiencyEditField.Limits = [0 1];
            app.DRSefficiencyEditField.Enable = 'off';
            app.DragCoeffc_wEditField = uieditfield(app.SimulatewithDRSPanel, 'numeric');
            app.DragCoeffc_wEditField.Limits = [0 Inf];
            app.DRSCheckBox = uicheckbox(app.SimulatewithDRSPanel);
            app.DRSCheckBox.ValueChangedFcn = createCallbackFcn(app, @DRSCheckBoxValueChanged, true);
            app.DRSCheckBox.Text = 'DRS';
            app.DRSSwitch = uiswitch(app.SimulatewithDRSPanel, 'slider');
            app.DRSSwitch.Items = {'efficiency', 'cd'};
            app.DRSSwitch.ValueChangedFcn = createCallbackFcn(app, @DRSSwitchValueChanged, true);
            app.DRSSwitch.Value = 'cd';
            app.clopenDRSEditFieldLabel = uilabel(app.SimulatewithDRSPanel);
            app.clopenDRSEditFieldLabel.Text = 'cl open DRS';
            app.clopenDRSEditField = uieditfield(app.SimulatewithDRSPanel, 'numeric');
            app.clopenDRSEditField.Limits = [0 Inf];
            app.DRSRadiusEditFieldLabel = uilabel(app.SimulatewithDRSPanel);
            app.DRSRadiusEditFieldLabel.Text = 'DRS Radius';
            app.DRSRadiusEditField = uieditfield(app.SimulatewithDRSPanel, 'numeric');
            app.DRSRadiusEditField.Limits = [0 Inf];
            app.DRSRadiusEditField.Value = 300;
            app.ConstantDownforceNEditFieldLabel = uilabel(app.AeroParametersPanel);
            app.ConstantDownforceNEditFieldLabel.Tooltip = {'Constant Downforce value in newton (for example groundeffect fan)'};
            app.ConstantDownforceNEditFieldLabel.Text = 'Constant Downforce [N]';
            app.ConstantDownforceNEditField = uieditfield(app.AeroParametersPanel, 'numeric');
            app.Copyright2021BalticRacingbyEricDorniedenLabel = uilabel(app.AeroParametersPanel);
            app.Copyright2021BalticRacingbyEricDorniedenLabel.Text = 'Copyright © 2021 Baltic Racing by Eric Dornieden';
            % Show the figure after all components are created
            app.AerodynamicSetupUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Aerodynamik(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.AerodynamicSetupUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.AerodynamicSetupUIFigure)
        end
    end
end