classdef Chassis < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ChassisSetupUIFigure           matlab.ui.Figure
        WeightsPanel                   matlab.ui.container.Panel
        Image2                         matlab.ui.control.Image
        Copyright2021BalticRacingbyEricDorniedenLabel  matlab.ui.control.Label
        ResetButton                    matlab.ui.control.Button
        SaveButton                     matlab.ui.control.Button
        COGPanel                       matlab.ui.container.Panel
        x_vaEditField                  matlab.ui.control.NumericEditField
        x_vammEditFieldLabel           matlab.ui.control.Label
        x_cogEditField                 matlab.ui.control.NumericEditField
        x_cogmmEditFieldLabel          matlab.ui.control.Label
        h_cogEditField                 matlab.ui.control.NumericEditField
        h_cogmmEditFieldLabel          matlab.ui.control.Label
        BallastPanel                   matlab.ui.container.Panel
        COGheightBallastEditField      matlab.ui.control.NumericEditField
        COGheightmmEditField_2Label    matlab.ui.control.Label
        COGlongBallastEditField        matlab.ui.control.NumericEditField
        COGlongmmEditField_2Label      matlab.ui.control.Label
        WeightBallastEditField         matlab.ui.control.NumericEditField
        WeightkgEditField_2Label       matlab.ui.control.Label
        MomentofInertiaPanel           matlab.ui.container.Panel
        Image                          matlab.ui.control.Image
        YawMomentofInertiaEditField    matlab.ui.control.NumericEditField
        YawMomentofInertiazkgmEditFieldLabel  matlab.ui.control.Label
        PitchMomentofInertiaEditField  matlab.ui.control.NumericEditField
        PitchMomentofInertiaykgmEditFieldLabel  matlab.ui.control.Label
        RollMomentofInertiaEditField   matlab.ui.control.NumericEditField
        RollMomentofInertiaxkgmEditFieldLabel  matlab.ui.control.Label
        DriverPanel                    matlab.ui.container.Panel
        COGheightDriverEditField       matlab.ui.control.NumericEditField
        COGheightmmEditFieldLabel      matlab.ui.control.Label
        COGlongDriverEditField         matlab.ui.control.NumericEditField
        COGlongmmEditFieldLabel        matlab.ui.control.Label
        WeightDriverEditField          matlab.ui.control.NumericEditField
        WeightkgEditFieldLabel         matlab.ui.control.Label
        CarPanel                       matlab.ui.container.Panel
        m_gesEditField                 matlab.ui.control.NumericEditField
        m_geskgEditFieldLabel          matlab.ui.control.Label
        Switch                         matlab.ui.control.Switch
        RRWeightEditField              matlab.ui.control.NumericEditField
        RRkgEditFieldLabel             matlab.ui.control.Label
        RLWeightEditField              matlab.ui.control.NumericEditField
        RLkgEditFieldLabel             matlab.ui.control.Label
        FRWeightEditField              matlab.ui.control.NumericEditField
        FRkgEditFieldLabel             matlab.ui.control.Label
        FLWeightEditField              matlab.ui.control.NumericEditField
        FLkgEditFieldLabel             matlab.ui.control.Label
    end

    
    properties (Access = private)
        CallingApp   % Main app object#
        m_ges
    end
    
    methods (Access = private)
        
        function saveData(app)
            readCarMass(app);
            
            % Saves variables to Main Gui
            % Car Weight
            app.CallingApp.m_ges = app.m_ges;
            app.CallingApp.h_cog = app.h_cogEditField.Value;
            app.CallingApp.x_cog = app.x_cogEditField.Value;
            app.CallingApp.x_va = app.x_vaEditField.Value;
            
            % Driver Weight
            app.CallingApp.m_driver = app.WeightDriverEditField.Value;
            app.CallingApp.x_cog_driver = app.COGlongDriverEditField.Value;
            app.CallingApp.h_cog_driver = app.COGheightDriverEditField.Value;
            
            % Ballast Weight
            app.CallingApp.m_ballast = app.WeightBallastEditField.Value;
            app.CallingApp.h_cog_ballast = app.COGheightBallastEditField.Value;
            app.CallingApp.x_cog_ballast = app.COGlongBallastEditField.Value;
            
            % Moment of Inertia
            app.CallingApp.thetaV_X = app.RollMomentofInertiaEditField.Value;
            app.CallingApp.thetaV_Y = app.PitchMomentofInertiaEditField.Value;
            app.CallingApp.thetaV_Z = app.YawMomentofInertiaEditField.Value;
        end
        
        function readCarMass(app)
            value = app.Switch.Value;
            if value == "tyres" 
                app.m_gesEditField.Enable = 'off';
                app.FLWeightEditField.Enable = 'on';
                app.FRWeightEditField.Enable = 'on';
                app.RLWeightEditField.Enable = 'on';
                app.RRWeightEditField.Enable = 'on';
                app.m_ges = app.FLWeightEditField.Value+app.FRWeightEditField.Value+app.RLWeightEditField.Value+app.RRWeightEditField.Value;
            else 
                app.m_gesEditField.Enable = 'on';
                app.FLWeightEditField.Enable = 'off';
                app.FRWeightEditField.Enable = 'off';
                app.RLWeightEditField.Enable = 'off';
                app.RRWeightEditField.Enable = 'off';
                app.m_ges = app.m_gesEditField.Value;
            end      
        end
        
        function loadData(app)
            % Load variables from Main Gui
            % Car Weight
            app.m_gesEditField.Value = app.CallingApp.m_ges;
            app.h_cogEditField.Value = app.CallingApp.h_cog;
            app.x_cogEditField.Value = app.CallingApp.x_cog;
            app.x_vaEditField.Value = app.CallingApp.x_va;
            
            % Driver Weight
            app.WeightDriverEditField.Value = app.CallingApp.m_driver;
            app.COGlongDriverEditField.Value = app.CallingApp.x_cog_driver;
            app.COGheightDriverEditField.Value = app.CallingApp.h_cog_driver;
            
            % Ballast Weight
            app.WeightBallastEditField.Value = app.CallingApp.m_ballast;
            app.COGheightBallastEditField.Value = app.CallingApp.h_cog_ballast;
            app.COGlongBallastEditField.Value = app.CallingApp.x_cog_ballast;
            
            % Moment of Inertia
            app.RollMomentofInertiaEditField.Value = app.CallingApp.thetaV_X;
            app.PitchMomentofInertiaEditField.Value = app.CallingApp.thetaV_Y;
            app.YawMomentofInertiaEditField.Value = app.CallingApp.thetaV_Z;
               
            % initalizes the status of the weight edit
            app.FLWeightEditField.Enable = 'off';
            app.FRWeightEditField.Enable = 'off';
            app.RLWeightEditField.Enable = 'off';
            app.RRWeightEditField.Enable = 'off';
        end
    end
    
    methods (Access = public)
        
        function changeColorScheme(app)       
                PrimaryColor = app.CallingApp.PrimaryColor;
                OnPrimaryColor = app.CallingApp.OnPrimaryColor;   
                PrimaryVariantColor = app.CallingApp.PrimaryVariantColor;
                OnPrimaryVariantColor = app.CallingApp.OnPrimaryVariantColor;
                SecondaryColor = app.CallingApp.SecondaryColor;
                OnSecondaryColor = app.CallingApp.OnSecondaryColor;
                BackgroundColor = app.CallingApp.BackgroundColor;
                OnBackgroundColor = app.CallingApp.OnBackgroundColor;
                SurfaceColor = app.CallingApp.SurfaceColor;
                OnSurfaceColor = app.CallingApp.OnSurfaceColor;
                ErrorColor = app.CallingApp.ErrorColor;
                OnErrorColor = app.CallingApp.OnErrorColor;
                BorderType = app.CallingApp.BorderType;                 
                Host_Logo = app.CallingApp.Host_Logo;
                
                app.ChassisSetupUIFigure.Color = BackgroundColor;       
                app.WeightsPanel.BackgroundColor = BackgroundColor;  
                app.WeightsPanel.BorderType = BorderType;
                app.WeightsPanel.ForegroundColor = OnSurfaceColor;
                
                %% Car Panel
                app.CarPanel.BackgroundColor = SurfaceColor;
                app.CarPanel.ForegroundColor = OnSurfaceColor;
                app.CarPanel.BorderType = BorderType;
                
                app.Switch.FontColor = OnSurfaceColor;
                
                app.m_geskgEditFieldLabel.FontColor = OnPrimaryColor;
                app.m_gesEditField.BackgroundColor = SurfaceColor;
                app.m_gesEditField.FontColor = OnSurfaceColor;
                
                app.FLkgEditFieldLabel.FontColor = OnPrimaryColor;
                app.FLWeightEditField.BackgroundColor = SurfaceColor;
                app.FLWeightEditField.FontColor = OnSurfaceColor;
                
                app.FRkgEditFieldLabel.FontColor = OnPrimaryColor;
                app.FRWeightEditField.BackgroundColor = SurfaceColor;
                app.FRWeightEditField.FontColor = OnSurfaceColor;
                
                app.RLkgEditFieldLabel.FontColor = OnPrimaryColor;
                app.RLWeightEditField.BackgroundColor = SurfaceColor;
                app.RLWeightEditField.FontColor = OnSurfaceColor;
                
                app.RRkgEditFieldLabel.FontColor = OnPrimaryColor;
                app.RRWeightEditField.BackgroundColor = SurfaceColor;
                app.RRWeightEditField.FontColor = OnSurfaceColor;
                
                %% COG Panel
                app.COGPanel.BackgroundColor = SurfaceColor;
                app.COGPanel.ForegroundColor = OnSurfaceColor;
                app.COGPanel.BorderType = BorderType;
                
                app.h_cogmmEditFieldLabel.FontColor = OnPrimaryColor;
                app.h_cogEditField.BackgroundColor = SurfaceColor;
                app.h_cogEditField.FontColor = OnSurfaceColor;
                
                app.x_cogmmEditFieldLabel.FontColor = OnPrimaryColor;
                app.x_cogEditField.BackgroundColor = SurfaceColor;
                app.x_cogEditField.FontColor = OnSurfaceColor;
                
                app.x_vammEditFieldLabel.FontColor = OnPrimaryColor;
                app.x_vaEditField.BackgroundColor = SurfaceColor;
                app.x_vaEditField.FontColor = OnSurfaceColor;
                              
                
                %% Driver Panel
                app.DriverPanel.BackgroundColor = SurfaceColor;
                app.DriverPanel.ForegroundColor = OnSurfaceColor;
                app.DriverPanel.BorderType = BorderType;
                
                app.WeightkgEditFieldLabel.FontColor = OnPrimaryColor;
                app.WeightDriverEditField.BackgroundColor = SurfaceColor;
                app.WeightDriverEditField.FontColor = OnSurfaceColor;
                
                app.COGlongmmEditFieldLabel.FontColor = OnPrimaryColor;
                app.COGlongDriverEditField.BackgroundColor = SurfaceColor;
                app.COGlongDriverEditField.FontColor = OnSurfaceColor;
                
                app.COGheightmmEditFieldLabel.FontColor = OnPrimaryColor;
                app.COGheightDriverEditField.BackgroundColor = SurfaceColor;
                app.COGheightDriverEditField.FontColor = OnSurfaceColor;
                
                %% Ballast Panel
                app.BallastPanel.BackgroundColor = SurfaceColor;
                app.BallastPanel.ForegroundColor = OnSurfaceColor;
                app.BallastPanel.BorderType = BorderType;
                
                app.WeightkgEditField_2Label.FontColor = OnPrimaryColor;
                app.WeightBallastEditField.BackgroundColor = SurfaceColor;
                app.WeightBallastEditField.FontColor = OnSurfaceColor;
                
                app.COGlongmmEditField_2Label.FontColor = OnPrimaryColor;
                app.COGlongBallastEditField.BackgroundColor = SurfaceColor;
                app.COGlongBallastEditField.FontColor = OnSurfaceColor;
                
                app.COGheightmmEditField_2Label.FontColor = OnPrimaryColor;
                app.COGheightBallastEditField.BackgroundColor = SurfaceColor;
                app.COGheightBallastEditField.FontColor = OnSurfaceColor;
                
                %% Moment of Inertia Panel
                app.MomentofInertiaPanel.BackgroundColor = SurfaceColor;
                app.MomentofInertiaPanel.ForegroundColor = OnSurfaceColor;
                app.MomentofInertiaPanel.BorderType = BorderType;
                
                app.RollMomentofInertiaxkgmEditFieldLabel.FontColor = OnPrimaryColor;
                app.RollMomentofInertiaEditField.BackgroundColor = SurfaceColor;
                app.RollMomentofInertiaEditField.FontColor = OnSurfaceColor;
                
                app.PitchMomentofInertiaykgmEditFieldLabel.FontColor = OnPrimaryColor;
                app.PitchMomentofInertiaEditField.BackgroundColor = SurfaceColor;
                app.PitchMomentofInertiaEditField.FontColor = OnSurfaceColor;
                
                app.YawMomentofInertiazkgmEditFieldLabel.FontColor = OnPrimaryColor;
                app.YawMomentofInertiaEditField.BackgroundColor = SurfaceColor;               
                app.YawMomentofInertiaEditField.FontColor = OnSurfaceColor;                     
                
                app.Copyright2021BalticRacingbyEricDorniedenLabel.FontColor = OnSurfaceColor; 
                
                if app.CallingApp.ColorSchemeID
                        app.Image.ImageSource = "Vehicle-Axis-System-ISO-8855-2011_dark.png";
                    else
                        app.Image.ImageSource = "Vehicle-Axis-System-ISO-8855-2011.png";
                end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            % Store main app object
            app.CallingApp = mainapp;
                       
            loadData(app);
            
            changeColorScheme(app);
        end

        % Value changed function: m_gesEditField
        function m_gesEditFieldValueChanged(app, event)
            value = app.m_gesEditField.Value;
            app.CallingApp.m_ges = value;
        end

        % Close request function: ChassisSetupUIFigure
        function ChassisSetupUIFigureCloseRequest(app, event)
            % Disable Chassis Options button while dialog is open
            app.CallingApp.ChassisButton.Enable = 'on';
            
            delete(app)            
        end

        % Value changed function: Switch
        function SwitchValueChanged(app, event)
            readCarMass(app);
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            saveData(app);
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            loadData(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ChassisSetupUIFigure = uifigure('Visible', 'off');
            app.ChassisSetupUIFigure.Name = 'Chassis Setup';
            app.ChassisSetupUIFigure.Icon = 'Baltic.png';
            app.ChassisSetupUIFigure.CloseRequestFcn = createCallbackFcn(app, @ChassisSetupUIFigureCloseRequest, true);
            app.WeightsPanel = uipanel(app.ChassisSetupUIFigure);
            app.WeightsPanel.Title = 'Weights';
            app.CarPanel = uipanel(app.WeightsPanel);
            app.CarPanel.Title = 'Car';
            app.FLkgEditFieldLabel = uilabel(app.CarPanel);
            app.FLkgEditFieldLabel.Text = 'FL [kg]';
            app.FLWeightEditField = uieditfield(app.CarPanel, 'numeric');
            app.FRkgEditFieldLabel = uilabel(app.CarPanel);
            app.FRkgEditFieldLabel.Text = 'FR [kg]';
            app.FRWeightEditField = uieditfield(app.CarPanel, 'numeric');
            app.RLkgEditFieldLabel = uilabel(app.CarPanel);
            app.RLkgEditFieldLabel.Text = 'RL [kg]';
            app.RLWeightEditField = uieditfield(app.CarPanel, 'numeric');
            app.RRkgEditFieldLabel = uilabel(app.CarPanel);
            app.RRkgEditFieldLabel.Text = 'RR [kg]';
            app.RRWeightEditField = uieditfield(app.CarPanel, 'numeric');
            app.Switch = uiswitch(app.CarPanel, 'slider');
            app.Switch.Items = {'tyres', 'overall'};
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.Switch.Value = 'overall';
            app.m_geskgEditFieldLabel = uilabel(app.CarPanel);
            app.m_geskgEditFieldLabel.Text = 'm_ges [kg]';
            app.m_gesEditField = uieditfield(app.CarPanel, 'numeric');
            app.m_gesEditField.ValueChangedFcn = createCallbackFcn(app, @m_gesEditFieldValueChanged, true);
            app.DriverPanel = uipanel(app.WeightsPanel);
            app.DriverPanel.Title = 'Driver';
            app.WeightkgEditFieldLabel = uilabel(app.DriverPanel);
            app.WeightkgEditFieldLabel.Text = 'Weight [kg]';
            app.WeightDriverEditField = uieditfield(app.DriverPanel, 'numeric');
            app.COGlongmmEditFieldLabel = uilabel(app.DriverPanel);
            app.COGlongmmEditFieldLabel.Text = 'COG long [mm]';
            app.COGlongDriverEditField = uieditfield(app.DriverPanel, 'numeric');
            app.COGheightmmEditFieldLabel = uilabel(app.DriverPanel);
            app.COGheightmmEditFieldLabel.Text = 'COG height [mm]';
            app.COGheightDriverEditField = uieditfield(app.DriverPanel, 'numeric');
            app.MomentofInertiaPanel = uipanel(app.WeightsPanel);
            app.MomentofInertiaPanel.Title = 'Moment of Inertia';
            app.RollMomentofInertiaxkgmEditFieldLabel = uilabel(app.MomentofInertiaPanel);
            app.RollMomentofInertiaxkgmEditFieldLabel.Text = 'Roll Moment of Inertia x [kg*m²]';
            app.RollMomentofInertiaEditField = uieditfield(app.MomentofInertiaPanel, 'numeric');
            app.PitchMomentofInertiaykgmEditFieldLabel = uilabel(app.MomentofInertiaPanel);
            app.PitchMomentofInertiaykgmEditFieldLabel.Text = 'Pitch Moment of Inertia y [kg*m²]';
            app.PitchMomentofInertiaEditField = uieditfield(app.MomentofInertiaPanel, 'numeric');
            app.YawMomentofInertiazkgmEditFieldLabel = uilabel(app.MomentofInertiaPanel);
            app.YawMomentofInertiazkgmEditFieldLabel.Text = 'Yaw Moment of Inertia z [kg*m²]';
            app.YawMomentofInertiaEditField = uieditfield(app.MomentofInertiaPanel, 'numeric');
            app.Image = uiimage(app.MomentofInertiaPanel);
            app.Image.ImageSource = 'Vehicle-Axis-System-ISO-8855-2011.png';
            app.BallastPanel = uipanel(app.WeightsPanel);
            app.BallastPanel.Title = 'Ballast';
            app.WeightkgEditField_2Label = uilabel(app.BallastPanel);
            app.WeightkgEditField_2Label.Text = 'Weight [kg]';
            app.WeightBallastEditField = uieditfield(app.BallastPanel, 'numeric');
            app.COGlongmmEditField_2Label = uilabel(app.BallastPanel);
            app.COGlongmmEditField_2Label.Text = 'COG long [mm]';
            app.COGlongBallastEditField = uieditfield(app.BallastPanel, 'numeric');
            app.COGheightmmEditField_2Label = uilabel(app.BallastPanel);
            app.COGheightmmEditField_2Label.Text = 'COG height [mm]';
            app.COGheightBallastEditField = uieditfield(app.BallastPanel, 'numeric');
            app.COGPanel = uipanel(app.WeightsPanel);
            app.COGPanel.Title = 'COG';
            app.h_cogmmEditFieldLabel = uilabel(app.COGPanel);
            app.h_cogmmEditFieldLabel.Text = 'h_cog [mm]';
            app.h_cogEditField = uieditfield(app.COGPanel, 'numeric');
            app.h_cogEditField.Tooltip = {''};
            app.x_cogmmEditFieldLabel = uilabel(app.COGPanel);
            app.x_cogmmEditFieldLabel.Text = 'x_cog [mm]';
            app.x_cogEditField = uieditfield(app.COGPanel, 'numeric');
            app.x_cogEditField.Tooltip = {''};
            app.x_vammEditFieldLabel = uilabel(app.COGPanel);
            app.x_vammEditFieldLabel.Text = 'x_va [mm]';
            app.x_vaEditField = uieditfield(app.COGPanel, 'numeric');
            app.x_vaEditField.Tooltip = {''};
            app.SaveButton = uibutton(app.WeightsPanel, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.ResetButton = uibutton(app.WeightsPanel, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.Copyright2021BalticRacingbyEricDorniedenLabel = uilabel(app.WeightsPanel);
            app.Copyright2021BalticRacingbyEricDorniedenLabel.Text = 'Copyright © 2021 Baltic Racing by Eric Dornieden';
            app.Image2 = uiimage(app.WeightsPanel);
            app.Image2.ImageSource = 'balticracing_logo_transparent.png';
            % Show the figure after all components are created
            app.ChassisSetupUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Chassis(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ChassisSetupUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ChassisSetupUIFigure)
        end
    end
end