classdef Customization < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        CustomizationAppUIFigure       matlab.ui.Figure
        SimulationReplayPlotColorsPanel  matlab.ui.container.Panel
        EditField_30                   matlab.ui.control.EditField
        plotcolorButton_6              matlab.ui.control.Button
        EditField_29                   matlab.ui.control.EditField
        plotcolorButton_5              matlab.ui.control.Button
        EditField_28                   matlab.ui.control.EditField
        plotcolorButton_4              matlab.ui.control.Button
        EditField_27                   matlab.ui.control.EditField
        plotcolorButton_3              matlab.ui.control.Button
        EditField_26                   matlab.ui.control.EditField
        plotcolorButton_2              matlab.ui.control.Button
        EditField_25                   matlab.ui.control.EditField
        plotcolorButton                matlab.ui.control.Button
        Image2                         matlab.ui.control.Image
        Copyright2021BalticRacingbyEricDorniedenLabel  matlab.ui.control.Label
        MiscPanel                      matlab.ui.container.Panel
        BrightModePanel_2              matlab.ui.container.Panel
        DarkModeLogosCheckBox_2        matlab.ui.control.CheckBox
        EnablePanelBordersCheckBox_2   matlab.ui.control.CheckBox
        DarkModePanel_2                matlab.ui.container.Panel
        DarkModeLogosCheckBox          matlab.ui.control.CheckBox
        EnablePanelBordersCheckBox     matlab.ui.control.CheckBox
        SaveDataButton                 matlab.ui.control.Button
        RestoreDefaultColorsButton     matlab.ui.control.Button
        ColorsPanel                    matlab.ui.container.Panel
        BrightModePanel                matlab.ui.container.Panel
        EditField_24                   matlab.ui.control.EditField
        EditField_23                   matlab.ui.control.EditField
        EditField_22                   matlab.ui.control.EditField
        EditField_21                   matlab.ui.control.EditField
        EditField_20                   matlab.ui.control.EditField
        EditField_19                   matlab.ui.control.EditField
        EditField_18                   matlab.ui.control.EditField
        EditField_17                   matlab.ui.control.EditField
        EditField_16                   matlab.ui.control.EditField
        EditField_15                   matlab.ui.control.EditField
        EditField_14                   matlab.ui.control.EditField
        EditField_13                   matlab.ui.control.EditField
        OnErrorColorButton_2           matlab.ui.control.Button
        ErrorColorButton_2             matlab.ui.control.Button
        OnSurfaceColorButton_2         matlab.ui.control.Button
        SurfaceColorButton_2           matlab.ui.control.Button
        OnBackgroundColorButton_2      matlab.ui.control.Button
        BackgroundColorButton_2        matlab.ui.control.Button
        OnSecondaryColorButton_2       matlab.ui.control.Button
        SecondaryColorButton_2         matlab.ui.control.Button
        OnPrimaryVariantColorButton_2  matlab.ui.control.Button
        PrimaryVariantColorButton_2    matlab.ui.control.Button
        OnPrimaryColorButton_2         matlab.ui.control.Button
        PrimaryColorButton_2           matlab.ui.control.Button
        DarkModePanel                  matlab.ui.container.Panel
        EditField_12                   matlab.ui.control.EditField
        EditField_11                   matlab.ui.control.EditField
        EditField_10                   matlab.ui.control.EditField
        EditField_9                    matlab.ui.control.EditField
        EditField_8                    matlab.ui.control.EditField
        EditField_7                    matlab.ui.control.EditField
        EditField_6                    matlab.ui.control.EditField
        EditField_5                    matlab.ui.control.EditField
        EditField_4                    matlab.ui.control.EditField
        EditField_3                    matlab.ui.control.EditField
        EditField_2                    matlab.ui.control.EditField
        EditField                      matlab.ui.control.EditField
        OnErrorColorButton             matlab.ui.control.Button
        ErrorColorButton               matlab.ui.control.Button
        OnSurfaceColorButton           matlab.ui.control.Button
        SurfaceColorButton             matlab.ui.control.Button
        OnBackgroundColorButton        matlab.ui.control.Button
        BackgroundColorButton          matlab.ui.control.Button
        OnSecondaryColorButton         matlab.ui.control.Button
        SecondaryColorButton           matlab.ui.control.Button
        OnPrimaryVariantColorButton    matlab.ui.control.Button
        PrimaryVariantColorButton      matlab.ui.control.Button
        OnPrimaryColorButton           matlab.ui.control.Button
        PrimaryColorButton             matlab.ui.control.Button
    end

    
    properties (Access = private)
        CallingApp % Main app object
        ColorsDark
        ColorsBright
        PlotColors
    end
    
    methods (Access = public)
        
        function changeColorScheme(app)
            PrimaryColor = app.CallingApp.PrimaryColor;
            OnPrimaryColor = app.CallingApp.OnPrimaryColor;   
            PrimaryVariantColor = app.CallingApp.PrimaryVariantColor;
            OnPrimaryVariantColor = app.CallingApp.OnPrimaryVariantColor;
            SecondaryColor = app.CallingApp.SecondaryColor;
            OnSecondaryColor = app.CallingApp.OnSecondaryColor;
            BackgroundColor = app.CallingApp.BackgroundColor;
            OnBackgroundColor = app.CallingApp.OnBackgroundColor;
            SurfaceColor = app.CallingApp.SurfaceColor;
            OnSurfaceColor = app.CallingApp.OnSurfaceColor;
            ErrorColor = app.CallingApp.ErrorColor;
            OnErrorColor = app.CallingApp.OnErrorColor;
            BorderType = app.CallingApp.BorderType;                 
            Host_Logo = app.CallingApp.Host_Logo;
            
            app.CustomizationAppUIFigure.Color = BackgroundColor;
            
            app.Copyright2021BalticRacingbyEricDorniedenLabel.FontColor = OnBackgroundColor;
            
            app.RestoreDefaultColorsButton.BackgroundColor = PrimaryVariantColor;
            app.RestoreDefaultColorsButton.FontColor = OnPrimaryColor;  
            
            app.SaveDataButton.BackgroundColor = PrimaryVariantColor;
            app.SaveDataButton.FontColor = OnPrimaryColor;  
            
            app.PrimaryColorButton.BackgroundColor = PrimaryVariantColor;
            app.PrimaryColorButton.FontColor = OnPrimaryColor;  
            app.PrimaryColorButton_2.BackgroundColor = PrimaryVariantColor;
            app.PrimaryColorButton_2.FontColor = OnPrimaryColor;  
            
            app.OnPrimaryColorButton.BackgroundColor = PrimaryVariantColor;
            app.OnPrimaryColorButton.FontColor = OnPrimaryColor;  
            app.OnPrimaryColorButton_2.BackgroundColor = PrimaryVariantColor;
            app.OnPrimaryColorButton_2.FontColor = OnPrimaryColor;  
            
            app.PrimaryVariantColorButton.BackgroundColor = PrimaryVariantColor;
            app.PrimaryVariantColorButton.FontColor = OnPrimaryColor;  
            app.PrimaryVariantColorButton_2.BackgroundColor = PrimaryVariantColor;
            app.PrimaryVariantColorButton_2.FontColor = OnPrimaryColor;  
            
            app.OnPrimaryVariantColorButton.BackgroundColor = PrimaryVariantColor;
            app.OnPrimaryVariantColorButton.FontColor = OnPrimaryColor;  
            app.OnPrimaryVariantColorButton_2.BackgroundColor = PrimaryVariantColor;
            app.OnPrimaryVariantColorButton_2.FontColor = OnPrimaryColor;  
            
            app.SecondaryColorButton.BackgroundColor = PrimaryVariantColor;
            app.SecondaryColorButton.FontColor = OnPrimaryColor;  
            app.SecondaryColorButton_2.BackgroundColor = PrimaryVariantColor;
            app.SecondaryColorButton_2.FontColor = OnPrimaryColor;  
            
            app.OnSecondaryColorButton.BackgroundColor = PrimaryVariantColor;
            app.OnSecondaryColorButton.FontColor = OnPrimaryColor;  
            app.OnSecondaryColorButton_2.BackgroundColor = PrimaryVariantColor;
            app.OnSecondaryColorButton_2.FontColor = OnPrimaryColor;  
            
            app.BackgroundColorButton.BackgroundColor = PrimaryVariantColor;
            app.BackgroundColorButton.FontColor = OnPrimaryColor;  
            app.BackgroundColorButton_2.BackgroundColor = PrimaryVariantColor;
            app.BackgroundColorButton_2.FontColor = OnPrimaryColor;  
            
            app.OnBackgroundColorButton.BackgroundColor = PrimaryVariantColor;
            app.OnBackgroundColorButton.FontColor = OnPrimaryColor;  
            app.OnBackgroundColorButton_2.BackgroundColor = PrimaryVariantColor;
            app.OnBackgroundColorButton_2.FontColor = OnPrimaryColor;  
            
            app.SurfaceColorButton.BackgroundColor = PrimaryVariantColor;
            app.SurfaceColorButton.FontColor = OnPrimaryColor;  
            app.SurfaceColorButton_2.BackgroundColor = PrimaryVariantColor;
            app.SurfaceColorButton_2.FontColor = OnPrimaryColor;  
            
            app.OnSurfaceColorButton.BackgroundColor = PrimaryVariantColor;
            app.OnSurfaceColorButton.FontColor = OnPrimaryColor;  
            app.OnSurfaceColorButton_2.BackgroundColor = PrimaryVariantColor;
            app.OnSurfaceColorButton_2.FontColor = OnPrimaryColor;  
            
            app.ErrorColorButton.BackgroundColor = PrimaryVariantColor;
            app.ErrorColorButton.FontColor = OnPrimaryColor;  
            app.ErrorColorButton_2.BackgroundColor = PrimaryVariantColor;
            app.ErrorColorButton_2.FontColor = OnPrimaryColor;  
            
            app.OnErrorColorButton.BackgroundColor = PrimaryVariantColor;
            app.OnErrorColorButton.FontColor = OnPrimaryColor;  
            app.OnErrorColorButton_2.BackgroundColor = PrimaryVariantColor;
            app.OnErrorColorButton_2.FontColor = OnPrimaryColor;  

            app.plotcolorButton.BackgroundColor = PrimaryVariantColor;
            app.plotcolorButton.FontColor = OnPrimaryColor;  
            app.plotcolorButton_2.BackgroundColor = PrimaryVariantColor;
            app.plotcolorButton_2.FontColor = OnPrimaryColor;  
            app.plotcolorButton_3.BackgroundColor = PrimaryVariantColor;
            app.plotcolorButton_3.FontColor = OnPrimaryColor;  
            app.plotcolorButton_4.BackgroundColor = PrimaryVariantColor;
            app.plotcolorButton_4.FontColor = OnPrimaryColor;  
            app.plotcolorButton_5.BackgroundColor = PrimaryVariantColor;
            app.plotcolorButton_5.FontColor = OnPrimaryColor;  
            app.plotcolorButton_6.BackgroundColor = PrimaryVariantColor;
            app.plotcolorButton_6.FontColor = OnPrimaryColor;  
            
            app.ColorsPanel.BackgroundColor = SurfaceColor;
            app.ColorsPanel.ForegroundColor = OnSurfaceColor;
            app.ColorsPanel.BorderType = BorderType;
            
            app.DarkModePanel.BackgroundColor = BackgroundColor;
            app.DarkModePanel.ForegroundColor = OnBackgroundColor;
            app.DarkModePanel.BorderType = BorderType;
            
            app.BrightModePanel.BackgroundColor = BackgroundColor;
            app.BrightModePanel.ForegroundColor = OnBackgroundColor;
            app.BrightModePanel.BorderType = BorderType;
            
            app.MiscPanel.ForegroundColor = OnSurfaceColor;
            app.MiscPanel.BackgroundColor = SurfaceColor;
            app.MiscPanel.BorderType = BorderType;
            
            app.DarkModePanel_2.BackgroundColor = BackgroundColor;
            app.DarkModePanel_2.ForegroundColor = OnBackgroundColor;
            app.DarkModePanel_2.BorderType = BorderType;
            app.EnablePanelBordersCheckBox.FontColor = OnBackgroundColor;
            app.DarkModeLogosCheckBox.FontColor = OnBackgroundColor;
            
            app.BrightModePanel_2.BackgroundColor = BackgroundColor;
            app.BrightModePanel_2.ForegroundColor = OnBackgroundColor;
            app.BrightModePanel_2.BorderType = BorderType;
            app.EnablePanelBordersCheckBox_2.FontColor = OnBackgroundColor;
            app.DarkModeLogosCheckBox_2.FontColor = OnBackgroundColor;

            app.SimulationReplayPlotColorsPanel.BackgroundColor = SurfaceColor;
            app.SimulationReplayPlotColorsPanel.ForegroundColor = OnSurfaceColor;
            app.SimulationReplayPlotColorsPanel.BorderType = BorderType;
        end
        
    end
    
    methods (Access = private)
        
        function saveData(app)
            app.CallingApp.ColorsDark = app.ColorsDark;
            app.CallingApp.ColorsBright = app.ColorsBright;
            app.CallingApp.PlotColors = app.PlotColors;
        end
        
        function updateColorIcons(app)
            
            %% Update Chechboxes
            if app.ColorsDark(13) == "line"
                app.EnablePanelBordersCheckBox.Value = 1;
            else
                app.EnablePanelBordersCheckBox.Value = 0;
            end
            
            if app.ColorsBright(13) == "line"
                app.EnablePanelBordersCheckBox_2.Value = 1;
            else
                app.EnablePanelBordersCheckBox_2.Value = 0;
            end
            
            if app.ColorsDark(14) == "Logo_Ship_HKS_Ranged_Dark.png"
                app.DarkModeLogosCheckBox.Value = 1;
            else
                app.DarkModeLogosCheckBox.Value = 0;
            end
            
            if app.ColorsBright(14) == "Logo_Ship_HKS_Ranged_Dark.png"
                app.DarkModeLogosCheckBox_2.Value = 1;
            else
                app.DarkModeLogosCheckBox_2.Value = 0;
            end
            
            %% Update Edit Fields (Icons)
            app.EditField.BackgroundColor = app.ColorsDark(1);
            app.EditField_2.BackgroundColor = app.ColorsDark(2);
            app.EditField_3.BackgroundColor = app.ColorsDark(3);
            app.EditField_4.BackgroundColor = app.ColorsDark(4);
            app.EditField_5.BackgroundColor = app.ColorsDark(5);
            app.EditField_6.BackgroundColor = app.ColorsDark(6);
            app.EditField_7.BackgroundColor = app.ColorsDark(7);
            app.EditField_8.BackgroundColor = app.ColorsDark(8);
            app.EditField_9.BackgroundColor = app.ColorsDark(9);
            app.EditField_10.BackgroundColor = app.ColorsDark(10);
            app.EditField_11.BackgroundColor = app.ColorsDark(11);
            app.EditField_12.BackgroundColor = app.ColorsDark(12);
            
            app.EditField_13.BackgroundColor = app.ColorsBright(1);
            app.EditField_14.BackgroundColor = app.ColorsBright(2);
            app.EditField_15.BackgroundColor = app.ColorsBright(3);
            app.EditField_16.BackgroundColor = app.ColorsBright(4);
            app.EditField_17.BackgroundColor = app.ColorsBright(5);
            app.EditField_18.BackgroundColor = app.ColorsBright(6);
            app.EditField_19.BackgroundColor = app.ColorsBright(7);
            app.EditField_20.BackgroundColor = app.ColorsBright(8);
            app.EditField_21.BackgroundColor = app.ColorsBright(9);
            app.EditField_22.BackgroundColor = app.ColorsBright(10);
            app.EditField_23.BackgroundColor = app.ColorsBright(11);
            app.EditField_24.BackgroundColor = app.ColorsBright(12);

            %% Update Icons plot colors
            app.EditField_25.BackgroundColor = app.PlotColors(1);
            app.EditField_26.BackgroundColor = app.PlotColors(2);
            app.EditField_27.BackgroundColor = app.PlotColors(3);
            app.EditField_28.BackgroundColor = app.PlotColors(4);
            app.EditField_29.BackgroundColor = app.PlotColors(5);
            app.EditField_30.BackgroundColor = app.PlotColors(6);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            app.CallingApp = mainapp;           
            
            app.ColorsDark = app.CallingApp.ColorsDark;
            app.ColorsBright = app.CallingApp.ColorsBright;    
            app.PlotColors = app.CallingApp.PlotColors;
            
            %% Update the color preview icons
            updateColorIcons(app);    
            changeColorScheme(app);
        end

        % Button pushed function: PrimaryColorButton
        function PrimaryColorButtonPushed(app, event)
            Color = num2str(uisetcolor(app.ColorsDark(1)));
            app.EditField.BackgroundColor = Color;
            app.ColorsDark(1) = Color;
        end

        % Button pushed function: RestoreDefaultColorsButton
        function RestoreDefaultColorsButtonPushed(app, event)
            app.ColorsDark = ["#BB86FC"; "white"; "#3700B3"; "#000000"; "#03DAC6"; "#000000"; "#121212"; "#FFFFFF"; "#2a2a2a"; "#FFFFFF"; "#CF6679"; "#000000"; "none"; "Logo_Ship_HKS_Ranged_Dark.png"];
            app.ColorsBright = ["[0.96 0.96 0.96]"; "#000000"; "[0.96 0.96 0.96]"; "#000000"; "#03DAC6"; "#000000"; "[0.94 0.94 0.94]"; "black"; "white"; "black"; "#CF6679"; "#000000"; "line"; "Logo_Ship_HKS_Ranged.png"];
            updateColorIcons(app);
        end

        % Close request function: CustomizationAppUIFigure
        function CustomizationAppUIFigureCloseRequest(app, event)
            % Disable StyleSettings button while dialog is open
            app.CallingApp.StyleSettingsButton.Enable = 'on';
            
            delete(app)  
        end

        % Button pushed function: SaveDataButton
        function SaveDataButtonPushed(app, event)
            % Save Data
            saveData(app);
            
            % Update Color Scheme
            updateColorScheme(app.CallingApp);
        end

        % Button pushed function: OnPrimaryColorButton
        function OnPrimaryColorButtonPushed(app, event)
            Color = num2str(uisetcolor(app.ColorsDark(2)));
            app.EditField_2.BackgroundColor = Color;
            app.ColorsDark(2) = Color;
        end

        % Button pushed function: PrimaryVariantColorButton
        function PrimaryVariantColorButtonPushed(app, event)
            Color = num2str(uisetcolor(app.ColorsDark(3)));
            app.EditField_3.BackgroundColor = Color;
            app.ColorsDark(3) = Color;
        end

        % Button pushed function: OnPrimaryVariantColorButton
        function OnPrimaryVariantColorButtonPushed(app, event)
            Color = num2str(uisetcolor(app.ColorsDark(4)));
            app.EditField_4.BackgroundColor = Color;
            app.ColorsDark(4) = Color;
        end

        % Button pushed function: SecondaryColorButton
        function SecondaryColorButtonPushed(app, event)
            Color = num2str(uisetcolor(app.ColorsDark(5)));
            app.EditField_5.BackgroundColor = Color;
            app.ColorsDark(5) = Color;
        end

        % Button pushed function: OnSecondaryColorButton
        function OnSecondaryColorButtonPushed(app, event)
            Color = num2str(uisetcolor(app.ColorsDark(6)));
            app.EditField_6.BackgroundColor = Color;
            app.ColorsDark(6) = Color;
        end

        % Button pushed function: BackgroundColorButton
        function BackgroundColorButtonPushed(app, event)
            Color = num2str(uisetcolor(app.ColorsDark(7)));
            app.EditField_7.BackgroundColor = Color;
            app.ColorsDark(7) = Color;
        end

        % Button pushed function: OnBackgroundColorButton
        function OnBackgroundColorButtonPushed(app, event)
            Color = num2str(uisetcolor(app.ColorsDark(8)));
            app.EditField_8.BackgroundColor = Color;
            app.ColorsDark(8) = Color;
        end

        % Button pushed function: SurfaceColorButton
        function SurfaceColorButtonPushed(app, event)
            Color = num2str(uisetcolor(app.ColorsDark(9)));
            app.EditField_9.BackgroundColor = Color;
            app.ColorsDark(9) = Color;
        end

        % Button pushed function: OnSurfaceColorButton
        function OnSurfaceColorButtonPushed(app, event)
            Color = num2str(uisetcolor(app.ColorsDark(10)));
            app.EditField_10.BackgroundColor = Color;
            app.ColorsDark(10) = Color;
        end

        % Button pushed function: ErrorColorButton
        function ErrorColorButtonPushed(app, event)
            Color = num2str(uisetcolor(app.ColorsDark(11)));
            app.EditField_11.BackgroundColor = Color;
            app.ColorsDark(11) = Color;
        end

        % Button pushed function: OnErrorColorButton
        function OnErrorColorButtonPushed(app, event)
            Color = num2str(uisetcolor(app.ColorsDark(12)));
            app.EditField_12.BackgroundColor = Color;
            app.ColorsDark(12) = Color;
        end

        % Button pushed function: PrimaryColorButton_2
        function PrimaryColorButton_2Pushed(app, event)
            Color = num2str(uisetcolor(app.ColorsBright(1)));
            app.EditField_13.BackgroundColor = Color;
            app.ColorsBright(1) = Color;
        end

        % Button pushed function: OnPrimaryColorButton_2
        function OnPrimaryColorButton_2Pushed(app, event)
            Color = num2str(uisetcolor(app.ColorsBright(2)));
            app.EditField_14.BackgroundColor = Color;
            app.ColorsBright(2) = Color;
        end

        % Button pushed function: PrimaryVariantColorButton_2
        function PrimaryVariantColorButton_2Pushed(app, event)
            Color = num2str(uisetcolor(app.ColorsBright(3)));
            app.EditField_15.BackgroundColor = Color;
            app.ColorsBright(3) = Color;
        end

        % Button pushed function: OnPrimaryVariantColorButton_2
        function OnPrimaryVariantColorButton_2Pushed(app, event)
            Color = num2str(uisetcolor(app.ColorsBright(4)));
            app.EditField_16.BackgroundColor = Color;
            app.ColorsBright(4) = Color;
        end

        % Button pushed function: SecondaryColorButton_2
        function SecondaryColorButton_2Pushed(app, event)
            Color = num2str(uisetcolor(app.ColorsBright(5)));
            app.EditField_17.BackgroundColor = Color;
            app.ColorsBright(5) = Color;
        end

        % Button pushed function: OnSecondaryColorButton_2
        function OnSecondaryColorButton_2Pushed(app, event)
            Color = num2str(uisetcolor(app.ColorsBright(6)));
            app.EditField_18.BackgroundColor = Color;
            app.ColorsBright(6) = Color;
        end

        % Button pushed function: BackgroundColorButton_2
        function BackgroundColorButton_2Pushed(app, event)
            Color = num2str(uisetcolor(app.ColorsBright(7)));
            app.EditField_19.BackgroundColor = Color;
            app.ColorsBright(7) = Color;
        end

        % Button pushed function: OnBackgroundColorButton_2
        function OnBackgroundColorButton_2Pushed(app, event)
            Color = num2str(uisetcolor(app.ColorsBright(8)));
            app.EditField_20.BackgroundColor = Color;
            app.ColorsBright(8) = Color;
        end

        % Button pushed function: SurfaceColorButton_2
        function SurfaceColorButton_2Pushed(app, event)
            Color = num2str(uisetcolor(app.ColorsBright(9)));
            app.EditField_21.BackgroundColor = Color;
            app.ColorsBright(9) = Color;
        end

        % Button pushed function: OnSurfaceColorButton_2
        function OnSurfaceColorButton_2Pushed(app, event)
            Color = num2str(uisetcolor(app.ColorsBright(10)));
            app.EditField_22.BackgroundColor = Color;
            app.ColorsBright(10) = Color;
        end

        % Button pushed function: ErrorColorButton_2
        function ErrorColorButton_2Pushed(app, event)
            Color = num2str(uisetcolor(app.ColorsBright(11)));
            app.EditField_23.BackgroundColor = Color;
            app.ColorsBright(11) = Color;
        end

        % Button pushed function: OnErrorColorButton_2
        function OnErrorColorButton_2Pushed(app, event)
            Color = num2str(uisetcolor(app.ColorsBright(12)));
            app.EditField_24.BackgroundColor = Color;
            app.ColorsBright(12) = Color;
        end

        % Value changed function: EnablePanelBordersCheckBox
        function EnablePanelBordersCheckBoxValueChanged(app, event)
            if app.EnablePanelBordersCheckBox.Value
                app.ColorsDark(13) = "line";
            else
                app.ColorsDark(13) = "none";
            end
        end

        % Value changed function: EnablePanelBordersCheckBox_2
        function EnablePanelBordersCheckBox_2ValueChanged(app, event)
            if app.EnablePanelBordersCheckBox_2.Value
                app.ColorsBright(13) = "line";
            else
                app.ColorsBright(13) = "none";
            end
        end

        % Value changed function: DarkModeLogosCheckBox
        function DarkModeLogosCheckBoxValueChanged(app, event)
            if app.DarkModeLogosCheckBox.Value
                app.ColorsDark(14) = "Logo_Ship_HKS_Ranged_Dark.png";
            else
                app.ColorsDark(14) = "Logo_Ship_HKS_Ranged.png";
            end
        end

        % Value changed function: DarkModeLogosCheckBox_2
        function DarkModeLogosCheckBox_2ValueChanged(app, event)
            if app.DarkModeLogosCheckBox_2.Value
                app.ColorsBright(14) = "Logo_Ship_HKS_Ranged_Dark.png";
            else
                app.ColorsBright(14) = "Logo_Ship_HKS_Ranged.png";
            end
        end

        % Button pushed function: plotcolorButton
        function plotcolorButtonPushed(app, event)
            Color = num2str(uisetcolor(app.PlotColors(1)));
            app.EditField_25.BackgroundColor = Color;
            app.PlotColors(1) = Color;
        end

        % Button pushed function: plotcolorButton_2
        function plotcolorButton_2Pushed(app, event)
            Color = num2str(uisetcolor(app.PlotColors(2)));
            app.EditField_26.BackgroundColor = Color;
            app.PlotColors(2) = Color;
        end

        % Button pushed function: plotcolorButton_3
        function plotcolorButton_3Pushed(app, event)
            Color = num2str(uisetcolor(app.PlotColors(3)));
            app.EditField_27.BackgroundColor = Color;
            app.PlotColors(3) = Color;
        end

        % Button pushed function: plotcolorButton_4
        function plotcolorButton_4Pushed(app, event)
            Color = num2str(uisetcolor(app.PlotColors(4)));
            app.EditField_28.BackgroundColor = Color;
            app.PlotColors(4) = Color;
        end

        % Button pushed function: plotcolorButton_5
        function plotcolorButton_5Pushed(app, event)
            Color = num2str(uisetcolor(app.PlotColors(5)));
            app.EditField_29.BackgroundColor = Color;
            app.PlotColors(5) = Color;
        end

        % Button pushed function: plotcolorButton_6
        function plotcolorButton_6Pushed(app, event)
            Color = num2str(uisetcolor(app.PlotColors(6)));
            app.EditField_30.BackgroundColor = Color;
            app.PlotColors(6) = Color;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.CustomizationAppUIFigure = uifigure('Visible', 'off');
            app.CustomizationAppUIFigure.Name = 'Customization App';
            app.CustomizationAppUIFigure.Icon = 'Baltic.png';
            app.CustomizationAppUIFigure.CloseRequestFcn = createCallbackFcn(app, @CustomizationAppUIFigureCloseRequest, true);
            app.ColorsPanel = uipanel(app.CustomizationAppUIFigure);
            app.ColorsPanel.Title = 'Colors';
            app.DarkModePanel = uipanel(app.ColorsPanel);
            app.DarkModePanel.Title = 'Dark Mode';
            app.PrimaryColorButton = uibutton(app.DarkModePanel, 'push');
            app.PrimaryColorButton.ButtonPushedFcn = createCallbackFcn(app, @PrimaryColorButtonPushed, true);
            app.PrimaryColorButton.Text = 'Primary Color';
            app.OnPrimaryColorButton = uibutton(app.DarkModePanel, 'push');
            app.OnPrimaryColorButton.ButtonPushedFcn = createCallbackFcn(app, @OnPrimaryColorButtonPushed, true);
            app.OnPrimaryColorButton.Text = 'On Primary Color';
            app.PrimaryVariantColorButton = uibutton(app.DarkModePanel, 'push');
            app.PrimaryVariantColorButton.ButtonPushedFcn = createCallbackFcn(app, @PrimaryVariantColorButtonPushed, true);
            app.PrimaryVariantColorButton.Text = 'Primary Variant Color';
            app.OnPrimaryVariantColorButton = uibutton(app.DarkModePanel, 'push');
            app.OnPrimaryVariantColorButton.ButtonPushedFcn = createCallbackFcn(app, @OnPrimaryVariantColorButtonPushed, true);
            app.OnPrimaryVariantColorButton.Text = 'On Primary Variant Color';
            app.SecondaryColorButton = uibutton(app.DarkModePanel, 'push');
            app.SecondaryColorButton.ButtonPushedFcn = createCallbackFcn(app, @SecondaryColorButtonPushed, true);
            app.SecondaryColorButton.Text = 'Secondary Color';
            app.OnSecondaryColorButton = uibutton(app.DarkModePanel, 'push');
            app.OnSecondaryColorButton.ButtonPushedFcn = createCallbackFcn(app, @OnSecondaryColorButtonPushed, true);
            app.OnSecondaryColorButton.Text = 'On Secondary Color';
            app.BackgroundColorButton = uibutton(app.DarkModePanel, 'push');
            app.BackgroundColorButton.ButtonPushedFcn = createCallbackFcn(app, @BackgroundColorButtonPushed, true);
            app.BackgroundColorButton.Text = 'Background Color';
            app.OnBackgroundColorButton = uibutton(app.DarkModePanel, 'push');
            app.OnBackgroundColorButton.ButtonPushedFcn = createCallbackFcn(app, @OnBackgroundColorButtonPushed, true);
            app.OnBackgroundColorButton.Text = 'On Background Color';
            app.SurfaceColorButton = uibutton(app.DarkModePanel, 'push');
            app.SurfaceColorButton.ButtonPushedFcn = createCallbackFcn(app, @SurfaceColorButtonPushed, true);
            app.SurfaceColorButton.Text = 'Surface Color';
            app.OnSurfaceColorButton = uibutton(app.DarkModePanel, 'push');
            app.OnSurfaceColorButton.ButtonPushedFcn = createCallbackFcn(app, @OnSurfaceColorButtonPushed, true);
            app.OnSurfaceColorButton.Text = 'On Surface Color';
            app.ErrorColorButton = uibutton(app.DarkModePanel, 'push');
            app.ErrorColorButton.ButtonPushedFcn = createCallbackFcn(app, @ErrorColorButtonPushed, true);
            app.ErrorColorButton.Text = 'Error Color';
            app.OnErrorColorButton = uibutton(app.DarkModePanel, 'push');
            app.OnErrorColorButton.ButtonPushedFcn = createCallbackFcn(app, @OnErrorColorButtonPushed, true);
            app.OnErrorColorButton.Text = 'On Error Color';
            app.EditField = uieditfield(app.DarkModePanel, 'text');
            app.EditField.Editable = 'off';
            app.EditField_2 = uieditfield(app.DarkModePanel, 'text');
            app.EditField_2.Editable = 'off';
            app.EditField_3 = uieditfield(app.DarkModePanel, 'text');
            app.EditField_3.Editable = 'off';
            app.EditField_4 = uieditfield(app.DarkModePanel, 'text');
            app.EditField_4.Editable = 'off';
            app.EditField_5 = uieditfield(app.DarkModePanel, 'text');
            app.EditField_5.Editable = 'off';
            app.EditField_6 = uieditfield(app.DarkModePanel, 'text');
            app.EditField_6.Editable = 'off';
            app.EditField_7 = uieditfield(app.DarkModePanel, 'text');
            app.EditField_7.Editable = 'off';
            app.EditField_8 = uieditfield(app.DarkModePanel, 'text');
            app.EditField_8.Editable = 'off';
            app.EditField_9 = uieditfield(app.DarkModePanel, 'text');
            app.EditField_9.Editable = 'off';
            app.EditField_10 = uieditfield(app.DarkModePanel, 'text');
            app.EditField_10.Editable = 'off';
            app.EditField_11 = uieditfield(app.DarkModePanel, 'text');
            app.EditField_11.Editable = 'off';
            app.EditField_12 = uieditfield(app.DarkModePanel, 'text');
            app.EditField_12.Editable = 'off';
            app.BrightModePanel = uipanel(app.ColorsPanel);
            app.BrightModePanel.Title = 'Bright Mode';
            app.PrimaryColorButton_2 = uibutton(app.BrightModePanel, 'push');
            app.PrimaryColorButton_2.ButtonPushedFcn = createCallbackFcn(app, @PrimaryColorButton_2Pushed, true);
            app.PrimaryColorButton_2.Text = 'Primary Color';
            app.OnPrimaryColorButton_2 = uibutton(app.BrightModePanel, 'push');
            app.OnPrimaryColorButton_2.ButtonPushedFcn = createCallbackFcn(app, @OnPrimaryColorButton_2Pushed, true);
            app.OnPrimaryColorButton_2.Text = 'On Primary Color';
            app.PrimaryVariantColorButton_2 = uibutton(app.BrightModePanel, 'push');
            app.PrimaryVariantColorButton_2.ButtonPushedFcn = createCallbackFcn(app, @PrimaryVariantColorButton_2Pushed, true);
            app.PrimaryVariantColorButton_2.Text = 'Primary Variant Color';
            app.OnPrimaryVariantColorButton_2 = uibutton(app.BrightModePanel, 'push');
            app.OnPrimaryVariantColorButton_2.ButtonPushedFcn = createCallbackFcn(app, @OnPrimaryVariantColorButton_2Pushed, true);
            app.OnPrimaryVariantColorButton_2.Text = 'On Primary Variant Color';
            app.SecondaryColorButton_2 = uibutton(app.BrightModePanel, 'push');
            app.SecondaryColorButton_2.ButtonPushedFcn = createCallbackFcn(app, @SecondaryColorButton_2Pushed, true);
            app.SecondaryColorButton_2.Text = 'Secondary Color';
            app.OnSecondaryColorButton_2 = uibutton(app.BrightModePanel, 'push');
            app.OnSecondaryColorButton_2.ButtonPushedFcn = createCallbackFcn(app, @OnSecondaryColorButton_2Pushed, true);
            app.OnSecondaryColorButton_2.Text = 'On Secondary Color';
            app.BackgroundColorButton_2 = uibutton(app.BrightModePanel, 'push');
            app.BackgroundColorButton_2.ButtonPushedFcn = createCallbackFcn(app, @BackgroundColorButton_2Pushed, true);
            app.BackgroundColorButton_2.Text = 'Background Color';
            app.OnBackgroundColorButton_2 = uibutton(app.BrightModePanel, 'push');
            app.OnBackgroundColorButton_2.ButtonPushedFcn = createCallbackFcn(app, @OnBackgroundColorButton_2Pushed, true);
            app.OnBackgroundColorButton_2.Text = 'On Background Color';
            app.SurfaceColorButton_2 = uibutton(app.BrightModePanel, 'push');
            app.SurfaceColorButton_2.ButtonPushedFcn = createCallbackFcn(app, @SurfaceColorButton_2Pushed, true);
            app.SurfaceColorButton_2.Text = 'Surface Color';
            app.OnSurfaceColorButton_2 = uibutton(app.BrightModePanel, 'push');
            app.OnSurfaceColorButton_2.ButtonPushedFcn = createCallbackFcn(app, @OnSurfaceColorButton_2Pushed, true);
            app.OnSurfaceColorButton_2.Text = 'On Surface Color';
            app.ErrorColorButton_2 = uibutton(app.BrightModePanel, 'push');
            app.ErrorColorButton_2.ButtonPushedFcn = createCallbackFcn(app, @ErrorColorButton_2Pushed, true);
            app.ErrorColorButton_2.Text = 'Error Color';
            app.OnErrorColorButton_2 = uibutton(app.BrightModePanel, 'push');
            app.OnErrorColorButton_2.ButtonPushedFcn = createCallbackFcn(app, @OnErrorColorButton_2Pushed, true);
            app.OnErrorColorButton_2.Text = 'On Error Color';
            app.EditField_13 = uieditfield(app.BrightModePanel, 'text');
            app.EditField_13.Editable = 'off';
            app.EditField_14 = uieditfield(app.BrightModePanel, 'text');
            app.EditField_14.Editable = 'off';
            app.EditField_15 = uieditfield(app.BrightModePanel, 'text');
            app.EditField_15.Editable = 'off';
            app.EditField_16 = uieditfield(app.BrightModePanel, 'text');
            app.EditField_16.Editable = 'off';
            app.EditField_17 = uieditfield(app.BrightModePanel, 'text');
            app.EditField_17.Editable = 'off';
            app.EditField_18 = uieditfield(app.BrightModePanel, 'text');
            app.EditField_18.Editable = 'off';
            app.EditField_19 = uieditfield(app.BrightModePanel, 'text');
            app.EditField_19.Editable = 'off';
            app.EditField_20 = uieditfield(app.BrightModePanel, 'text');
            app.EditField_20.Editable = 'off';
            app.EditField_21 = uieditfield(app.BrightModePanel, 'text');
            app.EditField_21.Editable = 'off';
            app.EditField_22 = uieditfield(app.BrightModePanel, 'text');
            app.EditField_22.Editable = 'off';
            app.EditField_23 = uieditfield(app.BrightModePanel, 'text');
            app.EditField_23.Editable = 'off';
            app.EditField_24 = uieditfield(app.BrightModePanel, 'text');
            app.EditField_24.Editable = 'off';
            app.RestoreDefaultColorsButton = uibutton(app.CustomizationAppUIFigure, 'push');
            app.RestoreDefaultColorsButton.ButtonPushedFcn = createCallbackFcn(app, @RestoreDefaultColorsButtonPushed, true);
            app.RestoreDefaultColorsButton.Text = 'Restore Default Colors';
            app.SaveDataButton = uibutton(app.CustomizationAppUIFigure, 'push');
            app.SaveDataButton.ButtonPushedFcn = createCallbackFcn(app, @SaveDataButtonPushed, true);
            app.SaveDataButton.Text = 'Save Data';
            app.MiscPanel = uipanel(app.CustomizationAppUIFigure);
            app.MiscPanel.Title = 'Misc';
            app.DarkModePanel_2 = uipanel(app.MiscPanel);
            app.DarkModePanel_2.Title = 'Dark Mode';
            app.EnablePanelBordersCheckBox = uicheckbox(app.DarkModePanel_2);
            app.EnablePanelBordersCheckBox.ValueChangedFcn = createCallbackFcn(app, @EnablePanelBordersCheckBoxValueChanged, true);
            app.EnablePanelBordersCheckBox.Text = 'Enable Panel Borders';
            app.DarkModeLogosCheckBox = uicheckbox(app.DarkModePanel_2);
            app.DarkModeLogosCheckBox.ValueChangedFcn = createCallbackFcn(app, @DarkModeLogosCheckBoxValueChanged, true);
            app.DarkModeLogosCheckBox.Text = 'Dark Mode Logos';
            app.BrightModePanel_2 = uipanel(app.MiscPanel);
            app.BrightModePanel_2.Title = 'Bright Mode';
            app.EnablePanelBordersCheckBox_2 = uicheckbox(app.BrightModePanel_2);
            app.EnablePanelBordersCheckBox_2.ValueChangedFcn = createCallbackFcn(app, @EnablePanelBordersCheckBox_2ValueChanged, true);
            app.EnablePanelBordersCheckBox_2.Text = 'Enable Panel Borders';
            app.DarkModeLogosCheckBox_2 = uicheckbox(app.BrightModePanel_2);
            app.DarkModeLogosCheckBox_2.ValueChangedFcn = createCallbackFcn(app, @DarkModeLogosCheckBox_2ValueChanged, true);
            app.DarkModeLogosCheckBox_2.Text = 'Dark Mode Logos';
            app.Copyright2021BalticRacingbyEricDorniedenLabel = uilabel(app.CustomizationAppUIFigure);
            app.Copyright2021BalticRacingbyEricDorniedenLabel.Text = 'Copyright © 2021 Baltic Racing by Eric Dornieden';
            app.Image2 = uiimage(app.CustomizationAppUIFigure);
            app.Image2.ImageSource = 'balticracing_logo_transparent.png';
            app.SimulationReplayPlotColorsPanel = uipanel(app.CustomizationAppUIFigure);
            app.SimulationReplayPlotColorsPanel.Title = 'Simulation Replay Plot Colors';
            app.plotcolorButton = uibutton(app.SimulationReplayPlotColorsPanel, 'push');
            app.plotcolorButton.ButtonPushedFcn = createCallbackFcn(app, @plotcolorButtonPushed, true);
            app.plotcolorButton.Text = '1. plot color';
            app.EditField_25 = uieditfield(app.SimulationReplayPlotColorsPanel, 'text');
            app.EditField_25.Editable = 'off';
            app.plotcolorButton_2 = uibutton(app.SimulationReplayPlotColorsPanel, 'push');
            app.plotcolorButton_2.ButtonPushedFcn = createCallbackFcn(app, @plotcolorButton_2Pushed, true);
            app.plotcolorButton_2.Text = '2. plot color';
            app.EditField_26 = uieditfield(app.SimulationReplayPlotColorsPanel, 'text');
            app.EditField_26.Editable = 'off';
            app.plotcolorButton_3 = uibutton(app.SimulationReplayPlotColorsPanel, 'push');
            app.plotcolorButton_3.ButtonPushedFcn = createCallbackFcn(app, @plotcolorButton_3Pushed, true);
            app.plotcolorButton_3.Text = '3. plot color';
            app.EditField_27 = uieditfield(app.SimulationReplayPlotColorsPanel, 'text');
            app.EditField_27.Editable = 'off';
            app.plotcolorButton_4 = uibutton(app.SimulationReplayPlotColorsPanel, 'push');
            app.plotcolorButton_4.ButtonPushedFcn = createCallbackFcn(app, @plotcolorButton_4Pushed, true);
            app.plotcolorButton_4.Text = '4. plot color';
            app.EditField_28 = uieditfield(app.SimulationReplayPlotColorsPanel, 'text');
            app.EditField_28.Editable = 'off';
            app.plotcolorButton_5 = uibutton(app.SimulationReplayPlotColorsPanel, 'push');
            app.plotcolorButton_5.ButtonPushedFcn = createCallbackFcn(app, @plotcolorButton_5Pushed, true);
            app.plotcolorButton_5.Text = '5. plot color';
            app.EditField_29 = uieditfield(app.SimulationReplayPlotColorsPanel, 'text');
            app.EditField_29.Editable = 'off';
            app.plotcolorButton_6 = uibutton(app.SimulationReplayPlotColorsPanel, 'push');
            app.plotcolorButton_6.ButtonPushedFcn = createCallbackFcn(app, @plotcolorButton_6Pushed, true);
            app.plotcolorButton_6.Text = '6. plot color';
            app.EditField_30 = uieditfield(app.SimulationReplayPlotColorsPanel, 'text');
            app.EditField_30.Editable = 'off';
            % Show the figure after all components are created
            app.CustomizationAppUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Customization(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.CustomizationAppUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.CustomizationAppUIFigure)
        end
    end
end