classdef Drivetrain < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        DrivetrainSetupUIFigure         matlab.ui.Figure
        PowertrainDataPanel             matlab.ui.container.Panel
        RekuperationPanel               matlab.ui.container.Panel
        MaxBrakingCurrentEditField      matlab.ui.control.EditField
        MaxBrakingCurrentEditFieldLabel  matlab.ui.control.Label
        EnableRecuCheckBox              matlab.ui.control.CheckBox
        UITable                         matlab.ui.control.Table
        Button_2                        matlab.ui.control.Button
        Button                          matlab.ui.control.Button
        Image2                          matlab.ui.control.Image
        Copyright2021BalticRacingbyEricDorniedenLabel  matlab.ui.control.Label
        Panel                           matlab.ui.container.Panel
        idle_rpm1minEditField           matlab.ui.control.NumericEditField
        idle_rpm1minEditFieldLabel      matlab.ui.control.Label
        TorquemultiplierEditField       matlab.ui.control.NumericEditField
        TorquemultiplierEditFieldLabel  matlab.ui.control.Label
        n_max1minEditField              matlab.ui.control.NumericEditField
        n_max1minEditFieldLabel         matlab.ui.control.Label
        DrivetrainEfficencyEditField    matlab.ui.control.NumericEditField
        DrivetrainEfficencyEditFieldLabel  matlab.ui.control.Label
        LayoutDropDown                  matlab.ui.control.DropDown
        LayoutDropDownLabel             matlab.ui.control.Label
        PowertrainSwitch                matlab.ui.control.Switch
        EPowertrainParametersPanel      matlab.ui.container.Panel
        InverterEfficiencyEditField     matlab.ui.control.NumericEditField
        InverterEfficiencyEditFieldLabel  matlab.ui.control.Label
        PowerlimitWEditField            matlab.ui.control.NumericEditField
        PowerlimitWEditFieldLabel       matlab.ui.control.Label
        SaveEngineButton                matlab.ui.control.Button
        DeleteEngineButton              matlab.ui.control.Button
        EnginePresetDropDown            matlab.ui.control.DropDown
        EnginePresetDropDownLabel       matlab.ui.control.Label
        Panel_2                         matlab.ui.container.Panel
        GearRatioLabel                  matlab.ui.control.Label
        z_chaindriveEditField           matlab.ui.control.NumericEditField
        z_chaindriveEditFieldLabel      matlab.ui.control.Label
        z_sprocketEditField             matlab.ui.control.NumericEditField
        z_sprocketEditFieldLabel        matlab.ui.control.Label
        ChaindriveSingleGearsetCheckBox  matlab.ui.control.CheckBox
        loadPanel                       matlab.ui.container.Panel
        TY20BaselineButton              matlab.ui.control.Button
        SaveButton                      matlab.ui.control.Button
        ResetButton                     matlab.ui.control.Button
        ElectricBaselineButton          matlab.ui.control.Button
        TY19BaselineButton              matlab.ui.control.Button
        ChaindrivePanel                 matlab.ui.container.Panel
        i_PEditField                    matlab.ui.control.NumericEditField
        i_PLabel                        matlab.ui.control.Label
        GearboxPanel                    matlab.ui.container.Panel
        n_downshift1minEditField        matlab.ui.control.NumericEditField
        n_downshift1minEditFieldLabel   matlab.ui.control.Label
        EnableGearboxCheckBox           matlab.ui.control.CheckBox
        PlotGearboxdataButton           matlab.ui.control.Button
        GearboxTable                    matlab.ui.control.Table
        Button_4                        matlab.ui.control.Button
        Button_3                        matlab.ui.control.Button
        n_shift1minEditField            matlab.ui.control.NumericEditField
        n_shift1minEditFieldLabel       matlab.ui.control.Label
        t_shiftsEditField               matlab.ui.control.NumericEditField
        t_shiftsEditFieldLabel          matlab.ui.control.Label
        UIAxes3                         matlab.ui.control.UIAxes
        UIAxes2                         matlab.ui.control.UIAxes
        UIAxes                          matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        CallingApp   % Main app object
    end
    
    methods (Access = public)
        % Function with updates the torque and power curves for the motor
        function updateMotorGraph(app)
            
            % updates the gear ration label and returns the gearratio
            i_tot = updateGearRatio(app);

            % reads the value of the torque multiplier
            torque_multiplier = app.TorquemultiplierEditField.Value;
            
            % reads the data from the UITable to data
            data = app.UITable.Data;
            
            % seperat data into two 1D arrays with an 0 added, because both
            % axis should start at zero (x-axis rpm, y-axis torque)
            x = [1; data(:,1)]; % n = RPM
            y = [0; data(:,2)] * torque_multiplier; % M = Torque
            
%            p = zeros(1:size(x,1));
            
            % Calculates the power of the motor for a given rpm and torque
            for n = 1 : length(x)
                p(n) = (y(n) * x(n)) / 9.5488 / 1000;
            end
            
            % Clears the UIAxes so it can be recreated
            cla(app.UIAxes,'reset');
            
            % Sets the axis
            app.UIAxes.XLabel.String='RPM [1/min]';
            yyaxis(app.UIAxes,'left');      
            
            plot(app.UIAxes,x,y);
            
            app.UIAxes.YLabel.String='Torque [Nm]';
            % holds plot so new axis can be drawn
            hold(app.UIAxes);
            title(app.UIAxes,"Single Motor");
            yyaxis(app.UIAxes,'right');
            app.UIAxes.YLabel.String='Power [kW]';
            plot(app.UIAxes,x,p);
            
            % disables zooming for the plot
            % disableDefaultInteractivity(app.UIAxes);
            
            % Checks which engine layout is selected and adjusts the number
            % of Motors acordingly
            Motorsdropdown = app.LayoutDropDown.Value;
            
            if (Motorsdropdown == "2 Motor RWD")
                numberMotors = 2;
            elseif (Motorsdropdown == "1 Motor RWD")
                numberMotors = 1;
            elseif (Motorsdropdown == "AWD")
                numberMotors = 4;
            end
            
            % Calculates the torque created by the given number of motors
            y_2 = y * numberMotors;
            
   %         p_2 = zeros(1:length(x));
            
            % Calculates the power of the motors for a given rpm and torque
            for n = 1 : length(x)
                p_2(n) = (y_2(n) * x(n)) / 9.5488 / 1000;
            end
            
            % Clears the UIAxes so it can be recreated
            cla(app.UIAxes2,'reset');
            
            % Sets the axis
            app.UIAxes2.XLabel.String='RPM [1/min]';
            yyaxis(app.UIAxes2,'left');
            plot(app.UIAxes2,x,y_2);
            app.UIAxes2.YLabel.String='Torque [Nm]';
            % holds plot so new axis can be drawn
            hold(app.UIAxes2);
            yyaxis(app.UIAxes2,'right');
            
            % Sets the title of the plot to the current number of motors
            title(app.UIAxes2,Motorsdropdown);
            app.UIAxes2.YLabel.String='Power [kW]';
            plot(app.UIAxes2,x,p_2);
            
            % disables zooming for the plot
%             disableDefaultInteractivity(app.UIAxes2);
            
%            plot(app.UIAxes,[1 2 3 4],'-r');
%            hold(app.UIAxes);
%            plot(app.UIAxes,[10 9 4 7],'--b');

            % Clears the UIAxes so it can be recreated
            cla(app.UIAxes3,'reset');
                  
            % Calculates the torque and the rpm at the wheels after the
            % secondary drivetrain with given losses
            x_final = x / i_tot;
            y_final = y_2 * i_tot * app.DrivetrainEfficencyEditField.Value;
            
            % Calculates Power at the driven wheels with losses
            P_out = p_2 * app.DrivetrainEfficencyEditField.Value * app.InverterEfficiencyEditField.Value;
                     
            % Sets the axis
            app.UIAxes3.XLabel.String='RPM [1/min]';
            yyaxis(app.UIAxes3,'left');
            plot(app.UIAxes3,x_final,y_final);
            app.UIAxes3.YLabel.String='Torque [Nm]';
            % holds plot so new axis can be drawn
            hold(app.UIAxes3);
            yyaxis(app.UIAxes3,'right');
            
            % Sets the title of the plot to the current number of motors
            title(app.UIAxes3,Motorsdropdown+" final");
            app.UIAxes3.YLabel.String='Power [kW]';
            plot(app.UIAxes3,x_final,P_out);
            
            changeColorScheme(app);
            
            % disables zooming for the plot
%             disableDefaultInteractivity(app.UIAxes3);

        end
        
        function i_tot = updateGearRatio(app)
            % updates the gear Ratio of secondary drivetrain
            % calculates i_ges
            i_tot = app.z_chaindriveEditField.Value / app.z_sprocketEditField.Value * app.i_PEditField.Value;
            app.GearRatioLabel.Text = "i_tot = " + i_tot;
        end
        
        function changeColorScheme(app)
            PrimaryColor = app.CallingApp.PrimaryColor;
            OnPrimaryColor = app.CallingApp.OnPrimaryColor;   
            PrimaryVariantColor = app.CallingApp.PrimaryVariantColor;
%             OnPrimaryVariantColor = app.CallingApp.OnPrimaryVariantColor;
%             SecondaryColor = app.CallingApp.SecondaryColor;
%             OnSecondaryColor = app.CallingApp.OnSecondaryColor;
            BackgroundColor = app.CallingApp.BackgroundColor;
            OnBackgroundColor = app.CallingApp.OnBackgroundColor;
            SurfaceColor = app.CallingApp.SurfaceColor;
            OnSurfaceColor = app.CallingApp.OnSurfaceColor;
%             ErrorColor = app.CallingApp.ErrorColor;
%             OnErrorColor = app.CallingApp.OnErrorColor;
            BorderType = app.CallingApp.BorderType;                 
%             Host_Logo = app.CallingApp.Host_Logo;
            
            app.DrivetrainSetupUIFigure.Color = BackgroundColor;
            
            app.Copyright2021BalticRacingbyEricDorniedenLabel.FontColor = OnBackgroundColor;
            
            app.PowertrainDataPanel.ForegroundColor = OnBackgroundColor;
            app.PowertrainDataPanel.BackgroundColor = BackgroundColor;
            app.PowertrainDataPanel.BorderType = BorderType;
            
            app.loadPanel.ForegroundColor = OnSurfaceColor;
            app.loadPanel.BackgroundColor = SurfaceColor;
            app.loadPanel.BorderType = BorderType;
            
            app.Panel.ForegroundColor = OnSurfaceColor;
            app.Panel.BackgroundColor = SurfaceColor;
            app.Panel.BorderType = BorderType;
            
            app.EPowertrainParametersPanel.ForegroundColor = OnSurfaceColor;
            app.EPowertrainParametersPanel.BackgroundColor = SurfaceColor;
            app.EPowertrainParametersPanel.BorderType = BorderType;
                       
            app.ChaindrivePanel.ForegroundColor = OnSurfaceColor;
            app.ChaindrivePanel.BackgroundColor = SurfaceColor;
            app.ChaindrivePanel.BorderType = BorderType;
            
            app.GearboxPanel.ForegroundColor = OnSurfaceColor;
            app.GearboxPanel.BackgroundColor = SurfaceColor;
            app.GearboxPanel.BorderType = BorderType;
            
            app.PowertrainSwitch.FontColor = OnSurfaceColor;
            
            app.LayoutDropDownLabel.FontColor = OnSurfaceColor;
            app.LayoutDropDown.BackgroundColor = PrimaryColor;
            app.LayoutDropDown.FontColor = OnPrimaryColor;
            
            app.DrivetrainEfficencyEditFieldLabel.FontColor = OnSurfaceColor;
            app.DrivetrainEfficencyEditField.BackgroundColor = SurfaceColor;
            app.DrivetrainEfficencyEditField.FontColor = OnSurfaceColor;
            
            app.n_max1minEditFieldLabel.FontColor = OnSurfaceColor;
            app.n_max1minEditField.BackgroundColor = SurfaceColor;
            app.n_max1minEditField.FontColor = OnSurfaceColor;
            
            app.TorquemultiplierEditFieldLabel.FontColor = OnSurfaceColor;
            app.TorquemultiplierEditField.BackgroundColor = SurfaceColor;
            app.TorquemultiplierEditField.FontColor = OnSurfaceColor;
            
            app.PowerlimitWEditFieldLabel.FontColor = OnSurfaceColor;
            app.PowerlimitWEditField.BackgroundColor = SurfaceColor;
            app.PowerlimitWEditField.FontColor = OnSurfaceColor;
            
            app.InverterEfficiencyEditFieldLabel.FontColor = OnSurfaceColor;
            app.InverterEfficiencyEditField.BackgroundColor = SurfaceColor;
            app.InverterEfficiencyEditField.FontColor = OnSurfaceColor;
            
            app.i_PLabel.FontColor = OnSurfaceColor;
            app.i_PEditField.BackgroundColor = SurfaceColor;
            app.i_PEditField.FontColor = OnSurfaceColor;
            
            app.z_sprocketEditFieldLabel.FontColor = OnSurfaceColor;
            app.z_sprocketEditField.BackgroundColor = SurfaceColor;
            app.z_sprocketEditField.FontColor = OnSurfaceColor;
            
            app.z_chaindriveEditFieldLabel.FontColor = OnSurfaceColor;
            app.z_chaindriveEditField.BackgroundColor = SurfaceColor;
            app.z_chaindriveEditField.FontColor = OnSurfaceColor;
            
            app.idle_rpm1minEditFieldLabel.FontColor = OnSurfaceColor;
            app.idle_rpm1minEditField.BackgroundColor = SurfaceColor;
            app.idle_rpm1minEditField.FontColor = OnSurfaceColor;
            
            app.GearRatioLabel.FontColor = OnSurfaceColor;
            
            app.EnableGearboxCheckBox.FontColor = OnSurfaceColor;
            
            app.t_shiftsEditFieldLabel.FontColor = OnSurfaceColor;
            app.t_shiftsEditField.BackgroundColor = SurfaceColor;
            app.t_shiftsEditField.FontColor = OnSurfaceColor;
            
            app.n_shift1minEditFieldLabel.FontColor = OnSurfaceColor;
            app.n_shift1minEditField.BackgroundColor = SurfaceColor;
            app.n_shift1minEditField.FontColor = OnSurfaceColor;
            
            app.n_downshift1minEditFieldLabel.FontColor = OnSurfaceColor;
            app.n_downshift1minEditField.BackgroundColor = SurfaceColor;
            app.n_downshift1minEditField.FontColor = OnSurfaceColor;
            
            app.PlotGearboxdataButton.FontColor = OnPrimaryColor;
            app.PlotGearboxdataButton.BackgroundColor = PrimaryVariantColor;
            
            app.SaveEngineButton.FontColor = OnPrimaryColor;
            app.SaveEngineButton.BackgroundColor = PrimaryVariantColor;

            app.DeleteEngineButton.FontColor = OnPrimaryColor;
            app.DeleteEngineButton.BackgroundColor = PrimaryVariantColor;

            app.EnginePresetDropDownLabel.FontColor = OnSurfaceColor;
            app.EnginePresetDropDown.BackgroundColor = SurfaceColor;
            app.EnginePresetDropDown.FontColor = OnSurfaceColor;

            app.Button.FontColor = OnPrimaryColor;
            app.Button.BackgroundColor = PrimaryVariantColor;
            
            app.Button_2.FontColor = OnPrimaryColor;
            app.Button_2.BackgroundColor = PrimaryVariantColor;
            
            app.Button_3.FontColor = OnPrimaryColor;
            app.Button_3.BackgroundColor = PrimaryVariantColor;
            
            app.Button_4.FontColor = OnPrimaryColor;
            app.Button_4.BackgroundColor = PrimaryVariantColor;
            
            app.TY19BaselineButton.FontColor = OnPrimaryColor;
            app.TY19BaselineButton.BackgroundColor = PrimaryVariantColor;
            
            app.TY20BaselineButton.FontColor = OnPrimaryColor;
            app.TY20BaselineButton.BackgroundColor = PrimaryVariantColor;
            
            app.ElectricBaselineButton.FontColor = OnPrimaryColor;
            app.ElectricBaselineButton.BackgroundColor = PrimaryVariantColor;     
            
            app.UIAxes.Title.Color = OnPrimaryColor;
            app.UIAxes.XColor = OnPrimaryColor;
            %app.UIAxes.YColor = OnPrimaryColor;
            app.UIAxes.Color = SurfaceColor;
            
            app.UIAxes2.Title.Color = OnPrimaryColor;
            app.UIAxes2.XColor = OnPrimaryColor;
            %app.UIAxes2.YColor = OnPrimaryColor;
            app.UIAxes2.Color = SurfaceColor;
            
            app.UIAxes3.Title.Color = OnPrimaryColor;
            app.UIAxes3.XColor = OnPrimaryColor;
            %app.UIAxes3.YColor = OnPrimaryColor;
            app.UIAxes3.Color = SurfaceColor;
            
            app.ResetButton.FontColor = OnPrimaryColor;
            app.ResetButton.BackgroundColor = PrimaryVariantColor;
            
            app.SaveButton.FontColor = OnPrimaryColor;
            app.SaveButton.BackgroundColor = PrimaryVariantColor;

            app.Panel_2.ForegroundColor = OnSurfaceColor;
            app.Panel_2.BackgroundColor = SurfaceColor;
            app.Panel_2.BorderType = BorderType;

            app.ChaindriveSingleGearsetCheckBox.FontColor = OnPrimaryColor;
        end
    end
    
    methods (Access = private)
        
        function saveData(app)
            app.CallingApp.engineData = app.EnginePresetDropDown.Items;

            app.CallingApp.p_max = app.PowerlimitWEditField.Value;
            app.CallingApp.drivetrain_eff = app.DrivetrainEfficencyEditField.Value;
            app.CallingApp.trq_multiplier = app.TorquemultiplierEditField.Value;
            app.CallingApp.i_param = app.GearboxTable.Data(:,1);
            app.CallingApp.invertor_eff = app.InverterEfficiencyEditField.Value;
            
            app.CallingApp.gearbox = app.EnableGearboxCheckBox.Value;
            app.CallingApp.i_P = app.i_PEditField.Value;
            app.CallingApp.z_chaindrive = app.z_chaindriveEditField.Value;
            app.CallingApp.z_sprocket = app.z_sprocketEditField.Value;
            app.CallingApp.n_max = app.n_max1minEditField.Value;
            app.CallingApp.engine_param = app.UITable.Data;
            app.CallingApp.recu_enabled = app.EnableRecuCheckBox.Value;
            
            if app.PowertrainSwitch.Value == "Electric"
                app.CallingApp.ptype = 1; 
            else
                app.CallingApp.ptype = 0; 
            end
          
            app.CallingApp.n_shift = app.n_shift1minEditField.Value;
            app.CallingApp.n_downshift = app.n_downshift1minEditField.Value;
            app.CallingApp.t_shift = app.t_shiftsEditField.Value;
            app.CallingApp.idleRPM = app.idle_rpm1minEditField.Value;

            % Checks which engine layout is selected and adjusts the number
            % of Motors acordingly
            Motorsdropdown = app.LayoutDropDown.Value;
            
            if (Motorsdropdown == "2 Motor RWD")
                app.CallingApp.num_motors = 2;
            elseif (Motorsdropdown == "1 Motor RWD")
                app.CallingApp.num_motors = 1;
            elseif (Motorsdropdown == "AWD")
                app.CallingApp.num_motors = 4;
            end  
        end
        
        function loadData(app)
            app.EnginePresetDropDown.Items = app.CallingApp.engineData;

            app.PowerlimitWEditField.Value = app.CallingApp.p_max;
            app.DrivetrainEfficencyEditField.Value = app.CallingApp.drivetrain_eff;
            app.TorquemultiplierEditField.Value = app.CallingApp.trq_multiplier;
            app.GearboxTable.Data = app.CallingApp.i_param;
            app.InverterEfficiencyEditField.Value = app.CallingApp.invertor_eff;
            
            updateGearRatios(app);

            app.EnableGearboxCheckBox.Value = app.CallingApp.gearbox;
            app.i_PEditField.Value = app.CallingApp.i_P;
            app.z_chaindriveEditField.Value = app.CallingApp.z_chaindrive;
            app.z_sprocketEditField.Value = app.CallingApp.z_sprocket;
            app.n_max1minEditField.Value = app.CallingApp.n_max;
            app.UITable.Data = app.CallingApp.engine_param; 
            app.EnableRecuCheckBox.Value= app.CallingApp.recu_enabled;
            
            updateGearbox(app);

            app.n_shift1minEditField.Value = app.CallingApp.n_shift;
            app.n_downshift1minEditField.Value = app.CallingApp.n_downshift;
            app.t_shiftsEditField.Value = app.CallingApp.t_shift;
            app.idle_rpm1minEditField.Value = app.CallingApp.idleRPM;
            
            % sets EngineLayoutDropDown text
            if (app.CallingApp.num_motors == 1) 
                app.LayoutDropDown.Value = "1 Motor RWD";           
            elseif (app.CallingApp.num_motors == 2)
                app.LayoutDropDown.Value = "2 Motor RWD";
            elseif (app.CallingApp.num_motors == 4)
                app.LayoutDropDown.Value = "AWD";
            end
            
            % E/C Panel activation
            if app.PowertrainSwitch.Value == "Electric"
                % E Parameters enabled
                app.PowerlimitWEditField.Enable = true;
                app.InverterEfficiencyEditField.Enable = true;
            else
                % E Parameters disabled
                app.PowerlimitWEditField.Enable = false;
                app.InverterEfficiencyEditField.Enable = false;
            end
               
            updateMotorGraph(app);       
        end
        
        function updateGearRatios(app)
            % Updates the gear ratio table with the caclculated final gear
            % ratios
            for i = 1:size(app.GearboxTable.Data, 1)
                i_tot = updateGearRatio(app);
                
                app.GearboxTable.Data(i,2) = app.GearboxTable.Data(i,1) * i_tot;
                app.GearboxTable.Data(i,3) =  app.n_max1minEditField.Value/(30/pi*app.GearboxTable.Data(i,2)/app.CallingApp.R0)*3.6;
            end
        end
        
        function updateGearbox(app)
            if app.EnableGearboxCheckBox.Value
                app.t_shiftsEditField.Enable = 'on';
                app.t_shiftsEditFieldLabel.Enable = 'on';
                app.n_shift1minEditField.Enable = 'on';
                app.n_shift1minEditFieldLabel.Enable = 'on';
                app.n_downshift1minEditField.Enable = 'on';
                app.n_downshift1minEditFieldLabel.Enable = 'on';
                app.GearboxTable.Enable = 'on';
                app.Button_3.Enable = 'on';
                app.Button_4.Enable = 'on';
                app.PlotGearboxdataButton.Enable = 'on';   
            else
                app.t_shiftsEditField.Enable = 'off';
                app.t_shiftsEditFieldLabel.Enable = 'off';
                app.n_shift1minEditField.Enable = 'off';
                app.n_shift1minEditFieldLabel.Enable = 'off';
                app.n_downshift1minEditField.Enable = 'off';
                app.n_downshift1minEditFieldLabel.Enable = 'off';
                app.GearboxTable.Enable = 'off';
                app.Button_3.Enable = 'off';
                app.Button_4.Enable = 'off';
                app.PlotGearboxdataButton.Enable = 'off';    
            end
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            % Store main app object
            app.CallingApp = mainapp;
            
            loadData(app);
            
            changeColorScheme(app);
        end

        % Cell edit callback: UITable
        function UITableCellEdit(app, event)
            % Updates the plot of the motor torque and power from the given
            % UIable Data
            updateMotorGraph(app);
        end

        % Value changed function: LayoutDropDown
        function LayoutDropDownValueChanged(app, event)
            updateMotorGraph(app);
        end

        % Value changed function: TorquemultiplierEditField
        function TorquemultiplierEditFieldValueChanged(app, event)
           updateMotorGraph(app);
        end

        % Value changed function: z_chaindriveEditField
        function z_chaindriveEditFieldValueChanged(app, event)
            updateMotorGraph(app);
        end

        % Value changed function: z_sprocketEditField
        function z_sprocketEditFieldValueChanged(app, event)
            updateMotorGraph(app);
        end

        % Value changed function: DrivetrainEfficencyEditField
        function DrivetrainEfficencyEditFieldValueChanged(app, event)
            updateMotorGraph(app);
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            % Adds an entry to the UITable
            data = app.UITable.Data;
            app.UITable.Data = [data; [0 0]];
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            % Deletes the last entry in the UITable
            data = app.UITable.Data;
            data((length(data)),:) = [];
            app.UITable.Data = data;
        end

        % Close request function: DrivetrainSetupUIFigure
        function DrivetrainSetupUIFigureCloseRequest(app, event)
            saveData(app);
            
            % Disable Drivetrain Options button while dialog is open
            app.CallingApp.DrivetrainButton.Enable = 'on';
            
            delete(app)            
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            saveData(app);
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            loadData(app);
        end

        % Value changed function: InverterEfficiencyEditField
        function InverterEfficiencyEditFieldValueChanged(app, event)
            updateMotorGraph(app);
        end

        % Value changed function: PowertrainSwitch
        function PowertrainSwitchValueChanged(app, event)
            value = app.PowertrainSwitch.Value;
            if value == "Electric"      
                % E Parameters enabled (additional parameters)
                app.PowerlimitWEditField.Enable = true;
                app.InverterEfficiencyEditField.Enable = true;
                
                app.CallingApp.ptype = 1; 
            else           
                % E Parameters disabled (additional parameters)
                app.PowerlimitWEditField.Enable = false;
                app.InverterEfficiencyEditField.Enable = false;
                
                app.CallingApp.ptype = 0;              
             end
            updateMotorGraph(app)
            updateGearRatio(app)
        end

        % Button pushed function: Button_3
        function Button_3Pushed(app, event)
            data = app.GearboxTable.Data;
            app.GearboxTable.Data = [data; 0,0,0];
        end

        % Button pushed function: Button_4
        function Button_4Pushed(app, event)
            % Deletes the last entry in the UITable
            data = app.GearboxTable.Data;
            data((size(data,1)),:,:) = [];
            app.GearboxTable.Data = data;
        end

        % Value changed function: i_PEditField
        function i_PEditFieldValueChanged(app, event)
            updateMotorGraph(app);
        end

        % Button pushed function: PlotGearboxdataButton
        function PlotGearboxdataButtonPushed(app, event)
            plotGearboxData(app.GearboxTable.Data,app.n_shift1minEditField.Value,app.t_shiftsEditField.Value,app.n_max1minEditField.Value);
        end

        % Cell edit callback: GearboxTable
        function GearboxTableCellEdit(app, event)
            updateGearRatios(app);      
        end

        % Value changed function: EnableGearboxCheckBox
        function EnableGearboxCheckBoxValueChanged(app, event)
            updateGearbox(app);            
        end

        % Button pushed function: TY19BaselineButton
        function TY19BaselineButtonPushed(app, event)
            app.PowertrainSwitch.Value = "Combustion";
            app.PowerlimitWEditField.Enable = false;
            app.InverterEfficiencyEditField.Enable = false;
            app.EnableGearboxCheckBox.Value = 1;
            
            triumphEngine = load('Dynojet Momentenverlauf.mat'); 
                
            app.CallingApp.ptype = 0; 
            app.CallingApp.n_max = 11000;
            app.CallingApp.i_param = [1.857; 1.565; 1.35; 1.238; 1.136];
            app.CallingApp.i_P = 1.848;
            app.CallingApp.z_chaindrive = 37;
            app.CallingApp.z_sprocket = 11;  
            app.CallingApp.gearbox = 1;
            app.CallingApp.engine_param = [triumphEngine.n(:) triumphEngine.M(:)];
            app.CallingApp.num_motors = 1;
            app.CallingApp.idleRPM = 2000;
            
            loadData(app);
        end

        % Button pushed function: ElectricBaselineButton
        function ElectricBaselineButtonPushed(app, event)
            app.PowertrainSwitch.Value = "Electric";
            app.PowerlimitWEditField.Enable = true;
            app.InverterEfficiencyEditField.Enable = true;
            app.EnableGearboxCheckBox.Value = 0;
                
            app.CallingApp.ptype = 1; 
            app.CallingApp.n_max = 6000;
            app.CallingApp.i_param = 1;
            app.CallingApp.i_P = 1;
            app.CallingApp.z_chaindrive = 66;
            app.CallingApp.z_sprocket = 17;     
            app.CallingApp.gearbox = 0;
            app.CallingApp.engine_param = [0 140; 1000 140; 2000 138; 3000 136; 4000 135; 5000 127; 6000 115];
            app.CallingApp.num_motors = 2;
            app.CallingApp.idleRPM = 0;
            
            loadData(app);
        end

        % Button pushed function: TY20BaselineButton
        function TY20BaselineButtonPushed(app, event)
            app.PowertrainSwitch.Value = "Combustion";
            app.PowerlimitWEditField.Enable = false;
            app.InverterEfficiencyEditField.Enable = false;
            app.EnableGearboxCheckBox.Value = 1;
            
            triumphEngine = load('Dynojet Momentenverlauf.mat'); 
                
            app.CallingApp.ptype = 0; 
            app.CallingApp.n_max = 11000;
            app.CallingApp.i_param = [2.579; 1.720; 1.429; 1.238];
            app.CallingApp.i_P = 1.848;
            app.CallingApp.z_chaindrive = 37;
            app.CallingApp.z_sprocket = 11;  
            app.CallingApp.gearbox = 1;
            app.CallingApp.engine_param = [triumphEngine.n(:) triumphEngine.M(:)];
            app.CallingApp.num_motors = 1;
            app.CallingApp.idleRPM = 2000;
            
            loadData(app);
        end

        % Value changed function: ChaindriveSingleGearsetCheckBox
        function ChaindriveSingleGearsetCheckBoxValueChanged(app, event)
            value = app.ChaindriveSingleGearsetCheckBox.Value;
            
            if value
                app.i_chain = app.z_sprocketEditField.Value * app.z_chaindriveEditField.Value;
                app.z_sprocketEditField.Enable =  'true';
            else
                app.i_chain = 1;
            end
        end

        % Value changed function: EnginePresetDropDown
        function EnginePresetDropDownValueChanged(app, event)
            % name of currently selected engine
            value = app.EnginePresetDropDown.Value;

            % Load .mat file for the selected engine
            engine = load([value '.mat']); 
            % Load engine parameters from the .mat file
            app.CallingApp.engine_param = [engine.n(:) engine.M(:)];

            % Update UI
            loadData(app);
        end

        % Button pushed function: SaveEngineButton
        function SaveEngineButtonPushed(app, event)
            data = app.UITable.Data;     
            n = data(:,1);
            M = data(:,2);

            % Opens a save file dialog
            [filename, pathname] = uiputfile('*.mat','Save Engine Variables As','new Engine.mat');
    
            newfilename = fullfile(pathname, filename);
    
            % Saves file with the given parameters
            save(newfilename, 'n','M');

            % Add new Engine to dropdown if name isn't already part of the
            % list.
            if ~strcmp(app.EnginePresetDropDown.Items, filename(1:end-4))
                data = app.EnginePresetDropDown.Items;
                data(end) = [];
                app.EnginePresetDropDown.Items = [data filename(1:end-4) "new Engine"];
                app.CallingApp.engineData = app.EnginePresetDropDown.Items;
            end
        end

        % Button pushed function: DeleteEngineButton
        function DeleteEngineButtonPushed(app, event)
             % name of currently selected engine
            selected = app.EnginePresetDropDown.Value;

            % Catch if user tries to delete 'new Engine' Entry
            if selected == "new Engine"
                app.CallingApp.engine_param = [0 0; 1000 0; 2000 0; 3000 0; 4000 0; 5000 0; 6000 0];

                % Update UI
                loadData(app);

                return;
            end

            data = app.EnginePresetDropDown.Items;
            % Find the index of the selected engine and remove the content
            ind = find(strcmp(data,selected));
            data(ind) = [];

            % update dropdown
            app.EnginePresetDropDown.Items = data;
            app.CallingApp.engineData = data;

            % Switch to first engine
            app.EnginePresetDropDown.Value = app.EnginePresetDropDown.Items(1);

            % Update UI
            loadData(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.DrivetrainSetupUIFigure = uifigure('Visible', 'off');
            app.DrivetrainSetupUIFigure.Name = 'Drivetrain Setup';
            app.DrivetrainSetupUIFigure.Icon = 'Baltic.png';
            app.DrivetrainSetupUIFigure.CloseRequestFcn = createCallbackFcn(app, @DrivetrainSetupUIFigureCloseRequest, true);
            app.PowertrainDataPanel = uipanel(app.DrivetrainSetupUIFigure);
            app.PowertrainDataPanel.Title = 'Powertrain Data';
            app.UIAxes = uiaxes(app.PowertrainDataPanel);
            title(app.UIAxes, 'Single Motor')
            xlabel(app.UIAxes, 'RPM [1/min]')
            ylabel(app.UIAxes, 'Torque [Nm]')
            app.UIAxes2 = uiaxes(app.PowertrainDataPanel);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            app.UIAxes3 = uiaxes(app.PowertrainDataPanel);
            title(app.UIAxes3, 'Title')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            app.GearboxPanel = uipanel(app.PowertrainDataPanel);
            app.GearboxPanel.Title = 'Gearbox';
            app.t_shiftsEditFieldLabel = uilabel(app.GearboxPanel);
            app.t_shiftsEditFieldLabel.Text = 't_shift [s]';
            app.t_shiftsEditField = uieditfield(app.GearboxPanel, 'numeric');
            app.t_shiftsEditField.LowerLimitInclusive = 'off';
            app.t_shiftsEditField.UpperLimitInclusive = 'off';
            app.t_shiftsEditField.Limits = [0 Inf];
            app.t_shiftsEditField.Value = 0.035;
            app.n_shift1minEditFieldLabel = uilabel(app.GearboxPanel);
            app.n_shift1minEditFieldLabel.Text = 'n_shift [1/min]';
            app.n_shift1minEditField = uieditfield(app.GearboxPanel, 'numeric');
            app.n_shift1minEditField.UpperLimitInclusive = 'off';
            app.n_shift1minEditField.Limits = [1 Inf];
            app.n_shift1minEditField.ValueDisplayFormat = '%.0f';
            app.n_shift1minEditField.Tooltip = {''};
            app.n_shift1minEditField.Value = 10500;
            app.Button_3 = uibutton(app.GearboxPanel, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @Button_3Pushed, true);
            app.Button_3.Text = '+';
            app.Button_4 = uibutton(app.GearboxPanel, 'push');
            app.Button_4.ButtonPushedFcn = createCallbackFcn(app, @Button_4Pushed, true);
            app.Button_4.Text = '-';
            app.GearboxTable = uitable(app.GearboxPanel);
            app.GearboxTable.ColumnName = {'Gear Ratio'; 'Final Ratio'; 'Vmax [km/h]'};
            app.GearboxTable.RowName = {};
            app.GearboxTable.ColumnEditable = [true false false];
            app.GearboxTable.CellEditCallback = createCallbackFcn(app, @GearboxTableCellEdit, true);
            app.PlotGearboxdataButton = uibutton(app.GearboxPanel, 'push');
            app.PlotGearboxdataButton.ButtonPushedFcn = createCallbackFcn(app, @PlotGearboxdataButtonPushed, true);
            app.PlotGearboxdataButton.Text = 'Plot Gearbox data';
            app.EnableGearboxCheckBox = uicheckbox(app.GearboxPanel);
            app.EnableGearboxCheckBox.ValueChangedFcn = createCallbackFcn(app, @EnableGearboxCheckBoxValueChanged, true);
            app.EnableGearboxCheckBox.Text = 'Enable Gearbox';
            app.n_downshift1minEditFieldLabel = uilabel(app.GearboxPanel);
            app.n_downshift1minEditFieldLabel.Text = 'n_downshift [1/min]';
            app.n_downshift1minEditField = uieditfield(app.GearboxPanel, 'numeric');
            app.n_downshift1minEditField.UpperLimitInclusive = 'off';
            app.n_downshift1minEditField.Limits = [1 Inf];
            app.n_downshift1minEditField.ValueDisplayFormat = '%.0f';
            app.n_downshift1minEditField.Tooltip = {''};
            app.n_downshift1minEditField.Value = 6000;
            app.ChaindrivePanel = uipanel(app.PowertrainDataPanel);
            app.ChaindrivePanel.Title = 'Chaindrive';
            app.i_PLabel = uilabel(app.ChaindrivePanel);
            app.i_PLabel.Text = 'i_P [-]';
            app.i_PEditField = uieditfield(app.ChaindrivePanel, 'numeric');
            app.i_PEditField.LowerLimitInclusive = 'off';
            app.i_PEditField.UpperLimitInclusive = 'off';
            app.i_PEditField.Limits = [0 Inf];
            app.i_PEditField.ValueChangedFcn = createCallbackFcn(app, @i_PEditFieldValueChanged, true);
            app.i_PEditField.Value = 1;
            app.loadPanel = uipanel(app.PowertrainDataPanel);
            app.loadPanel.Title = 'load';
            app.TY19BaselineButton = uibutton(app.loadPanel, 'push');
            app.TY19BaselineButton.ButtonPushedFcn = createCallbackFcn(app, @TY19BaselineButtonPushed, true);
            app.TY19BaselineButton.Text = 'TY-19 Baseline';
            app.ElectricBaselineButton = uibutton(app.loadPanel, 'push');
            app.ElectricBaselineButton.ButtonPushedFcn = createCallbackFcn(app, @ElectricBaselineButtonPushed, true);
            app.ElectricBaselineButton.Text = 'Electric Baseline';
            app.ResetButton = uibutton(app.loadPanel, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.SaveButton = uibutton(app.loadPanel, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.TY20BaselineButton = uibutton(app.loadPanel, 'push');
            app.TY20BaselineButton.ButtonPushedFcn = createCallbackFcn(app, @TY20BaselineButtonPushed, true);
            app.TY20BaselineButton.Text = 'TY-20 Baseline';
            app.Panel_2 = uipanel(app.PowertrainDataPanel);
            app.ChaindriveSingleGearsetCheckBox = uicheckbox(app.Panel_2);
            app.ChaindriveSingleGearsetCheckBox.ValueChangedFcn = createCallbackFcn(app, @ChaindriveSingleGearsetCheckBoxValueChanged, true);
            app.ChaindriveSingleGearsetCheckBox.Text = {'Chaindrive /'; 'Single Gearset'};
            app.z_sprocketEditFieldLabel = uilabel(app.Panel_2);
            app.z_sprocketEditFieldLabel.Text = 'z_sprocket [-]';
            app.z_sprocketEditField = uieditfield(app.Panel_2, 'numeric');
            app.z_sprocketEditField.UpperLimitInclusive = 'off';
            app.z_sprocketEditField.Limits = [1 Inf];
            app.z_sprocketEditField.ValueChangedFcn = createCallbackFcn(app, @z_sprocketEditFieldValueChanged, true);
            app.z_sprocketEditField.Value = 17;
            app.z_chaindriveEditFieldLabel = uilabel(app.Panel_2);
            app.z_chaindriveEditFieldLabel.Text = 'z_chaindrive [-]';
            app.z_chaindriveEditField = uieditfield(app.Panel_2, 'numeric');
            app.z_chaindriveEditField.UpperLimitInclusive = 'off';
            app.z_chaindriveEditField.Limits = [1 Inf];
            app.z_chaindriveEditField.ValueChangedFcn = createCallbackFcn(app, @z_chaindriveEditFieldValueChanged, true);
            app.z_chaindriveEditField.Value = 66;
            app.GearRatioLabel = uilabel(app.Panel_2);
            app.GearRatioLabel.Text = 'Gear Ratio';
            app.EnginePresetDropDownLabel = uilabel(app.PowertrainDataPanel);
            app.EnginePresetDropDownLabel.Text = 'Engine Preset';
            app.EnginePresetDropDown = uidropdown(app.PowertrainDataPanel);
            app.EnginePresetDropDown.Items = {'Triumph_D67LD', 'EMRAX_188_TorqueData_Peak', 'EMRAX_208_TorqueData_Peak', 'Emrax 228', 'Emrax 268', 'Emrax 348', 'Rotax_125_Junior_MAX_evo', 'Rotax_125_MAX_DD2_evo', 'Rotax_125_MAX_evo', 'new Engine'};
            app.EnginePresetDropDown.ValueChangedFcn = createCallbackFcn(app, @EnginePresetDropDownValueChanged, true);
            app.EnginePresetDropDown.Value = 'Triumph_D67LD';
            app.DeleteEngineButton = uibutton(app.PowertrainDataPanel, 'push');
            app.DeleteEngineButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteEngineButtonPushed, true);
            app.DeleteEngineButton.Tooltip = {'Deletes the currently selected Cell if more than one Cell type is remaining.'};
            app.DeleteEngineButton.Text = 'Delete Engine';
            app.SaveEngineButton = uibutton(app.PowertrainDataPanel, 'push');
            app.SaveEngineButton.ButtonPushedFcn = createCallbackFcn(app, @SaveEngineButtonPushed, true);
            app.SaveEngineButton.Text = 'Save Engine';
            app.EPowertrainParametersPanel = uipanel(app.PowertrainDataPanel);
            app.EPowertrainParametersPanel.Title = 'E Powertrain Parameters';
            app.PowerlimitWEditFieldLabel = uilabel(app.EPowertrainParametersPanel);
            app.PowerlimitWEditFieldLabel.Text = 'Power limit [W]';
            app.PowerlimitWEditField = uieditfield(app.EPowertrainParametersPanel, 'numeric');
            app.PowerlimitWEditField.LowerLimitInclusive = 'off';
            app.PowerlimitWEditField.UpperLimitInclusive = 'off';
            app.PowerlimitWEditField.Limits = [0 Inf];
            app.PowerlimitWEditField.ValueDisplayFormat = '%.0f';
            app.PowerlimitWEditField.Value = 80000;
            app.InverterEfficiencyEditFieldLabel = uilabel(app.EPowertrainParametersPanel);
            app.InverterEfficiencyEditFieldLabel.Text = 'Inverter Efficiency [-]';
            app.InverterEfficiencyEditField = uieditfield(app.EPowertrainParametersPanel, 'numeric');
            app.InverterEfficiencyEditField.LowerLimitInclusive = 'off';
            app.InverterEfficiencyEditField.UpperLimitInclusive = 'off';
            app.InverterEfficiencyEditField.Limits = [0 Inf];
            app.InverterEfficiencyEditField.ValueChangedFcn = createCallbackFcn(app, @InverterEfficiencyEditFieldValueChanged, true);
            app.InverterEfficiencyEditField.Value = 0.95;
            app.Panel = uipanel(app.PowertrainDataPanel);
            app.Panel.Title = 'Panel';
            app.PowertrainSwitch = uiswitch(app.Panel, 'slider');
            app.PowertrainSwitch.Items = {'Electric', 'Combustion'};
            app.PowertrainSwitch.ValueChangedFcn = createCallbackFcn(app, @PowertrainSwitchValueChanged, true);
            app.PowertrainSwitch.Value = 'Electric';
            app.LayoutDropDownLabel = uilabel(app.Panel);
            app.LayoutDropDownLabel.Text = 'Layout';
            app.LayoutDropDown = uidropdown(app.Panel);
            app.LayoutDropDown.Items = {'2 Motor RWD', '1 Motor RWD', 'AWD'};
            app.LayoutDropDown.ValueChangedFcn = createCallbackFcn(app, @LayoutDropDownValueChanged, true);
            app.LayoutDropDown.Value = '2 Motor RWD';
            app.DrivetrainEfficencyEditFieldLabel = uilabel(app.Panel);
            app.DrivetrainEfficencyEditFieldLabel.Text = 'Drivetrain Efficency [-]';
            app.DrivetrainEfficencyEditField = uieditfield(app.Panel, 'numeric');
            app.DrivetrainEfficencyEditField.LowerLimitInclusive = 'off';
            app.DrivetrainEfficencyEditField.Limits = [0 1];
            app.DrivetrainEfficencyEditField.ValueChangedFcn = createCallbackFcn(app, @DrivetrainEfficencyEditFieldValueChanged, true);
            app.DrivetrainEfficencyEditField.Value = 0.95;
            app.n_max1minEditFieldLabel = uilabel(app.Panel);
            app.n_max1minEditFieldLabel.Text = 'n_max [1/min]';
            app.n_max1minEditField = uieditfield(app.Panel, 'numeric');
            app.n_max1minEditField.UpperLimitInclusive = 'off';
            app.n_max1minEditField.Limits = [1 Inf];
            app.n_max1minEditField.ValueDisplayFormat = '%.0f';
            app.n_max1minEditField.Value = 6000;
            app.TorquemultiplierEditFieldLabel = uilabel(app.Panel);
            app.TorquemultiplierEditFieldLabel.Text = 'Torque multiplier [-]';
            app.TorquemultiplierEditField = uieditfield(app.Panel, 'numeric');
            app.TorquemultiplierEditField.LowerLimitInclusive = 'off';
            app.TorquemultiplierEditField.UpperLimitInclusive = 'off';
            app.TorquemultiplierEditField.Limits = [0 Inf];
            app.TorquemultiplierEditField.ValueChangedFcn = createCallbackFcn(app, @TorquemultiplierEditFieldValueChanged, true);
            app.TorquemultiplierEditField.Value = 1;
            app.idle_rpm1minEditFieldLabel = uilabel(app.Panel);
            app.idle_rpm1minEditFieldLabel.Text = 'idle_rpm [1/min]';
            app.idle_rpm1minEditField = uieditfield(app.Panel, 'numeric');
            app.idle_rpm1minEditField.UpperLimitInclusive = 'off';
            app.idle_rpm1minEditField.Limits = [0 Inf];
            app.idle_rpm1minEditField.Value = 2000;
            app.Copyright2021BalticRacingbyEricDorniedenLabel = uilabel(app.PowertrainDataPanel);
            app.Copyright2021BalticRacingbyEricDorniedenLabel.Text = 'Copyright © 2021 Baltic Racing by Eric Dornieden';
            app.Image2 = uiimage(app.PowertrainDataPanel);
            app.Image2.ImageSource = 'balticracing_logo_transparent.png';
            app.Button = uibutton(app.PowertrainDataPanel, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '+';
            app.Button_2 = uibutton(app.PowertrainDataPanel, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = '-';
            app.UITable = uitable(app.PowertrainDataPanel);
            app.UITable.ColumnName = {'RPM [1/min]'; 'Torque [Nm]'};
            app.UITable.RowName = {};
            app.UITable.ColumnEditable = true;
            app.UITable.CellEditCallback = createCallbackFcn(app, @UITableCellEdit, true);
            app.RekuperationPanel = uipanel(app.PowertrainDataPanel);
            app.RekuperationPanel.Title = 'Rekuperation';
            app.EnableRecuCheckBox = uicheckbox(app.RekuperationPanel);
            app.EnableRecuCheckBox.Text = 'Enable Recu';
            app.MaxBrakingCurrentEditFieldLabel = uilabel(app.RekuperationPanel);
            app.MaxBrakingCurrentEditFieldLabel.Text = 'Max Braking Current';
            app.MaxBrakingCurrentEditField = uieditfield(app.RekuperationPanel, 'text');
            app.MaxBrakingCurrentEditField.Value = '0';
            % Show the figure after all components are created
            app.DrivetrainSetupUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Drivetrain(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.DrivetrainSetupUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.DrivetrainSetupUIFigure)
        end
    end
end