classdef Environment < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        EnvironmentSetupUIFigure  matlab.ui.Figure
        Copyright2021BalticRacingbyEricDorniedenLabel  matlab.ui.control.Label
        ResetButton               matlab.ui.control.Button
        gmsEditField              matlab.ui.control.NumericEditField
        gmsEditFieldLabel         matlab.ui.control.Label
        SaveButton                matlab.ui.control.Button
        AirPanel                  matlab.ui.container.Panel
        R_LJkgKEditField          matlab.ui.control.NumericEditField
        R_LJkgKEditFieldLabel     matlab.ui.control.Label
        p_LPaEditField            matlab.ui.control.NumericEditField
        p_LPaEditField_2Label     matlab.ui.control.Label
        t_LCEditField             matlab.ui.control.NumericEditField
        t_LCEditField_2Label      matlab.ui.control.Label
    end

    
    properties (Access = private)
        CallingApp   % Main app object
    end
    
    methods (Access = private)
        
        function saveData(app)
            app.CallingApp.t_L = app.t_LCEditField.Value;
            app.CallingApp.p_L = app.p_LPaEditField.Value;
            app.CallingApp.R_L = app.R_LJkgKEditField.Value;
            app.CallingApp.g = app.gmsEditField.Value;
        end
        
        function loadApp(app)
            app.t_LCEditField.Value = app.CallingApp.t_L;
            app.p_LPaEditField.Value = app.CallingApp.p_L;
            app.R_LJkgKEditField.Value = app.CallingApp.R_L;
            app.gmsEditField.Value = app.CallingApp.g;
        end
    end
    
    methods (Access = public)
        
        function changeColorScheme(app)
            PrimaryColor = app.CallingApp.PrimaryColor;
            OnPrimaryColor = app.CallingApp.OnPrimaryColor;   
            PrimaryVariantColor = app.CallingApp.PrimaryVariantColor;
            OnPrimaryVariantColor = app.CallingApp.OnPrimaryVariantColor;
            SecondaryColor = app.CallingApp.SecondaryColor;
            OnSecondaryColor = app.CallingApp.OnSecondaryColor;
            BackgroundColor = app.CallingApp.BackgroundColor;
            OnBackgroundColor = app.CallingApp.OnBackgroundColor;
            SurfaceColor = app.CallingApp.SurfaceColor;
            OnSurfaceColor = app.CallingApp.OnSurfaceColor;
            ErrorColor = app.CallingApp.ErrorColor;
            OnErrorColor = app.CallingApp.OnErrorColor;
            BorderType = app.CallingApp.BorderType;                 
            Host_Logo = app.CallingApp.Host_Logo;
            
            app.EnvironmentSetupUIFigure.Color = BackgroundColor;
            app.Copyright2021BalticRacingbyEricDorniedenLabel.FontColor = OnBackgroundColor;
            
            app.AirPanel.BackgroundColor = SurfaceColor;
            app.AirPanel.ForegroundColor = OnSurfaceColor;
            app.AirPanel.BorderType = BorderType;
            
            app.gmsEditFieldLabel.FontColor = OnPrimaryColor;
            app.gmsEditField.BackgroundColor = SurfaceColor;
            app.gmsEditField.FontColor = OnSurfaceColor;
            
            app.t_LCEditField_2Label.FontColor = OnPrimaryColor;
            app.t_LCEditField.BackgroundColor = SurfaceColor;
            app.t_LCEditField.FontColor = OnSurfaceColor;
            
            app.p_LPaEditField_2Label.FontColor = OnPrimaryColor;
            app.p_LPaEditField.BackgroundColor = SurfaceColor;
            app.p_LPaEditField.FontColor = OnSurfaceColor;
            
            app.R_LJkgKEditFieldLabel.FontColor = OnPrimaryColor;
            app.R_LJkgKEditField.BackgroundColor = SurfaceColor;
            app.R_LJkgKEditField.FontColor = OnSurfaceColor;
            
            app.ResetButton.FontColor = OnPrimaryColor;
            app.ResetButton.BackgroundColor = PrimaryVariantColor;
            
            app.SaveButton.FontColor = OnPrimaryColor;
            app.SaveButton.BackgroundColor = PrimaryVariantColor;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            % Store main app object
            app.CallingApp = mainapp;
            
            loadApp(app);
            
            changeColorScheme(app);
        end

        % Close request function: EnvironmentSetupUIFigure
        function EnvironmentSetupUIFigureCloseRequest(app, event)
            saveData(app);
            
            % Disable Environment Options button while dialog is open
            app.CallingApp.EnvironmentButton.Enable = 'on';
            
            delete(app)            
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            saveData(app);
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            loadApp(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.EnvironmentSetupUIFigure = uifigure('Visible', 'off');
            app.EnvironmentSetupUIFigure.Name = 'Environment Setup';
            app.EnvironmentSetupUIFigure.Icon = 'Baltic.png';
            app.EnvironmentSetupUIFigure.CloseRequestFcn = createCallbackFcn(app, @EnvironmentSetupUIFigureCloseRequest, true);
            app.AirPanel = uipanel(app.EnvironmentSetupUIFigure);
            app.AirPanel.Title = 'Air ';
            app.t_LCEditField_2Label = uilabel(app.AirPanel);
            app.t_LCEditField_2Label.Text = 't_L [°C]';
            app.t_LCEditField = uieditfield(app.AirPanel, 'numeric');
            app.p_LPaEditField_2Label = uilabel(app.AirPanel);
            app.p_LPaEditField_2Label.Text = 'p_L [Pa]';
            app.p_LPaEditField = uieditfield(app.AirPanel, 'numeric');
            app.p_LPaEditField.ValueDisplayFormat = '%.0f';
            app.R_LJkgKEditFieldLabel = uilabel(app.AirPanel);
            app.R_LJkgKEditFieldLabel.Text = 'R_L [J/(kg*K)]';
            app.R_LJkgKEditField = uieditfield(app.AirPanel, 'numeric');
            app.SaveButton = uibutton(app.EnvironmentSetupUIFigure, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.gmsEditFieldLabel = uilabel(app.EnvironmentSetupUIFigure);
            app.gmsEditFieldLabel.Text = 'g [m/s²]';
            app.gmsEditField = uieditfield(app.EnvironmentSetupUIFigure, 'numeric');
            app.ResetButton = uibutton(app.EnvironmentSetupUIFigure, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.Copyright2021BalticRacingbyEricDorniedenLabel = uilabel(app.EnvironmentSetupUIFigure);
            app.Copyright2021BalticRacingbyEricDorniedenLabel.Text = 'Copyright © 2021 Baltic Racing by Eric Dornieden';
            % Show the figure after all components are created
            app.EnvironmentSetupUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Environment(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.EnvironmentSetupUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.EnvironmentSetupUIFigure)
        end
    end
end