classdef Drivetrain < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        MotorDataPanel                  matlab.ui.container.Panel
        EngineLayoutDropDownLabel       matlab.ui.control.Label
        EngineLayoutDropDown            matlab.ui.control.DropDown
        PowerlimitkWEditFieldLabel      matlab.ui.control.Label
        PowerlimitkWEditField           matlab.ui.control.NumericEditField
        UIAxes                          matlab.ui.control.UIAxes
        UIAxes2                         matlab.ui.control.UIAxes
        n_max1minEditFieldLabel         matlab.ui.control.Label
        n_max1minEditField              matlab.ui.control.NumericEditField
        z_chaindriveEditFieldLabel      matlab.ui.control.Label
        z_chaindriveEditField           matlab.ui.control.NumericEditField
        z_sprocketEditFieldLabel        matlab.ui.control.Label
        z_sprocketEditField             matlab.ui.control.NumericEditField
        TorquemultiplierEditFieldLabel  matlab.ui.control.Label
        TorquemultiplierEditField       matlab.ui.control.NumericEditField
        GearRatioLabel                  matlab.ui.control.Label
        UIAxes3                         matlab.ui.control.UIAxes
        DrivetrainEfficencyEditFieldLabel  matlab.ui.control.Label
        DrivetrainEfficencyEditField    matlab.ui.control.NumericEditField
        Button                          matlab.ui.control.Button
        Button_2                        matlab.ui.control.Button
        SaveButton                      matlab.ui.control.Button
        ResetButton                     matlab.ui.control.Button
        UITable                         matlab.ui.control.Table
        InvertorEfficiencyEditFieldLabel  matlab.ui.control.Label
        InvertorEfficiencyEditField     matlab.ui.control.NumericEditField
    end

    
    properties (Access = private)
        CallingApp   % Main app object
    end
    
    methods (Access = public)
        % Function with updates the torque and power curves for the motor
        function updateMotorGraph(app)
            
            % updates the gear Ratio of secondary drivetrain
            % calculates i_ges
            i_ges = app.z_chaindriveEditField.Value/app.z_sprocketEditField.Value;
            app.GearRatioLabel.Text = "i_ges = " + i_ges;


            % reads the value of the torque multiplier
            torque_multiplier = app.TorquemultiplierEditField.Value;
            
            % reads the data from the UITable to data
            data = app.UITable.Data;
            
            % seperat data into two 1D arrays with an 0 added, because both
            % axis should start at zero (x-axis rpm, y-axis torque)
            x = [0; data(:,1)];
            y = [0; data(:,2)] * torque_multiplier;
            
            % Calculates the power of the motor for a given rpm and torque
            for n = 1 : length(x)
                p(n) = (y(n) * x(n)) / 9.5488 / 1000;
            end
            
            % Clears the UIAxes so it can be recreated
            cla(app.UIAxes,'reset');
            
            % Sets the axis
            app.UIAxes.XLabel.String='RPM [1/min]';
            yyaxis(app.UIAxes,'left');
            plot(app.UIAxes,x,y);
            app.UIAxes.YLabel.String='Torque [Nm]';
            % holds plot so new axis can be drawn
            hold(app.UIAxes);
            title(app.UIAxes,"Single Motor");
            yyaxis(app.UIAxes,'right');
            app.UIAxes.YLabel.String='Power [kW]';
            plot(app.UIAxes,x,p);
            
            % disables zooming for the plot
%             disableDefaultInteractivity(app.UIAxes);
            
            % Checks which engine layout is selected and adjusts the number
            % of Motors acordingly
            Motorsdropdown = app.EngineLayoutDropDown.Value;
            
            if (Motorsdropdown == "2 Motor RWD")
                numberMotors = 2;
            elseif (Motorsdropdown == "1 Motor RWD")
                numberMotors = 1;
            elseif (Motorsdropdown == "AWD")
                numberMotors = 4;
            end
            
            % Calculates the torque created by the given number of motors
            y_2 = y*numberMotors;
            
            % Calculates the power of the motors for a given rpm and torque
            for n = 1 : length(x)
                p_2(n) = (y_2(n) * x(n))/9.5488/1000;
            end
            
            % Clears the UIAxes so it can be recreated
            cla(app.UIAxes2,'reset');
            
            % Sets the axis
            app.UIAxes2.XLabel.String='RPM [1/min]';
            yyaxis(app.UIAxes2,'left');
            plot(app.UIAxes2,x,y_2);
            app.UIAxes2.YLabel.String='Torque [Nm]';
            % holds plot so new axis can be drawn
            hold(app.UIAxes2);
            yyaxis(app.UIAxes2,'right');
            
            % Sets the title of the plot to the current number of motors
            title(app.UIAxes2,Motorsdropdown);
            app.UIAxes2.YLabel.String='Power [kW]';
            plot(app.UIAxes2,x,p_2);
            
            % disables zooming for the plot
%             disableDefaultInteractivity(app.UIAxes2);
            
%            plot(app.UIAxes,[1 2 3 4],'-r');
%            hold(app.UIAxes);
%            plot(app.UIAxes,[10 9 4 7],'--b');

            % Clears the UIAxes so it can be recreated
            cla(app.UIAxes3,'reset');
                  
            % Calculates the torque and the rpm at the wheels after the
            % secondary drivetrain with given losses
            x_final = x/i_ges;
            y_final = y_2 * i_ges * app.DrivetrainEfficencyEditField.Value;
            
            % Calculates Power at the driven wheels with losses
            P_out = p_2 * app.DrivetrainEfficencyEditField.Value * app.InvertorEfficiencyEditField.Value;
                     
            % Sets the axis
            app.UIAxes3.XLabel.String='RPM [1/min]';
            yyaxis(app.UIAxes3,'left');
            plot(app.UIAxes3,x_final,y_final);
            app.UIAxes3.YLabel.String='Torque [Nm]';
            % holds plot so new axis can be drawn
            hold(app.UIAxes3);
            yyaxis(app.UIAxes3,'right');
            
            % Sets the title of the plot to the current number of motors
            title(app.UIAxes3,Motorsdropdown+" final");
            app.UIAxes3.YLabel.String='Power [kW]';
            plot(app.UIAxes3,x_final,P_out);
            
            % disables zooming for the plot
%             disableDefaultInteractivity(app.UIAxes3);

        end
        
        function updateGearRatio(app)
            % calculates i_ges
            i_ges = app.z_chaindriveEditField.Value/app.z_sprocketEditField.Value;
            app.GearRatioLabel.Text = "i_ges = " + i_ges;
        end
    end
    
    methods (Access = private)
        
        function saveData(app)
            app.CallingApp.p_max = app.PowerlimitkWEditField.Value;
            app.CallingApp.n_max = app.n_max1minEditField.Value;
            app.CallingApp.drivetrain_eff = app.DrivetrainEfficencyEditField.Value;
            app.CallingApp.z_chaindrive = app.z_chaindriveEditField.Value;
            app.CallingApp.z_sprocket = app.z_sprocketEditField.Value;
            app.CallingApp.trq_multiplier = app.TorquemultiplierEditField.Value;
            app.CallingApp.engine_param = app.UITable.Data;
            app.CallingApp.invertor_eff = app.InvertorEfficiencyEditField.Value;
            
            % Checks which engine layout is selected and adjusts the number
            % of Motors acordingly
            Motorsdropdown = app.EngineLayoutDropDown.Value;
            
             if (Motorsdropdown == "2 Motor RWD")
                app.CallingApp.num_motors = 2;
            elseif (Motorsdropdown == "1 Motor RWD")
                app.CallingApp.num_motors = 1;
            elseif (Motorsdropdown == "AWD")
                app.CallingApp.num_motors = 4;
            end
        end
        
        function loadData(app)
            app.PowerlimitkWEditField.Value = app.CallingApp.p_max;
            app.n_max1minEditField.Value = app.CallingApp.n_max;
            app.DrivetrainEfficencyEditField.Value = app.CallingApp.drivetrain_eff;
            app.z_chaindriveEditField.Value = app.CallingApp.z_chaindrive;
            app.z_sprocketEditField.Value = app.CallingApp.z_sprocket;
            app.TorquemultiplierEditField.Value = app.CallingApp.trq_multiplier;
            app.UITable.Data = app.CallingApp.engine_param;
            app.InvertorEfficiencyEditField.Value = app.CallingApp.invertor_eff;
            
            % sets EngineLayoutDropDown text
            if (app.CallingApp.num_motors == 1) 
                    app.EngineLayoutDropDown.Value = "1 Motor RWD";           
            elseif (app.CallingApp.num_motors == 2)
                app.EngineLayoutDropDown.Value = "2 Motor RWD";
            elseif (app.CallingApp.num_motors == 4)
                app.EngineLayoutDropDown.Value = "AWD";
            end
            
            updateMotorGraph(app);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            % Store main app object
            app.CallingApp = mainapp;
            
            loadData(app);
        end

        % Cell edit callback: UITable
        function UITableCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;                    
            
            % Updates the plot of the motor torque and power from the given
            % UIable Data
            updateMotorGraph(app);
        end

        % Value changed function: EngineLayoutDropDown
        function EngineLayoutDropDownValueChanged(app, event)
            updateMotorGraph(app);
        end

        % Value changed function: TorquemultiplierEditField
        function TorquemultiplierEditFieldValueChanged(app, event)
           updateMotorGraph(app);
        end

        % Value changed function: z_chaindriveEditField
        function z_chaindriveEditFieldValueChanged(app, event)
            updateMotorGraph(app);
        end

        % Value changed function: z_sprocketEditField
        function z_sprocketEditFieldValueChanged(app, event)
            updateMotorGraph(app);
        end

        % Value changed function: DrivetrainEfficencyEditField
        function DrivetrainEfficencyEditFieldValueChanged(app, event)
            updateMotorGraph(app);
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            % Adds an entry to the UITable
            data = app.UITable.Data;
            app.UITable.Data = [data; [0 0]];
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            % Deletes the last entry in the UITable
            data = app.UITable.Data;
            data((length(data)),:) = [];
            app.UITable.Data = data;
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            saveData(app);
            
            % Disable Drivetrain Options button while dialog is open
            app.CallingApp.DrivetrainButton.Enable = 'on';
            
            delete(app)            
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            saveData(app);
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            loadData(app);
        end

        % Value changed function: InvertorEfficiencyEditField
        function InvertorEfficiencyEditFieldValueChanged(app, event)
            updateMotorGraph(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.MotorDataPanel = uipanel(app.UIFigure);
            app.MotorDataPanel.Title = 'Motor Data';
            app.EngineLayoutDropDownLabel = uilabel(app.MotorDataPanel);
            app.EngineLayoutDropDownLabel.Text = 'Engine Layout';
            app.EngineLayoutDropDown = uidropdown(app.MotorDataPanel);
            app.EngineLayoutDropDown.Items = {'2 Motor RWD', '1 Motor RWD', 'AWD'};
            app.EngineLayoutDropDown.ValueChangedFcn = createCallbackFcn(app, @EngineLayoutDropDownValueChanged, true);
            app.EngineLayoutDropDown.Value = '2 Motor RWD';
            app.PowerlimitkWEditFieldLabel = uilabel(app.MotorDataPanel);
            app.PowerlimitkWEditFieldLabel.Text = 'Power limit [kW]';
            app.PowerlimitkWEditField = uieditfield(app.MotorDataPanel, 'numeric');
            app.PowerlimitkWEditField.Value = 80;
            app.UIAxes = uiaxes(app.MotorDataPanel);
            title(app.UIAxes, 'Single Motor')
            xlabel(app.UIAxes, 'RPM [1/min]')
            ylabel(app.UIAxes, 'Torque [Nm]')
            app.UIAxes2 = uiaxes(app.MotorDataPanel);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            app.n_max1minEditFieldLabel = uilabel(app.MotorDataPanel);
            app.n_max1minEditFieldLabel.Text = 'n_max [1/min]';
            app.n_max1minEditField = uieditfield(app.MotorDataPanel, 'numeric');
            app.n_max1minEditField.Value = 6000;
            app.z_chaindriveEditFieldLabel = uilabel(app.MotorDataPanel);
            app.z_chaindriveEditFieldLabel.Text = 'z_chaindrive [-]';
            app.z_chaindriveEditField = uieditfield(app.MotorDataPanel, 'numeric');
            app.z_chaindriveEditField.ValueChangedFcn = createCallbackFcn(app, @z_chaindriveEditFieldValueChanged, true);
            app.z_chaindriveEditField.Value = 66;
            app.z_sprocketEditFieldLabel = uilabel(app.MotorDataPanel);
            app.z_sprocketEditFieldLabel.Text = 'z_sprocket [-]';
            app.z_sprocketEditField = uieditfield(app.MotorDataPanel, 'numeric');
            app.z_sprocketEditField.ValueChangedFcn = createCallbackFcn(app, @z_sprocketEditFieldValueChanged, true);
            app.z_sprocketEditField.Value = 17;
            app.TorquemultiplierEditFieldLabel = uilabel(app.MotorDataPanel);
            app.TorquemultiplierEditFieldLabel.Text = 'Torque multiplier [-]';
            app.TorquemultiplierEditField = uieditfield(app.MotorDataPanel, 'numeric');
            app.TorquemultiplierEditField.ValueChangedFcn = createCallbackFcn(app, @TorquemultiplierEditFieldValueChanged, true);
            app.TorquemultiplierEditField.Value = 1;
            app.GearRatioLabel = uilabel(app.MotorDataPanel);
            app.GearRatioLabel.Text = 'Gear Ratio';
            app.UIAxes3 = uiaxes(app.MotorDataPanel);
            title(app.UIAxes3, 'Title')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            app.DrivetrainEfficencyEditFieldLabel = uilabel(app.MotorDataPanel);
            app.DrivetrainEfficencyEditFieldLabel.Text = 'Drivetrain Efficency [-]';
            app.DrivetrainEfficencyEditField = uieditfield(app.MotorDataPanel, 'numeric');
            app.DrivetrainEfficencyEditField.ValueChangedFcn = createCallbackFcn(app, @DrivetrainEfficencyEditFieldValueChanged, true);
            app.DrivetrainEfficencyEditField.Value = 0.95;
            app.Button = uibutton(app.MotorDataPanel, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '+';
            app.Button_2 = uibutton(app.MotorDataPanel, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = '-';
            app.SaveButton = uibutton(app.MotorDataPanel, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.ResetButton = uibutton(app.MotorDataPanel, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.UITable = uitable(app.MotorDataPanel);
            app.UITable.ColumnName = {'RPM [1/min]'; 'Torque [Nm]'};
            app.UITable.RowName = {};
            app.UITable.ColumnEditable = true;
            app.UITable.CellEditCallback = createCallbackFcn(app, @UITableCellEdit, true);
            app.InvertorEfficiencyEditFieldLabel = uilabel(app.MotorDataPanel);
            app.InvertorEfficiencyEditFieldLabel.Text = 'Invertor Efficiency [-]';
            app.InvertorEfficiencyEditField = uieditfield(app.MotorDataPanel, 'numeric');
            app.InvertorEfficiencyEditField.ValueChangedFcn = createCallbackFcn(app, @InvertorEfficiencyEditFieldValueChanged, true);
            app.InvertorEfficiencyEditField.Value = 0.95;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Drivetrain(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end