classdef Suspension < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SuspensionSetupUIFigure         matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        GeneralTab                      matlab.ui.container.Tab
        DrawPitchCenterCheckBox         matlab.ui.control.CheckBox
        DrawRearRollCenterCheckBox      matlab.ui.control.CheckBox
        SteeringPanel                   matlab.ui.container.Panel
        AckermannEditField              matlab.ui.control.NumericEditField
        AckermannEditFieldLabel         matlab.ui.control.Label
        PiniondiamatermmEditField       matlab.ui.control.NumericEditField
        PiniondiamatermmEditFieldLabel  matlab.ui.control.Label
        SteeringWheelangleEditField     matlab.ui.control.NumericEditField
        SteeringWheelangleEditFieldLabel  matlab.ui.control.Label
        SteeringLeverLengthmmEditField  matlab.ui.control.NumericEditField
        SteeringLeverLengthmmEditFieldLabel  matlab.ui.control.Label
        SteeringWheelRadiusmmEditField  matlab.ui.control.NumericEditField
        SteeringWheelRadiusmmEditFieldLabel  matlab.ui.control.Label
        SaveButton                      matlab.ui.control.Button
        ResetButton                     matlab.ui.control.Button
        BrakesPanel                     matlab.ui.container.Panel
        ABSPanel                        matlab.ui.container.Panel
        ABSSwitch                       matlab.ui.control.Switch
        BrakeBiasFrontEditField         matlab.ui.control.NumericEditField
        BrakeBiasFrontEditFieldLabel    matlab.ui.control.Label
        BrakeForceNEditField            matlab.ui.control.NumericEditField
        BrakeForceNEditFieldLabel       matlab.ui.control.Label
        InnereffectivebrakediscdiametermmEditField  matlab.ui.control.NumericEditField
        InnereffectivebrakediscdiametermmEditFieldLabel  matlab.ui.control.Label
        OutereffectivebrakediscdiametermmEditField  matlab.ui.control.NumericEditField
        OutereffectivebrakediscdiametermmEditFieldLabel  matlab.ui.control.Label
        GeneralPanel                    matlab.ui.container.Panel
        TIRFileDropDown                 matlab.ui.control.DropDown
        TIRFileDropDownLabel            matlab.ui.control.Label
        ToerearEditField                matlab.ui.control.NumericEditField
        ToerearEditFieldLabel           matlab.ui.control.Label
        ToefrontEditField               matlab.ui.control.NumericEditField
        ToefrontEditFieldLabel          matlab.ui.control.Label
        CamberrearEditField             matlab.ui.control.NumericEditField
        CamberrearEditFieldLabel        matlab.ui.control.Label
        TrackrearmmEditField            matlab.ui.control.NumericEditField
        TrackrearmmEditFieldLabel       matlab.ui.control.Label
        CamberfrontEditField            matlab.ui.control.NumericEditField
        CamberfrontEditFieldLabel       matlab.ui.control.Label
        TrackfrontmmEditField           matlab.ui.control.NumericEditField
        TrackfrontmmEditFieldLabel      matlab.ui.control.Label
        WheelbasemmEditField            matlab.ui.control.NumericEditField
        WheelbasemmEditFieldLabel       matlab.ui.control.Label
        TyreParametersPanel             matlab.ui.container.Panel
        RimdiametermmEditField          matlab.ui.control.NumericEditField
        RimdiametermmEditFieldLabel     matlab.ui.control.Label
        TireradiusmmEditField           matlab.ui.control.NumericEditField
        TireradiusmmEditFieldLabel      matlab.ui.control.Label
        TirewidthmmEditField            matlab.ui.control.NumericEditField
        TirewidthmmEditFieldLabel       matlab.ui.control.Label
        RBX3EditField                   matlab.ui.control.NumericEditField
        RBX3EditFieldLabel              matlab.ui.control.Label
        LYKAEditField                   matlab.ui.control.NumericEditField
        LYKAEditFieldLabel              matlab.ui.control.Label
        LXALEditField                   matlab.ui.control.NumericEditField
        LXALEditFieldLabel              matlab.ui.control.Label
        RollingResistanceEditField      matlab.ui.control.NumericEditField
        RollingResistanceEditFieldLabel  matlab.ui.control.Label
        LMUYSlider                      matlab.ui.control.Slider
        LMUYSliderLabel                 matlab.ui.control.Label
        LMUXSlider                      matlab.ui.control.Slider
        LMUXSliderLabel                 matlab.ui.control.Label
        p_tirePaEditField               matlab.ui.control.NumericEditField
        p_tirePaEditFieldLabel          matlab.ui.control.Label
        J_TirekgmEditField              matlab.ui.control.NumericEditField
        J_TirekgmEditFieldLabel         matlab.ui.control.Label
        Copyright2021BalticRacingbyEricDorniedenLabel  matlab.ui.control.Label
        Image2                          matlab.ui.control.Image
        DrawFrontRollCenterCheckBox     matlab.ui.control.CheckBox
        DrawRollAxisCheckBox            matlab.ui.control.CheckBox
        DrawFrontSuspensionCheckBox     matlab.ui.control.CheckBox
        DrawRearSuspensionCheckBox      matlab.ui.control.CheckBox
        DrawFrontTiresCheckBox          matlab.ui.control.CheckBox
        DrawRearTiresCheckBox           matlab.ui.control.CheckBox
        DrawCoGCheckBox                 matlab.ui.control.CheckBox
        DrawWheelaxisCheckBox           matlab.ui.control.CheckBox
        RearButton                      matlab.ui.control.Button
        SideButton                      matlab.ui.control.Button
        TopButton                       matlab.ui.control.Button
        FrontButton                     matlab.ui.control.Button
        IsometricButton                 matlab.ui.control.Button
        DrawFrontUprightCheckBox        matlab.ui.control.CheckBox
        DrawRearUprightCheckBox         matlab.ui.control.CheckBox
        DrawInstantCentersFrontCheckBox  matlab.ui.control.CheckBox
        DrawInstantCentersRearCheckBox  matlab.ui.control.CheckBox
        UIAxes                          matlab.ui.control.UIAxes
        KinematicPointsTab              matlab.ui.container.Tab
        ExportasxlsButton               matlab.ui.control.Button
        StaticValuesLabel               matlab.ui.control.Label
        RearSuspensionLabel             matlab.ui.control.Label
        FrontSuspensionLabel            matlab.ui.control.Label
        MechanicalTrailrearLabel        matlab.ui.control.Label
        MechanicalTrailfrontLabel       matlab.ui.control.Label
        CasterrearLabel                 matlab.ui.control.Label
        CasterfrontLabel                matlab.ui.control.Label
        KPIrearLabel                    matlab.ui.control.Label
        KPIfrontLabel                   matlab.ui.control.Label
        RollCenterheightrearLabel       matlab.ui.control.Label
        UITable_2                       matlab.ui.control.Table
        UITable                         matlab.ui.control.Table
        RollCenterheightfrontLabel      matlab.ui.control.Label
        RollCenterMomentRadiusLabel     matlab.ui.control.Label
        CarPlotTab                      matlab.ui.container.Tab
        UIAxes2                         matlab.ui.control.UIAxes
        ContextMenu                     matlab.ui.container.ContextMenu
        ViewpointsMenu                  matlab.ui.container.Menu
        IsometricMenu                   matlab.ui.container.Menu
        FrontMenu                       matlab.ui.container.Menu
        RearMenu                        matlab.ui.container.Menu
        SideMenu                        matlab.ui.container.Menu
        TopMenu                         matlab.ui.container.Menu
        VisibilityMenu                  matlab.ui.container.Menu
        FrontMenu_2                     matlab.ui.container.Menu
        LowerAArmMenu                   matlab.ui.container.Menu
        UpperAArmMenu                   matlab.ui.container.Menu
        TieRodMenu                      matlab.ui.container.Menu
        UprightMenu                     matlab.ui.container.Menu
        RockerMenu                      matlab.ui.container.Menu
        CoiloverMenu                    matlab.ui.container.Menu
        PushPullRodMenu                 matlab.ui.container.Menu
        ARBMenu                         matlab.ui.container.Menu
        TiresMenu                       matlab.ui.container.Menu
        RollCenterMenu                  matlab.ui.container.Menu
        InstantCentersMenu              matlab.ui.container.Menu
        WheelAxisMenu                   matlab.ui.container.Menu
        RearMenu_2                      matlab.ui.container.Menu
        LowerAArmMenu_2                 matlab.ui.container.Menu
        UpperAArmMenu_2                 matlab.ui.container.Menu
        TieRodMenu_2                    matlab.ui.container.Menu
        UprightMenu_2                   matlab.ui.container.Menu
        RockerMenu_2                    matlab.ui.container.Menu
        CoiloverMenu_2                  matlab.ui.container.Menu
        PushPullRodMenu_2               matlab.ui.container.Menu
        ARBMenu_2                       matlab.ui.container.Menu
        TiresMenu_2                     matlab.ui.container.Menu
        RollCenterMenu_2                matlab.ui.container.Menu
        InstantCentersMenu_2            matlab.ui.container.Menu
        WheelAxisMenu_2                 matlab.ui.container.Menu
        CoGMenu                         matlab.ui.container.Menu
        PitchCenterMenu                 matlab.ui.container.Menu
        RollAxisMenu                    matlab.ui.container.Menu
    end

    
    properties (Access = private)
        CallingApp   % Main app object
    end
    
    properties (Access = public)
        plotParameters

        CHAS_LowForFront
        CHAS_LowAftFront
        UPRI_LowPntFront
        CHAS_UppForFront
        CHAS_UppAftFront
        UPRI_UppPntFront
        CHAS_TiePntFront
        UPRI_TiePntFront
        NSMA_PPAttPnt_L_Front
        CHAS_AttPnt_L_Front
        CHAS_RocAxi_L_Front
        CHAS_RocPiv_L_Front
        ROCK_RodPnt_L_Front
        ROCK_CoiPnt_L_Front
        NSMA_UBarAttPnt_L_Front
        UBAR_AttPnt_L_Front
        CHAS_PivPnt_L_Front

        CHAS_LowForRear
        CHAS_LowAftRear
        UPRI_LowPntRear
        CHAS_UppForRear
        CHAS_UppAftRear
        UPRI_UppPntRear
        CHAS_TiePntRear
        UPRI_TiePntRear
        NSMA_PPAttPnt_L_Rear
        CHAS_AttPnt_L_Rear
        CHAS_RocAxi_L_Rear
        CHAS_RocPiv_L_Rear
        ROCK_RodPnt_L_Rear
        ROCK_CoiPnt_L_Rear
        NSMA_UBarAttPnt_L_Rear
        UBAR_AttPnt_L_Rear
        CHAS_PivPnt_L_Rear
    end
    
    methods (Access = private)
        
        function saveData(app)
            app.CallingApp.wheelbase = app.WheelbasemmEditField.Value;
            app.CallingApp.track_f = app.TrackfrontmmEditField.Value;
            app.CallingApp.track_r = app.TrackrearmmEditField.Value;
            app.CallingApp.J_Tire = app.J_TirekgmEditField.Value;
            app.CallingApp.p_Tire = app.p_tirePaEditField.Value;
            app.CallingApp.LMUX = app.LMUXSlider.Value;
            app.CallingApp.LMUY = app.LMUYSlider.Value;
            app.CallingApp.LXAL = app.LXALEditField.Value;
            app.CallingApp.LYKA = app.LYKAEditField.Value;
            app.CallingApp.RBX3 = app.RBX3EditField.Value;
            app.CallingApp.k_R = app.RollingResistanceEditField.Value;
            app.CallingApp.FB = app.BrakeForceNEditField.Value;
            app.CallingApp.camber = app.CamberfrontEditField.Value;
%             app.CallingApp.h_rc_f = app.RollCenterheightfrontmmEditField.Value;
%             app.CallingApp.h_rc_r = app.RollCenterheightrearmmEditField.Value;
            app.CallingApp.tirFile = app.TIRFileDropDown.Value;
            
            if app.ABSSwitch.Value == "On"
                app.CallingApp.brakeBias = -1;
            else
                app.CallingApp.brakeBias = app.BrakeBiasFrontEditField.Value;
            end
            
        end
        
        function loadApp(app)
            % Loads data from main app
            app.WheelbasemmEditField.Value = app.CallingApp.wheelbase;
            app.TrackfrontmmEditField.Value = app.CallingApp.track_f;
            app.TrackrearmmEditField.Value = app.CallingApp.track_r;
            app.J_TirekgmEditField.Value = app.CallingApp.J_Tire;
            app.p_tirePaEditField.Value = app.CallingApp.p_Tire;
            app.LMUXSlider.Value = app.CallingApp.LMUX;
            app.LMUYSlider.Value = app.CallingApp.LMUY;
            app.LXALEditField.Value = app.CallingApp.LXAL;
            app.LYKAEditField.Value = app.CallingApp.LYKA;
            app.RBX3EditField.Value = app.CallingApp.RBX3;
            app.RollingResistanceEditField.Value = app.CallingApp.k_R;
            app.BrakeForceNEditField.Value = app.CallingApp.FB;
            app.CamberfrontEditField.Value = app.CallingApp.camber;     
%             app.RollCenterheightfrontmmEditField.Value = app.CallingApp.h_rc_f;
%             app.RollCenterheightrearmmEditField.Value = app.CallingApp.h_rc_r;
            
            if (app.CallingApp.brakeBias == -1)
                app.ABSSwitch.Value = 'On';
                app.BrakeBiasFrontEditField.Enable = 'off';
                app.BrakeBiasFrontEditFieldLabel.Enable = 'off';
                app.BrakeBiasFrontEditField.Value = 50;
            else
                app.ABSSwitch.Value = 'Off';
                app.BrakeBiasFrontEditField.Enable = 'on';
                app.BrakeBiasFrontEditFieldLabel.Enable = 'on';
                app.BrakeBiasFrontEditField.Value = app.CallingApp.brakeBias;
            end 

            app.plotParameters.drawCoG = true;
            app.plotParameters.drawWheelCenterAxis = false;
            app.plotParameters.drawFrontUpright = true;
            app.plotParameters.drawRearUpright = true;
            app.plotParameters.drawFrontTires = true;
            app.plotParameters.drawRearTires = true;
            app.plotParameters.drawFrontWheelCenterAxis = false;
            app.plotParameters.drawRearWheelCenterAxis = false;
            app.plotParameters.drawFrontRollCenter = false;
            app.plotParameters.drawRearRollCenter = false;
            app.plotParameters.drawFrontLowerAarm = true;
            app.plotParameters.drawRearLowerAarm = true;
            app.plotParameters.drawFrontUpperAarm = true;
            app.plotParameters.drawRearUpperAarm = true;
            app.plotParameters.drawFrontTieRod = true;
            app.plotParameters.drawRearTieRod = true;
            app.plotParameters.drawFrontInstantCenters = false;
            app.plotParameters.drawRearInstantCenters = false;
            app.plotParameters.drawPitchCenter = false;
            app.plotParameters.drawRollAxis = false;
            app.plotParameters.drawFrontRocker = true;
            app.plotParameters.drawRearRocker = true;
            app.plotParameters.drawFrontPushPullRod = true;
            app.plotParameters.drawRearPushPullRod = true;
            app.plotParameters.drawFrontCoilover = true;
            app.plotParameters.drawRearCoilover = true;
            app.plotParameters.drawFrontARB = true;
            app.plotParameters.drawRearARB = false;
        end
        
        function calculateRollCenter(app)
%             % Calculate distance from roll center to cog
%             alpha = tan(abs(app.RollCenterheightrearmmEditField.Value-app.RollCenterheightfrontmmEditField.Value)/app.WheelbasemmEditField.Value);
%             h_rc_cog = alpha*(app.CallingApp.x_cog - app.CallingApp.x_va) + min(app.RollCenterheightrearmmEditField.Value, app.RollCenterheightfrontmmEditField.Value);
%             h_rc = h_rc_cog;
%             h_rc_mr = app.CallingApp.h_cog-h_rc_cog;
%             
%             app.RollCenterheightfrontLabel.Text = "Roll Center height " + num2str(h_rc) + " [mm]";
%             app.RollCenterMomentRadiusLabel.Text = "Roll Center Moment Radius " + num2str(h_rc_mr) + " [mm]";
        end      
        
        function loadKinematic(app)
            % Set the data for the UITable.
            data = app.UITable.Data;
            app.UITable.Data = [data; ["CHAS_LowForFront" 398.914 -156.064 67.743]; ["CHAS_LowAftFront" 599.771 -153.063 76.107]; ["UPRI_LowPntFront" 479.410 -588.899 73.000]; ["CHAS_UppForFront" 395.756 -259.077 245.545];...
                ["CHAS_UppAftFront" 617.179 -287.046 245.545]; ["UPRI_UppPntFront" 504.310 -570.000 295.000]; ["CHAS_TiePntFront" 350.172 -226.166 72.400]; ["UPRI_TiePntFront" 435.850 -630.210 78.825];
                ["NSMA_PPAttPnt_L_Front" 498.693 529.849 268.66]; ["CHAS_AttPnt_L_Front" 501.464 295.136 354.975]; ["CHAS_RocAxi_L_Front" 523.859 287.03 121.496]; ["CHAS_RocPiv_L_Front" 503.905 300.776 127.464];
                ["ROCK_RodPnt_L_Front" 504.426 342.079 109.611]; ["ROCK_CoiPnt_L_Front" 503.872 361.164 176.833]; ["NSMA_UBarAttPnt_L_Front" 486.767 350.044 178.137]; ["UBAR_AttPnt_L_Front" 488.388 417.118 54.159];
                ["CHAS_PivPnt_L_Front" 630 340.312 75]];

            app.UITable_2.Data = [data; ["CHAS_LowForRear" 1876.610 66.501 118.500]; ["CHAS_LowAftRear" 2151.485 45.485 69.946]; ["UPRI_LowPntRear" 2012.500 588.422 73.000]; ["CHAS_UppForRear" 1881.000 159.103 199.991];...
                ["CHAS_UppAftRear" 2134.104 153.990 204.054]; ["UPRI_UppPntRear" 2012.500 568.050 283.000]; ["CHAS_TiePntRear" 2171.012 130.926 203.734]; ["UPRI_TiePntRear" 2085.775 567.088 283.240];
                ["NSMA_PPAttPnt_L_Rear" 2011.31 531.067 311.352]; ["CHAS_AttPnt_L_Rear" 1994.174 22.243 474.247]; ["CHAS_RocAxi_L_Rear" 2016.847 243.475 466.532]; ["CHAS_RocPiv_L_Rear" 2001.856 243.996 466.582];
                ["ROCK_RodPnt_L_Rear" 2003.843 295.22 528]; ["ROCK_CoiPnt_L_Rear" 2000.6 200.876 538.66]; ["NSMA_UBarAttPnt_L_Rear" 2049.35 176.943 131.706]; ["UBAR_AttPnt_L_Rear" 2179.156 195 124.603];
                ["CHAS_PivPnt_L_Rear" 2179.156 176.943 200]];     
            
            % Update the kinematic points with the data from the UITables.
            updateFrontSuspensionData(app);
            updateRearSuspensionData(app);
        end
        
        function updateFrontSuspensionData(app)
            % Front Suspension
            app.CHAS_LowForFront = [str2double(app.UITable.Data(1, 2)), str2double(app.UITable.Data(1, 3)), str2double(app.UITable.Data(1, 4))];
            app.CHAS_LowAftFront = [str2double(app.UITable.Data(2, 2)), str2double(app.UITable.Data(2, 3)), str2double(app.UITable.Data(2, 4))];
            app.UPRI_LowPntFront = [str2double(app.UITable.Data(3, 2)), str2double(app.UITable.Data(3, 3)), str2double(app.UITable.Data(3, 4))];

            app.CHAS_UppForFront = [str2double(app.UITable.Data(4, 2)), str2double(app.UITable.Data(4, 3)), str2double(app.UITable.Data(4, 4))];
            app.CHAS_UppAftFront = [str2double(app.UITable.Data(5, 2)), str2double(app.UITable.Data(5, 3)), str2double(app.UITable.Data(5, 4))];
            app.UPRI_UppPntFront = [str2double(app.UITable.Data(6, 2)), str2double(app.UITable.Data(6, 3)), str2double(app.UITable.Data(6, 4))];

            app.CHAS_TiePntFront = [str2double(app.UITable.Data(7, 2)), str2double(app.UITable.Data(7, 3)), str2double(app.UITable.Data(7, 4))];
            app.UPRI_TiePntFront = [str2double(app.UITable.Data(8, 2)), str2double(app.UITable.Data(8, 3)), str2double(app.UITable.Data(8, 4))];

            app.NSMA_PPAttPnt_L_Front = [str2double(app.UITable.Data(9, 2)), str2double(app.UITable.Data(9, 3)), str2double(app.UITable.Data(9, 4))];
            app.CHAS_AttPnt_L_Front = [str2double(app.UITable.Data(10, 2)), str2double(app.UITable.Data(10, 3)), str2double(app.UITable.Data(10, 4))];
            app.CHAS_RocAxi_L_Front = [str2double(app.UITable.Data(11, 2)), str2double(app.UITable.Data(11, 3)), str2double(app.UITable.Data(11, 4))];
            app.CHAS_RocPiv_L_Front = [str2double(app.UITable.Data(12, 2)), str2double(app.UITable.Data(12, 3)), str2double(app.UITable.Data(12, 4))];
            app.ROCK_RodPnt_L_Front = [str2double(app.UITable.Data(13, 2)), str2double(app.UITable.Data(13, 3)), str2double(app.UITable.Data(13, 4))];
            app.ROCK_CoiPnt_L_Front = [str2double(app.UITable.Data(14, 2)), str2double(app.UITable.Data(14, 3)), str2double(app.UITable.Data(14, 4))];

            app.NSMA_UBarAttPnt_L_Front = [str2double(app.UITable.Data(15, 2)), str2double(app.UITable.Data(15, 3)), str2double(app.UITable.Data(15, 4))];
            app.UBAR_AttPnt_L_Front = [str2double(app.UITable.Data(16, 2)), str2double(app.UITable.Data(16, 3)), str2double(app.UITable.Data(16, 4))];
            app.CHAS_PivPnt_L_Front = [str2double(app.UITable.Data(17, 2)), str2double(app.UITable.Data(17, 3)), str2double(app.UITable.Data(17, 4))];
        end

        function updateRearSuspensionData(app)
            % Rear Suspension
            app.CHAS_LowForRear = [str2double(app.UITable_2.Data(1, 2)), str2double(app.UITable_2.Data(1, 3)), str2double(app.UITable_2.Data(1, 4))];
            app.CHAS_LowAftRear = [str2double(app.UITable_2.Data(2, 2)), str2double(app.UITable_2.Data(2, 3)), str2double(app.UITable_2.Data(2, 4))];
            app.UPRI_LowPntRear = [str2double(app.UITable_2.Data(3, 2)), str2double(app.UITable_2.Data(3, 3)), str2double(app.UITable_2.Data(3, 4))];

            app.CHAS_UppForRear = [str2double(app.UITable_2.Data(4, 2)), str2double(app.UITable_2.Data(4, 3)), str2double(app.UITable_2.Data(4, 4))];
            app.CHAS_UppAftRear = [str2double(app.UITable_2.Data(5, 2)), str2double(app.UITable_2.Data(5, 3)), str2double(app.UITable_2.Data(5, 4))];
            app.UPRI_UppPntRear = [str2double(app.UITable_2.Data(6, 2)), str2double(app.UITable_2.Data(6, 3)), str2double(app.UITable_2.Data(6, 4))];

            app.CHAS_TiePntRear = [str2double(app.UITable_2.Data(7, 2)), str2double(app.UITable_2.Data(7, 3)), str2double(app.UITable_2.Data(7, 4))];
            app.UPRI_TiePntRear = [str2double(app.UITable_2.Data(8, 2)), str2double(app.UITable_2.Data(8, 3)), str2double(app.UITable_2.Data(8, 4))];
            
            app.NSMA_PPAttPnt_L_Rear = [str2double(app.UITable_2.Data(9, 2)), str2double(app.UITable_2.Data(9, 3)), str2double(app.UITable_2.Data(9, 4))];
            app.CHAS_AttPnt_L_Rear = [str2double(app.UITable_2.Data(10, 2)), str2double(app.UITable_2.Data(10, 3)), str2double(app.UITable_2.Data(10, 4))];
            app.CHAS_RocAxi_L_Rear = [str2double(app.UITable_2.Data(11, 2)), str2double(app.UITable_2.Data(11, 3)), str2double(app.UITable_2.Data(11, 4))];
            app.CHAS_RocPiv_L_Rear = [str2double(app.UITable_2.Data(12, 2)), str2double(app.UITable_2.Data(12, 3)), str2double(app.UITable_2.Data(12, 4))];
            app.ROCK_RodPnt_L_Rear = [str2double(app.UITable_2.Data(13, 2)), str2double(app.UITable_2.Data(13, 3)), str2double(app.UITable_2.Data(13, 4))];
            app.ROCK_CoiPnt_L_Rear = [str2double(app.UITable_2.Data(14, 2)), str2double(app.UITable_2.Data(14, 3)), str2double(app.UITable_2.Data(14, 4))];

            app.NSMA_UBarAttPnt_L_Rear = [str2double(app.UITable_2.Data(15, 2)), str2double(app.UITable_2.Data(15, 3)), str2double(app.UITable_2.Data(15, 4))];
            app.UBAR_AttPnt_L_Rear = [str2double(app.UITable_2.Data(16, 2)), str2double(app.UITable_2.Data(16, 3)), str2double(app.UITable_2.Data(16, 4))];
            app.CHAS_PivPnt_L_Rear = [str2double(app.UITable_2.Data(17, 2)), str2double(app.UITable_2.Data(17, 3)), str2double(app.UITable_2.Data(17, 4))];
        end

    end
    
    methods (Access = public)
        
        function changeColorScheme(app)       
%                 PrimaryColor = app.CallingApp.PrimaryColor;
            OnPrimaryColor = app.CallingApp.OnPrimaryColor;   
            PrimaryVariantColor = app.CallingApp.PrimaryVariantColor;
%                 OnPrimaryVariantColor = app.CallingApp.OnPrimaryVariantColor;
%                 SecondaryColor = app.CallingApp.SecondaryColor;
%                 OnSecondaryColor = app.CallingApp.OnSecondaryColor;
            BackgroundColor = app.CallingApp.BackgroundColor;
            OnBackgroundColor = app.CallingApp.OnBackgroundColor;
            SurfaceColor = app.CallingApp.SurfaceColor;
            OnSurfaceColor = app.CallingApp.OnSurfaceColor;
%                 ErrorColor = app.CallingApp.ErrorColor;
%                 OnErrorColor = app.CallingApp.OnErrorColor;
            BorderType = app.CallingApp.BorderType;                 
%                 Host_Logo = app.CallingApp.Host_Logo;
            
            app.SuspensionSetupUIFigure.Color = BackgroundColor;
            app.GeneralPanel.BackgroundColor = SurfaceColor;
            app.GeneralPanel.ForegroundColor = OnSurfaceColor;
            app.GeneralPanel.BorderType = BorderType;

            app.GeneralTab.BackgroundColor = BackgroundColor;
            app.KinematicPointsTab.BackgroundColor = BackgroundColor;
            
            app.Copyright2021BalticRacingbyEricDorniedenLabel.FontColor = OnBackgroundColor;
            
            app.WheelbasemmEditFieldLabel.FontColor = OnPrimaryColor;
            app.WheelbasemmEditField.BackgroundColor = SurfaceColor;
            app.WheelbasemmEditField.FontColor = OnSurfaceColor;
            
            app.TrackfrontmmEditFieldLabel.FontColor = OnPrimaryColor;
            app.TrackfrontmmEditField.BackgroundColor = SurfaceColor;
            app.TrackfrontmmEditField.FontColor = OnSurfaceColor;

            app.TrackrearmmEditFieldLabel.FontColor = OnPrimaryColor;
            app.TrackrearmmEditField.BackgroundColor = SurfaceColor;
            app.TrackrearmmEditField.FontColor = OnSurfaceColor;
            
            app.CamberfrontEditFieldLabel.FontColor = OnPrimaryColor;
            app.CamberfrontEditField.BackgroundColor = SurfaceColor;
            app.CamberfrontEditField.FontColor = OnSurfaceColor;

            app.CamberrearEditFieldLabel.FontColor = OnPrimaryColor;
            app.CamberrearEditField.BackgroundColor = SurfaceColor;
            app.CamberrearEditField.FontColor = OnSurfaceColor;
            
            app.TyreParametersPanel.BackgroundColor = SurfaceColor;
            app.TyreParametersPanel.ForegroundColor = OnSurfaceColor;
            app.TyreParametersPanel.BorderType = BorderType;
            
            app.J_TirekgmEditFieldLabel.FontColor = OnPrimaryColor;
            app.J_TirekgmEditField.BackgroundColor = SurfaceColor;
            app.J_TirekgmEditField.FontColor = OnSurfaceColor;
            
            app.p_tirePaEditFieldLabel.FontColor = OnPrimaryColor;
            app.p_tirePaEditField.BackgroundColor = SurfaceColor;
            app.p_tirePaEditField.FontColor = OnSurfaceColor;
            
            app.LMUXSliderLabel.FontColor = OnPrimaryColor;
            app.LMUXSlider.FontColor = OnPrimaryColor;
            
            app.LMUYSliderLabel.FontColor = OnPrimaryColor;
            app.LMUYSlider.FontColor = OnPrimaryColor;
            
            app.LXALEditFieldLabel.FontColor = OnPrimaryColor;
            app.LXALEditField.BackgroundColor = SurfaceColor;
            app.LXALEditField.FontColor = OnSurfaceColor;
            
            app.LYKAEditFieldLabel.FontColor = OnPrimaryColor;
            app.LYKAEditField.BackgroundColor = SurfaceColor;
            app.LYKAEditField.FontColor = OnSurfaceColor;
            
            app.RBX3EditFieldLabel.FontColor = OnPrimaryColor;
            app.RBX3EditField.BackgroundColor = SurfaceColor;
            app.RBX3EditField.FontColor = OnSurfaceColor;
            
            app.RollingResistanceEditFieldLabel.FontColor = OnPrimaryColor;
            app.RollingResistanceEditField.BackgroundColor = SurfaceColor;
            app.RollingResistanceEditField.FontColor = OnSurfaceColor;
            
            app.BrakeForceNEditFieldLabel.FontColor = OnPrimaryColor;
            app.BrakeForceNEditField.BackgroundColor = SurfaceColor;
            app.BrakeForceNEditField.FontColor = OnSurfaceColor;
            
            app.BrakesPanel.BackgroundColor = SurfaceColor;
            app.BrakesPanel.ForegroundColor = OnSurfaceColor;
            app.BrakesPanel.BorderType = BorderType;               
            
            app.OutereffectivebrakediscdiametermmEditFieldLabel.FontColor = OnPrimaryColor;
            app.OutereffectivebrakediscdiametermmEditField.BackgroundColor = SurfaceColor;
            app.OutereffectivebrakediscdiametermmEditField.FontColor = OnSurfaceColor;
            
            app.InnereffectivebrakediscdiametermmEditFieldLabel.FontColor = OnPrimaryColor;
            app.InnereffectivebrakediscdiametermmEditField.BackgroundColor = SurfaceColor;
            app.InnereffectivebrakediscdiametermmEditField.FontColor = OnSurfaceColor;
            
            app.BrakeBiasFrontEditFieldLabel.FontColor = OnPrimaryColor;
            app.BrakeBiasFrontEditField.BackgroundColor = SurfaceColor;
            app.BrakeBiasFrontEditField.FontColor = OnSurfaceColor;
            
            app.ABSSwitch.FontColor = OnPrimaryColor;
            
            app.ABSPanel.BackgroundColor = SurfaceColor;
            app.ABSPanel.ForegroundColor = OnSurfaceColor;
            app.ABSPanel.BorderType = BorderType;
            
            app.ResetButton.FontColor = OnPrimaryColor;
            app.ResetButton.BackgroundColor = PrimaryVariantColor;
            
            app.SaveButton.FontColor = OnPrimaryColor;
            app.SaveButton.BackgroundColor = PrimaryVariantColor;

            app.SteeringPanel.BackgroundColor = SurfaceColor;
            app.SteeringPanel.ForegroundColor = OnSurfaceColor;
            app.SteeringPanel.BorderType = BorderType;

            app.SteeringWheelangleEditFieldLabel.FontColor = OnPrimaryColor;
            app.SteeringWheelangleEditField.BackgroundColor = SurfaceColor;
            app.SteeringWheelangleEditField.FontColor = OnSurfaceColor;

            app.SteeringWheelRadiusmmEditFieldLabel.FontColor = OnPrimaryColor;
            app.SteeringWheelRadiusmmEditField.BackgroundColor = SurfaceColor;
            app.SteeringWheelRadiusmmEditField.FontColor = OnSurfaceColor;

            app.SteeringLeverLengthmmEditFieldLabel.FontColor = OnPrimaryColor;
            app.SteeringLeverLengthmmEditField.BackgroundColor = SurfaceColor;
            app.SteeringLeverLengthmmEditField.FontColor = OnSurfaceColor;

            app.PiniondiamatermmEditFieldLabel.FontColor = OnPrimaryColor;
            app.PiniondiamatermmEditField.BackgroundColor = SurfaceColor;
            app.PiniondiamatermmEditField.FontColor = OnSurfaceColor;

            app.AckermannEditFieldLabel.FontColor = OnPrimaryColor;
            app.AckermannEditField.BackgroundColor = SurfaceColor;
            app.AckermannEditField.FontColor = OnSurfaceColor;

            app.TireradiusmmEditFieldLabel.FontColor = OnPrimaryColor;
            app.TireradiusmmEditField.BackgroundColor = SurfaceColor;
            app.TireradiusmmEditField.FontColor = OnSurfaceColor;

            app.RimdiametermmEditFieldLabel.FontColor = OnPrimaryColor;
            app.RimdiametermmEditField.BackgroundColor = SurfaceColor;
            app.RimdiametermmEditField.FontColor = OnSurfaceColor;

            app.TirewidthmmEditFieldLabel.FontColor = OnPrimaryColor;
            app.TirewidthmmEditField.BackgroundColor = SurfaceColor;
            app.TirewidthmmEditField.FontColor = OnSurfaceColor;

            app.ToefrontEditFieldLabel.FontColor = OnPrimaryColor;
            app.ToefrontEditField.BackgroundColor = SurfaceColor;
            app.ToefrontEditField.FontColor = OnSurfaceColor;

            app.ToerearEditFieldLabel.FontColor = OnPrimaryColor;
            app.ToerearEditField.BackgroundColor = SurfaceColor;
            app.ToerearEditField.FontColor = OnSurfaceColor;

            app.RollCenterheightfrontLabel.FontColor = OnPrimaryColor;
            app.RollCenterheightrearLabel.FontColor = OnPrimaryColor;
            app.RollCenterMomentRadiusLabel.FontColor = OnPrimaryColor;
            app.KPIfrontLabel.FontColor = OnPrimaryColor;
            app.KPIrearLabel.FontColor = OnPrimaryColor;
            app.CasterfrontLabel.FontColor = OnPrimaryColor;
            app.CasterrearLabel.FontColor = OnPrimaryColor;
            app.MechanicalTrailfrontLabel.FontColor = OnPrimaryColor;
            app.MechanicalTrailrearLabel.FontColor = OnPrimaryColor;
            app.FrontSuspensionLabel.FontColor = OnPrimaryColor;
            app.RearSuspensionLabel.FontColor = OnPrimaryColor;
            app.StaticValuesLabel.FontColor = OnPrimaryColor;
    
            % Checkboxes for Car plot
            app.DrawCoGCheckBox.FontColor = OnPrimaryColor;
            app.DrawFrontSuspensionCheckBox.FontColor = OnPrimaryColor;
            app.DrawFrontTiresCheckBox.FontColor = OnPrimaryColor;
            app.DrawRearSuspensionCheckBox.FontColor = OnPrimaryColor;
            app.DrawRearTiresCheckBox.FontColor = OnPrimaryColor;
            app.DrawRollAxisCheckBox.FontColor = OnPrimaryColor;
            app.DrawFrontRollCenterCheckBox.FontColor = OnPrimaryColor;
            app.DrawWheelaxisCheckBox.FontColor = OnPrimaryColor;
            app.DrawFrontUprightCheckBox.FontColor = OnPrimaryColor;
            app.DrawRearUprightCheckBox.FontColor = OnPrimaryColor;
            app.DrawInstantCentersFrontCheckBox.FontColor = OnPrimaryColor;
            app.DrawInstantCentersRearCheckBox.FontColor = OnPrimaryColor;
            app.DrawRearRollCenterCheckBox.FontColor = OnPrimaryColor;
            app.DrawPitchCenterCheckBox.FontColor = OnPrimaryColor;

            %% Car Reprensation plot
            app.UIAxes.Title.Color = OnPrimaryColor;
            app.UIAxes.XColor = OnPrimaryColor;
            app.UIAxes.YColor = OnPrimaryColor;
            app.UIAxes.Color = SurfaceColor;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            % Store main app object
            app.CallingApp = mainapp;
            
            loadApp(app);
            
            changeColorScheme(app);
            calculateRollCenter(app);

            loadKinematic(app);

            plotCar(app, app.CallingApp);    

            view(app.UIAxes2, -37.5, 30);
            app.plotParameters.viewPoint = [-37.5 30];

            app.UIAxes.PositionConstraint = 'innerposition';  
            app.UIAxes2.PositionConstraint = 'innerposition';

            updateTirFiles(app.TIRFileDropDown);
        end

        % Close request function: SuspensionSetupUIFigure
        function SuspensionSetupUIFigureCloseRequest(app, event)
            saveData(app);
            
            % Disable Suspension Options button while dialog is open
            app.CallingApp.SuspensionButton.Enable = 'on';
            
            delete(app)            
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            saveData(app);
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            loadApp(app);
        end

        % Value changed function: ABSSwitch
        function ABSSwitchValueChanged(app, event)

            if app.ABSSwitch.Value == "Off"
                app.BrakeBiasFrontEditField.Enable = 'on';
                app.BrakeBiasFrontEditFieldLabel.Enable = 'on';
            else
                app.BrakeBiasFrontEditField.Enable = 'off';
                app.BrakeBiasFrontEditFieldLabel.Enable = 'off';
            end
        end

        % Callback function
        function RollCenterheightfrontmmEditFieldValueChanged(app, event)
            calculateRollCenter(app);  
            plotCar(app, app.CallingApp);
        end

        % Callback function
        function RollCenterheightrearmmEditFieldValueChanged(app, event)
            calculateRollCenter(app);
            plotCar(app, app.CallingApp);
        end

        % Value changed function: TrackfrontmmEditField
        function TrackfrontmmEditFieldValueChanged(app, event)
            plotCar(app, app.CallingApp);
        end

        % Value changed function: TrackrearmmEditField
        function TrackrearmmEditFieldValueChanged(app, event)
            plotCar(app, app.CallingApp);
        end

        % Value changed function: WheelbasemmEditField
        function WheelbasemmEditFieldValueChanged(app, event)
            plotCar(app, app.CallingApp);
        end

        % Value changed function: DrawFrontRollCenterCheckBox
        function DrawFrontRollCenterCheckBoxValueChanged(app, event)
            plotCar(app, app.CallingApp);
        end

        % Value changed function: DrawFrontSuspensionCheckBox
        function DrawFrontSuspensionCheckBoxValueChanged(app, event)
            plotCar(app, app.CallingApp);
        end

        % Value changed function: DrawFrontTiresCheckBox
        function DrawFrontTiresCheckBoxValueChanged(app, event)
            plotCar(app, app.CallingApp);
        end

        % Value changed function: DrawCoGCheckBox
        function DrawCoGCheckBoxValueChanged(app, event)
            plotCar(app, app.CallingApp);
        end

        % Value changed function: DrawRollAxisCheckBox
        function DrawRollAxisCheckBoxValueChanged(app, event)
            plotCar(app, app.CallingApp);
        end

        % Value changed function: DrawRearSuspensionCheckBox
        function DrawRearSuspensionCheckBoxValueChanged(app, event)
            plotCar(app, app.CallingApp);
        end

        % Value changed function: DrawRearTiresCheckBox
        function DrawRearTiresCheckBoxValueChanged(app, event)
            plotCar(app, app.CallingApp);
        end

        % Value changed function: DrawWheelaxisCheckBox
        function DrawWheelaxisCheckBoxValueChanged(app, event)
            plotCar(app, app.CallingApp);
        end

        % Button pushed function: RearButton
        function RearButtonPushed(app, event)
            view(app.UIAxes, 90, 0);
        end

        % Button pushed function: SideButton
        function SideButtonPushed(app, event)
            view(app.UIAxes, 0, 0);
        end

        % Button pushed function: TopButton
        function TopButtonPushed(app, event)
            view(app.UIAxes, 0, 90);
        end

        % Button pushed function: FrontButton
        function FrontButtonPushed(app, event)
            view(app.UIAxes, 270, 0);
        end

        % Button pushed function: IsometricButton
        function IsometricButtonPushed(app, event)
            view(app.UIAxes, -37.5, 30);
        end

        % Value changed function: DrawFrontUprightCheckBox
        function DrawFrontUprightCheckBoxValueChanged(app, event)
            plotCar(app, app.CallingApp);
        end

        % Value changed function: DrawRearUprightCheckBox
        function DrawRearUprightCheckBoxValueChanged(app, event)
            plotCar(app, app.CallingApp);
        end

        % Value changed function: DrawInstantCentersFrontCheckBox
        function DrawInstantCentersFrontCheckBoxValueChanged(app, event)
            plotCar(app, app.CallingApp);
        end

        % Value changed function: DrawInstantCentersRearCheckBox
        function DrawInstantCentersRearCheckBoxValueChanged(app, event)
            plotCar(app, app.CallingApp);
        end

        % Value changed function: DrawRearRollCenterCheckBox
        function DrawRearRollCenterCheckBoxValueChanged(app, event)
            plotCar(app, app.CallingApp);
        end

        % Selection change function: TabGroup
        function TabGroupSelectionChanged(app, event)
            selectedTab = app.TabGroup.SelectedTab;
            if selectedTab == app.CarPlotTab
                plotCar(app, app.CallingApp, app.UIAxes2, app.plotParameters)
            end
        end

        % Cell edit callback: UITable
        function UITableCellEdit(app, event)
            updateFrontSuspensionData(app);            
        end

        % Cell edit callback: UITable_2
        function UITable_2CellEdit(app, event)
            updateRearSuspensionData(app);         
        end

        % Value changed function: TireradiusmmEditField
        function TireradiusmmEditFieldValueChanged(app, event)
            plotCar(app, app.CallingApp);
        end

        % Value changed function: TirewidthmmEditField
        function TirewidthmmEditFieldValueChanged(app, event)
            plotCar(app, app.CallingApp);
        end

        % Value changed function: DrawPitchCenterCheckBox
        function DrawPitchCenterCheckBoxValueChanged(app, event)
            plotCar(app, app.CallingApp);
        end

        % Menu selected function: IsometricMenu
        function IsometricMenuSelected(app, event)
            view(app.UIAxes2, -37.5, 30);
        end

        % Menu selected function: FrontMenu
        function FrontMenuSelected(app, event)
            view(app.UIAxes2, 270, 0);
        end

        % Menu selected function: RearMenu
        function RearMenuSelected(app, event)
            view(app.UIAxes2, 90, 0);
        end

        % Menu selected function: SideMenu
        function SideMenuSelected(app, event)
            view(app.UIAxes2, 0, 0);
        end

        % Menu selected function: TopMenu
        function TopMenuSelected(app, event)
            view(app.UIAxes2, 0, 90);       
        end

        % Menu selected function: UprightMenu
        function UprightMenuSelected(app, event)
            % Get current state of the checkbox and invert it
            app.plotParameters.drawFrontUpright = ~app.UprightMenu.Checked;
            app.UprightMenu.Checked = ~app.UprightMenu.Checked;

            % save current viewpoint
            app.plotParameters.viewPoint = app.UIAxes2.View;

            % Update plot with updated data
            plotCar(app, app.CallingApp, app.UIAxes2, app.plotParameters)
        end

        % Menu selected function: CoGMenu
        function CoGMenuSelected(app, event)
            % Get current state of the checkbox and invert it
            app.plotParameters.drawCoG = ~app.CoGMenu.Checked;
            app.CoGMenu.Checked = ~app.CoGMenu.Checked;

            % save current viewpoint
            app.plotParameters.viewPoint = app.UIAxes2.View;

            % Update plot with updated data
            plotCar(app, app.CallingApp, app.UIAxes2, app.plotParameters)
        end

        % Menu selected function: TiresMenu
        function TiresMenuSelected(app, event)
            % Get current state of the checkbox and invert it
            app.plotParameters.drawFrontTires = ~app.TiresMenu.Checked;
            app.TiresMenu.Checked = ~app.TiresMenu.Checked;

            % save current viewpoint
            app.plotParameters.viewPoint = app.UIAxes2.View;

            % Update plot with updated data
            plotCar(app, app.CallingApp, app.UIAxes2, app.plotParameters)
        end

        % Menu selected function: WheelAxisMenu
        function WheelAxisMenuSelected(app, event)
            % Get current state of the checkbox and invert it
            app.plotParameters.drawFrontWheelCenterAxis = ~app.WheelAxisMenu.Checked;
            app.WheelAxisMenu.Checked = ~app.WheelAxisMenu.Checked;

            % save current viewpoint
            app.plotParameters.viewPoint = app.UIAxes2.View;

            % Update plot with updated data
            plotCar(app, app.CallingApp, app.UIAxes2, app.plotParameters)
        end

        % Menu selected function: RollCenterMenu
        function RollCenterMenuSelected(app, event)
            % Get current state of the checkbox and invert it
            app.plotParameters.drawFrontRollCenter = ~app.RollCenterMenu.Checked;
            app.RollCenterMenu.Checked = ~app.RollCenterMenu.Checked;

            % save current viewpoint
            app.plotParameters.viewPoint = app.UIAxes2.View;

            % Update plot with updated data
            plotCar(app, app.CallingApp, app.UIAxes2, app.plotParameters)
        end

        % Menu selected function: LowerAArmMenu
        function LowerAArmMenuSelected(app, event)
            % Get current state of the checkbox and invert it
            app.plotParameters.drawFrontLowerAarm = ~app.LowerAArmMenu.Checked;
            app.LowerAArmMenu.Checked = ~app.LowerAArmMenu.Checked;

            % save current viewpoint
            app.plotParameters.viewPoint = app.UIAxes2.View;

            % Update plot with updated data
            plotCar(app, app.CallingApp, app.UIAxes2, app.plotParameters)
        end

        % Menu selected function: UpperAArmMenu
        function UpperAArmMenuSelected(app, event)
            % Get current state of the checkbox and invert it
            app.plotParameters.drawFrontUpperAarm = ~app.UpperAArmMenu.Checked;
            app.UpperAArmMenu.Checked = ~app.UpperAArmMenu.Checked;

            % save current viewpoint
            app.plotParameters.viewPoint = app.UIAxes2.View;

            % Update plot with updated data
            plotCar(app, app.CallingApp, app.UIAxes2, app.plotParameters)
        end

        % Menu selected function: TieRodMenu
        function TieRodMenuSelected(app, event)
            % Get current state of the checkbox and invert it
            app.plotParameters.drawFrontTieRod = ~app.TieRodMenu.Checked;
            app.TieRodMenu.Checked = ~app.TieRodMenu.Checked;

            % save current viewpoint
            app.plotParameters.viewPoint = app.UIAxes2.View;

            % Update plot with updated data
            plotCar(app, app.CallingApp, app.UIAxes2, app.plotParameters)
        end

        % Menu selected function: InstantCentersMenu
        function InstantCentersMenuSelected(app, event)
            % Get current state of the checkbox and invert it
            app.plotParameters.drawFrontInstantCenters = ~app.InstantCentersMenu.Checked;
            app.InstantCentersMenu.Checked = ~app.InstantCentersMenu.Checked;

            % save current viewpoint
            app.plotParameters.viewPoint = app.UIAxes2.View;

            % Update plot with updated data
            plotCar(app, app.CallingApp, app.UIAxes2, app.plotParameters)
        end

        % Menu selected function: PitchCenterMenu
        function PitchCenterMenuSelected(app, event)
            % Get current state of the checkbox and invert it
            app.plotParameters.drawPitchCenter = ~app.PitchCenterMenu.Checked;
            app.PitchCenterMenu.Checked = ~app.PitchCenterMenu.Checked;

            % save current viewpoint
            app.plotParameters.viewPoint = app.UIAxes2.View;

            % Update plot with updated data
            plotCar(app, app.CallingApp, app.UIAxes2, app.plotParameters)
        end

        % Menu selected function: LowerAArmMenu_2
        function LowerAArmMenu_2Selected(app, event)
            % Get current state of the checkbox and invert it
            app.plotParameters.drawRearLowerAarm = ~app.LowerAArmMenu_2.Checked;
            app.LowerAArmMenu_2.Checked = ~app.LowerAArmMenu_2.Checked;

            % save current viewpoint
            app.plotParameters.viewPoint = app.UIAxes2.View;

            % Update plot with updated data
            plotCar(app, app.CallingApp, app.UIAxes2, app.plotParameters)
        end

        % Menu selected function: UpperAArmMenu_2
        function UpperAArmMenu_2Selected(app, event)
            % Get current state of the checkbox and invert it
            app.plotParameters.drawRearUpperAarm = ~app.UpperAArmMenu_2.Checked;
            app.UpperAArmMenu_2.Checked = ~app.UpperAArmMenu_2.Checked;

            % save current viewpoint
            app.plotParameters.viewPoint = app.UIAxes2.View;

            % Update plot with updated data
            plotCar(app, app.CallingApp, app.UIAxes2, app.plotParameters)
        end

        % Menu selected function: TieRodMenu_2
        function TieRodMenu_2Selected(app, event)
            % Get current state of the checkbox and invert it
            app.plotParameters.drawRearTieRod = ~app.TieRodMenu_2.Checked;
            app.TieRodMenu_2.Checked = ~app.TieRodMenu_2.Checked;

            % save current viewpoint
            app.plotParameters.viewPoint = app.UIAxes2.View;

            % Update plot with updated data
            plotCar(app, app.CallingApp, app.UIAxes2, app.plotParameters)
        end

        % Menu selected function: UprightMenu_2
        function UprightMenu_2Selected(app, event)
            % Get current state of the checkbox and invert it
            app.plotParameters.drawRearUpright = ~app.UprightMenu_2.Checked;
            app.UprightMenu_2.Checked = ~app.UprightMenu_2.Checked;

            % save current viewpoint
            app.plotParameters.viewPoint = app.UIAxes2.View;

            % Update plot with updated data
            plotCar(app, app.CallingApp, app.UIAxes2, app.plotParameters)
        end

        % Menu selected function: TiresMenu_2
        function TiresMenu_2Selected(app, event)
            % Get current state of the checkbox and invert it
            app.plotParameters.drawRearTires = ~app.TiresMenu_2.Checked;
            app.TiresMenu_2.Checked = ~app.TiresMenu_2.Checked;

            % save current viewpoint
            app.plotParameters.viewPoint = app.UIAxes2.View;

            % Update plot with updated data
            plotCar(app, app.CallingApp, app.UIAxes2, app.plotParameters)
        end

        % Menu selected function: RollCenterMenu_2
        function RollCenterMenu_2Selected(app, event)
            % Get current state of the checkbox and invert it
            app.plotParameters.drawRearRollCenter = ~app.RollCenterMenu_2.Checked;
            app.RollCenterMenu_2.Checked = ~app.RollCenterMenu_2.Checked;

            % save current viewpoint
            app.plotParameters.viewPoint = app.UIAxes2.View;

            % Update plot with updated data
            plotCar(app, app.CallingApp, app.UIAxes2, app.plotParameters)
        end

        % Menu selected function: InstantCentersMenu_2
        function InstantCentersMenu_2Selected(app, event)
            % Get current state of the checkbox and invert it
            app.plotParameters.drawRearInstantCenters = ~app.InstantCentersMenu_2.Checked;
            app.InstantCentersMenu_2.Checked = ~app.InstantCentersMenu_2.Checked;

            % save current viewpoint
            app.plotParameters.viewPoint = app.UIAxes2.View;

            % Update plot with updated data
            plotCar(app, app.CallingApp, app.UIAxes2, app.plotParameters)
        end

        % Menu selected function: WheelAxisMenu_2
        function WheelAxisMenu_2Selected(app, event)
            % Get current state of the checkbox and invert it
            app.plotParameters.drawRearWheelCenterAxis = ~app.WheelAxisMenu_2.Checked;
            app.WheelAxisMenu_2.Checked = ~app.WheelAxisMenu_2.Checked;

            % save current viewpoint
            app.plotParameters.viewPoint = app.UIAxes2.View;

            % Update plot with updated data
            plotCar(app, app.CallingApp, app.UIAxes2, app.plotParameters)
        end

        % Menu selected function: RollAxisMenu
        function RollAxisMenuSelected(app, event)
            % Get current state of the checkbox and invert it
            app.plotParameters.drawRollAxis = ~app.RollAxisMenu.Checked;
            app.RollAxisMenu.Checked = ~app.RollAxisMenu.Checked;

            % save current viewpoint
            app.plotParameters.viewPoint = app.UIAxes2.View;

            % Update plot with updated data
            plotCar(app, app.CallingApp, app.UIAxes2, app.plotParameters)
        end

        % Menu selected function: RockerMenu
        function RockerMenuSelected(app, event)
            % Get current state of the checkbox and invert it
            app.plotParameters.drawFrontRocker = ~app.RockerMenu.Checked;
            app.RockerMenu.Checked = ~app.RockerMenu.Checked;

            % save current viewpoint
            app.plotParameters.viewPoint = app.UIAxes2.View;

            % Update plot with updated data
            plotCar(app, app.CallingApp, app.UIAxes2, app.plotParameters)
        end

        % Menu selected function: PushPullRodMenu
        function PushPullRodMenuSelected(app, event)
            % Get current state of the checkbox and invert it
            app.plotParameters.drawFrontPushPullRod = ~app.PushPullRodMenu.Checked;
            app.PushPullRodMenu.Checked = ~app.PushPullRodMenu.Checked;

            % save current viewpoint
            app.plotParameters.viewPoint = app.UIAxes2.View;

            % Update plot with updated data
            plotCar(app, app.CallingApp, app.UIAxes2, app.plotParameters)
        end

        % Menu selected function: PushPullRodMenu_2
        function PushPullRodMenu_2Selected(app, event)
            % Get current state of the checkbox and invert it
            app.plotParameters.drawRearPushPullRod = ~app.PushPullRodMenu_2.Checked;
            app.PushPullRodMenu_2.Checked = ~app.PushPullRodMenu_2.Checked;

            % save current viewpoint
            app.plotParameters.viewPoint = app.UIAxes2.View;

            % Update plot with updated data
            plotCar(app, app.CallingApp, app.UIAxes2, app.plotParameters)
        end

        % Menu selected function: CoiloverMenu
        function CoiloverMenuSelected(app, event)
            % Get current state of the checkbox and invert it
            app.plotParameters.drawFrontCoilover = ~app.CoiloverMenu.Checked;
            app.CoiloverMenu.Checked = ~app.CoiloverMenu.Checked;

            % save current viewpoint
            app.plotParameters.viewPoint = app.UIAxes2.View;

            % Update plot with updated data
            plotCar(app, app.CallingApp, app.UIAxes2, app.plotParameters)
        end

        % Menu selected function: RockerMenu_2
        function RockerMenu_2Selected(app, event)
            % Get current state of the checkbox and invert it
            app.plotParameters.drawRearRocker = ~app.RockerMenu_2.Checked;
            app.RockerMenu_2.Checked = ~app.RockerMenu_2.Checked;

            % save current viewpoint
            app.plotParameters.viewPoint = app.UIAxes2.View;

            % Update plot with updated data
            plotCar(app, app.CallingApp, app.UIAxes2, app.plotParameters)
        end

        % Menu selected function: CoiloverMenu_2
        function CoiloverMenu_2Selected(app, event)
            % Get current state of the checkbox and invert it
            app.plotParameters.drawRearCoilover = ~app.CoiloverMenu_2.Checked;
            app.CoiloverMenu_2.Checked = ~app.CoiloverMenu_2.Checked;

            % save current viewpoint
            app.plotParameters.viewPoint = app.UIAxes2.View;

            % Update plot with updated data
            plotCar(app, app.CallingApp, app.UIAxes2, app.plotParameters)
        end

        % Menu selected function: ARBMenu
        function ARBMenuSelected(app, event)
            % Get current state of the checkbox and invert it
            app.plotParameters.drawFrontARB = ~app.ARBMenu.Checked;
            app.ARBMenu.Checked = ~app.ARBMenu.Checked;

            % save current viewpoint
            app.plotParameters.viewPoint = app.UIAxes2.View;

            % Update plot with updated data
            plotCar(app, app.CallingApp, app.UIAxes2, app.plotParameters)
        end

        % Menu selected function: ARBMenu_2
        function ARBMenu_2Selected(app, event)
            % Get current state of the checkbox and invert it
            app.plotParameters.drawRearARB = ~app.ARBMenu_2.Checked;
            app.ARBMenu_2.Checked = ~app.ARBMenu_2.Checked;

            % save current viewpoint
            app.plotParameters.viewPoint = app.UIAxes2.View;

            % Update plot with updated data
            plotCar(app, app.CallingApp, app.UIAxes2, app.plotParameters)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SuspensionSetupUIFigure = uifigure('Visible', 'off');
            app.SuspensionSetupUIFigure.Name = 'Suspension Setup';
            app.SuspensionSetupUIFigure.Icon = 'Baltic.png';
            app.SuspensionSetupUIFigure.CloseRequestFcn = createCallbackFcn(app, @SuspensionSetupUIFigureCloseRequest, true);
            app.TabGroup = uitabgroup(app.SuspensionSetupUIFigure);
            app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @TabGroupSelectionChanged, true);
            app.GeneralTab = uitab(app.TabGroup);
            app.GeneralTab.Title = 'General';
            app.UIAxes = uiaxes(app.GeneralTab);
            app.UIAxes.XTick = [];
            app.UIAxes.XTickLabel = '';
            app.UIAxes.YTick = [];
            app.UIAxes.YTickLabel = '';
            app.UIAxes.ZTick = [];
            app.DrawInstantCentersRearCheckBox = uicheckbox(app.GeneralTab);
            app.DrawInstantCentersRearCheckBox.ValueChangedFcn = createCallbackFcn(app, @DrawInstantCentersRearCheckBoxValueChanged, true);
            app.DrawInstantCentersRearCheckBox.Text = 'Draw Instant Centers Rear';
            app.DrawInstantCentersFrontCheckBox = uicheckbox(app.GeneralTab);
            app.DrawInstantCentersFrontCheckBox.ValueChangedFcn = createCallbackFcn(app, @DrawInstantCentersFrontCheckBoxValueChanged, true);
            app.DrawInstantCentersFrontCheckBox.Text = 'Draw Instant Centers Front';
            app.DrawRearUprightCheckBox = uicheckbox(app.GeneralTab);
            app.DrawRearUprightCheckBox.ValueChangedFcn = createCallbackFcn(app, @DrawRearUprightCheckBoxValueChanged, true);
            app.DrawRearUprightCheckBox.Text = 'Draw Rear Upright';
            app.DrawRearUprightCheckBox.Value = true;
            app.DrawFrontUprightCheckBox = uicheckbox(app.GeneralTab);
            app.DrawFrontUprightCheckBox.ValueChangedFcn = createCallbackFcn(app, @DrawFrontUprightCheckBoxValueChanged, true);
            app.DrawFrontUprightCheckBox.Text = 'Draw Front Upright';
            app.DrawFrontUprightCheckBox.Value = true;
            app.IsometricButton = uibutton(app.GeneralTab, 'push');
            app.IsometricButton.ButtonPushedFcn = createCallbackFcn(app, @IsometricButtonPushed, true);
            app.IsometricButton.Text = 'Isometric';
            app.FrontButton = uibutton(app.GeneralTab, 'push');
            app.FrontButton.ButtonPushedFcn = createCallbackFcn(app, @FrontButtonPushed, true);
            app.FrontButton.Text = 'Front';
            app.TopButton = uibutton(app.GeneralTab, 'push');
            app.TopButton.ButtonPushedFcn = createCallbackFcn(app, @TopButtonPushed, true);
            app.TopButton.Text = 'Top';
            app.SideButton = uibutton(app.GeneralTab, 'push');
            app.SideButton.ButtonPushedFcn = createCallbackFcn(app, @SideButtonPushed, true);
            app.SideButton.Text = 'Side';
            app.RearButton = uibutton(app.GeneralTab, 'push');
            app.RearButton.ButtonPushedFcn = createCallbackFcn(app, @RearButtonPushed, true);
            app.RearButton.Text = 'Rear';
            app.DrawWheelaxisCheckBox = uicheckbox(app.GeneralTab);
            app.DrawWheelaxisCheckBox.ValueChangedFcn = createCallbackFcn(app, @DrawWheelaxisCheckBoxValueChanged, true);
            app.DrawWheelaxisCheckBox.Text = 'Draw Wheel axis';
            app.DrawCoGCheckBox = uicheckbox(app.GeneralTab);
            app.DrawCoGCheckBox.ValueChangedFcn = createCallbackFcn(app, @DrawCoGCheckBoxValueChanged, true);
            app.DrawCoGCheckBox.Text = 'Draw CoG';
            app.DrawCoGCheckBox.Value = true;
            app.DrawRearTiresCheckBox = uicheckbox(app.GeneralTab);
            app.DrawRearTiresCheckBox.ValueChangedFcn = createCallbackFcn(app, @DrawRearTiresCheckBoxValueChanged, true);
            app.DrawRearTiresCheckBox.Text = 'Draw Rear Tires';
            app.DrawRearTiresCheckBox.Value = true;
            app.DrawFrontTiresCheckBox = uicheckbox(app.GeneralTab);
            app.DrawFrontTiresCheckBox.ValueChangedFcn = createCallbackFcn(app, @DrawFrontTiresCheckBoxValueChanged, true);
            app.DrawFrontTiresCheckBox.Text = 'Draw Front Tires';
            app.DrawFrontTiresCheckBox.Value = true;
            app.DrawRearSuspensionCheckBox = uicheckbox(app.GeneralTab);
            app.DrawRearSuspensionCheckBox.ValueChangedFcn = createCallbackFcn(app, @DrawRearSuspensionCheckBoxValueChanged, true);
            app.DrawRearSuspensionCheckBox.Text = 'Draw Rear Suspension';
            app.DrawRearSuspensionCheckBox.Value = true;
            app.DrawFrontSuspensionCheckBox = uicheckbox(app.GeneralTab);
            app.DrawFrontSuspensionCheckBox.ValueChangedFcn = createCallbackFcn(app, @DrawFrontSuspensionCheckBoxValueChanged, true);
            app.DrawFrontSuspensionCheckBox.Text = 'Draw Front Suspension';
            app.DrawFrontSuspensionCheckBox.Value = true;
            app.DrawRollAxisCheckBox = uicheckbox(app.GeneralTab);
            app.DrawRollAxisCheckBox.ValueChangedFcn = createCallbackFcn(app, @DrawRollAxisCheckBoxValueChanged, true);
            app.DrawRollAxisCheckBox.Text = 'Draw Roll Axis';
            app.DrawFrontRollCenterCheckBox = uicheckbox(app.GeneralTab);
            app.DrawFrontRollCenterCheckBox.ValueChangedFcn = createCallbackFcn(app, @DrawFrontRollCenterCheckBoxValueChanged, true);
            app.DrawFrontRollCenterCheckBox.Text = 'Draw Front Roll Center';
            app.Image2 = uiimage(app.GeneralTab);
            app.Image2.ImageSource = 'balticracing_logo_transparent.png';
            app.Copyright2021BalticRacingbyEricDorniedenLabel = uilabel(app.GeneralTab);
            app.Copyright2021BalticRacingbyEricDorniedenLabel.Text = 'Copyright © 2021 Baltic Racing by Eric Dornieden';
            app.TyreParametersPanel = uipanel(app.GeneralTab);
            app.TyreParametersPanel.Title = 'Tyre Parameters';
            app.J_TirekgmEditFieldLabel = uilabel(app.TyreParametersPanel);
            app.J_TirekgmEditFieldLabel.Text = 'J_Tire [kg*m²]';
            app.J_TirekgmEditField = uieditfield(app.TyreParametersPanel, 'numeric');
            app.J_TirekgmEditField.LowerLimitInclusive = 'off';
            app.J_TirekgmEditField.UpperLimitInclusive = 'off';
            app.J_TirekgmEditField.Limits = [0 Inf];
            app.J_TirekgmEditField.Value = 1;
            app.p_tirePaEditFieldLabel = uilabel(app.TyreParametersPanel);
            app.p_tirePaEditFieldLabel.Text = 'p_tire [Pa]';
            app.p_tirePaEditField = uieditfield(app.TyreParametersPanel, 'numeric');
            app.p_tirePaEditField.LowerLimitInclusive = 'off';
            app.p_tirePaEditField.UpperLimitInclusive = 'off';
            app.p_tirePaEditField.Limits = [0 Inf];
            app.p_tirePaEditField.ValueDisplayFormat = '%.0f';
            app.p_tirePaEditField.Value = 1;
            app.LMUXSliderLabel = uilabel(app.TyreParametersPanel);
            app.LMUXSliderLabel.Text = 'LMUX';
            app.LMUXSlider = uislider(app.TyreParametersPanel);
            app.LMUXSlider.Limits = [0.6 0.75];
            app.LMUXSlider.MajorTicks = [0.6 0.65 0.7 0.75];
            app.LMUXSlider.Value = 0.65;
            app.LMUYSliderLabel = uilabel(app.TyreParametersPanel);
            app.LMUYSliderLabel.Text = 'LMUY';
            app.LMUYSlider = uislider(app.TyreParametersPanel);
            app.LMUYSlider.Limits = [0.6 0.75];
            app.LMUYSlider.MajorTicks = [0.6 0.65 0.7 0.75];
            app.LMUYSlider.Value = 0.65;
            app.RollingResistanceEditFieldLabel = uilabel(app.TyreParametersPanel);
            app.RollingResistanceEditFieldLabel.Text = 'Rolling Resistance [-]';
            app.RollingResistanceEditField = uieditfield(app.TyreParametersPanel, 'numeric');
            app.RollingResistanceEditField.Limits = [0 Inf];
            app.RollingResistanceEditField.ValueDisplayFormat = '%.3f';
            app.LXALEditFieldLabel = uilabel(app.TyreParametersPanel);
            app.LXALEditFieldLabel.Text = 'LXAL [-]';
            app.LXALEditField = uieditfield(app.TyreParametersPanel, 'numeric');
            app.LXALEditField.Limits = [0 Inf];
            app.LXALEditField.ValueDisplayFormat = '%.3f';
            app.LYKAEditFieldLabel = uilabel(app.TyreParametersPanel);
            app.LYKAEditFieldLabel.Text = 'LYKA [-]';
            app.LYKAEditField = uieditfield(app.TyreParametersPanel, 'numeric');
            app.LYKAEditField.Limits = [0 Inf];
            app.LYKAEditField.ValueDisplayFormat = '%.3f';
            app.RBX3EditFieldLabel = uilabel(app.TyreParametersPanel);
            app.RBX3EditFieldLabel.Text = 'RBX3 [-]';
            app.RBX3EditField = uieditfield(app.TyreParametersPanel, 'numeric');
            app.RBX3EditField.Limits = [0 Inf];
            app.RBX3EditField.ValueDisplayFormat = '%.3f';
            app.TirewidthmmEditFieldLabel = uilabel(app.TyreParametersPanel);
            app.TirewidthmmEditFieldLabel.Text = 'Tire width [mm]';
            app.TirewidthmmEditField = uieditfield(app.TyreParametersPanel, 'numeric');
            app.TirewidthmmEditField.LowerLimitInclusive = 'off';
            app.TirewidthmmEditField.UpperLimitInclusive = 'off';
            app.TirewidthmmEditField.Limits = [0 Inf];
            app.TirewidthmmEditField.ValueChangedFcn = createCallbackFcn(app, @TirewidthmmEditFieldValueChanged, true);
            app.TirewidthmmEditField.Value = 203.4;
            app.TireradiusmmEditFieldLabel = uilabel(app.TyreParametersPanel);
            app.TireradiusmmEditFieldLabel.Text = 'Tire radius [mm]';
            app.TireradiusmmEditField = uieditfield(app.TyreParametersPanel, 'numeric');
            app.TireradiusmmEditField.LowerLimitInclusive = 'off';
            app.TireradiusmmEditField.UpperLimitInclusive = 'off';
            app.TireradiusmmEditField.Limits = [0 Inf];
            app.TireradiusmmEditField.ValueChangedFcn = createCallbackFcn(app, @TireradiusmmEditFieldValueChanged, true);
            app.TireradiusmmEditField.Value = 226.8;
            app.RimdiametermmEditFieldLabel = uilabel(app.TyreParametersPanel);
            app.RimdiametermmEditFieldLabel.Text = 'Rim diameter [mm]';
            app.RimdiametermmEditField = uieditfield(app.TyreParametersPanel, 'numeric');
            app.RimdiametermmEditField.LowerLimitInclusive = 'off';
            app.RimdiametermmEditField.UpperLimitInclusive = 'off';
            app.RimdiametermmEditField.Limits = [0 Inf];
            app.RimdiametermmEditField.Value = 330.2;
            app.GeneralPanel = uipanel(app.GeneralTab);
            app.GeneralPanel.Title = 'General';
            app.WheelbasemmEditFieldLabel = uilabel(app.GeneralPanel);
            app.WheelbasemmEditFieldLabel.Text = 'Wheelbase [mm]';
            app.WheelbasemmEditField = uieditfield(app.GeneralPanel, 'numeric');
            app.WheelbasemmEditField.LowerLimitInclusive = 'off';
            app.WheelbasemmEditField.UpperLimitInclusive = 'off';
            app.WheelbasemmEditField.Limits = [0 Inf];
            app.WheelbasemmEditField.ValueChangedFcn = createCallbackFcn(app, @WheelbasemmEditFieldValueChanged, true);
            app.WheelbasemmEditField.Value = 1;
            app.TrackfrontmmEditFieldLabel = uilabel(app.GeneralPanel);
            app.TrackfrontmmEditFieldLabel.Text = 'Track front [mm]';
            app.TrackfrontmmEditField = uieditfield(app.GeneralPanel, 'numeric');
            app.TrackfrontmmEditField.LowerLimitInclusive = 'off';
            app.TrackfrontmmEditField.UpperLimitInclusive = 'off';
            app.TrackfrontmmEditField.Limits = [0 Inf];
            app.TrackfrontmmEditField.ValueChangedFcn = createCallbackFcn(app, @TrackfrontmmEditFieldValueChanged, true);
            app.TrackfrontmmEditField.Value = 1;
            app.CamberfrontEditFieldLabel = uilabel(app.GeneralPanel);
            app.CamberfrontEditFieldLabel.Text = 'Camber front [°]';
            app.CamberfrontEditField = uieditfield(app.GeneralPanel, 'numeric');
            app.CamberfrontEditField.LowerLimitInclusive = 'off';
            app.CamberfrontEditField.UpperLimitInclusive = 'off';
            app.TrackrearmmEditFieldLabel = uilabel(app.GeneralPanel);
            app.TrackrearmmEditFieldLabel.Text = 'Track rear [mm]';
            app.TrackrearmmEditField = uieditfield(app.GeneralPanel, 'numeric');
            app.TrackrearmmEditField.LowerLimitInclusive = 'off';
            app.TrackrearmmEditField.UpperLimitInclusive = 'off';
            app.TrackrearmmEditField.Limits = [0 Inf];
            app.TrackrearmmEditField.ValueChangedFcn = createCallbackFcn(app, @TrackrearmmEditFieldValueChanged, true);
            app.TrackrearmmEditField.Value = 1;
            app.CamberrearEditFieldLabel = uilabel(app.GeneralPanel);
            app.CamberrearEditFieldLabel.Text = 'Camber rear [°]';
            app.CamberrearEditField = uieditfield(app.GeneralPanel, 'numeric');
            app.CamberrearEditField.LowerLimitInclusive = 'off';
            app.CamberrearEditField.UpperLimitInclusive = 'off';
            app.ToefrontEditFieldLabel = uilabel(app.GeneralPanel);
            app.ToefrontEditFieldLabel.Text = 'Toe front [°]';
            app.ToefrontEditField = uieditfield(app.GeneralPanel, 'numeric');
            app.ToefrontEditField.LowerLimitInclusive = 'off';
            app.ToefrontEditField.UpperLimitInclusive = 'off';
            app.ToerearEditFieldLabel = uilabel(app.GeneralPanel);
            app.ToerearEditFieldLabel.Text = 'Toe rear [°]';
            app.ToerearEditField = uieditfield(app.GeneralPanel, 'numeric');
            app.ToerearEditField.LowerLimitInclusive = 'off';
            app.ToerearEditField.UpperLimitInclusive = 'off';
            app.TIRFileDropDownLabel = uilabel(app.GeneralPanel);
            app.TIRFileDropDownLabel.Text = 'TIR File';
            app.TIRFileDropDown = uidropdown(app.GeneralPanel);
            app.BrakesPanel = uipanel(app.GeneralTab);
            app.BrakesPanel.Title = 'Brakes';
            app.OutereffectivebrakediscdiametermmEditFieldLabel = uilabel(app.BrakesPanel);
            app.OutereffectivebrakediscdiametermmEditFieldLabel.Text = 'Outer effective brake disc diameter [mm]';
            app.OutereffectivebrakediscdiametermmEditField = uieditfield(app.BrakesPanel, 'numeric');
            app.OutereffectivebrakediscdiametermmEditField.LowerLimitInclusive = 'off';
            app.OutereffectivebrakediscdiametermmEditField.UpperLimitInclusive = 'off';
            app.OutereffectivebrakediscdiametermmEditField.Limits = [0 Inf];
            app.OutereffectivebrakediscdiametermmEditField.ValueDisplayFormat = '%.0f';
            app.OutereffectivebrakediscdiametermmEditField.Value = 1;
            app.InnereffectivebrakediscdiametermmEditFieldLabel = uilabel(app.BrakesPanel);
            app.InnereffectivebrakediscdiametermmEditFieldLabel.Text = 'Inner effective brake disc diameter [mm]';
            app.InnereffectivebrakediscdiametermmEditField = uieditfield(app.BrakesPanel, 'numeric');
            app.InnereffectivebrakediscdiametermmEditField.LowerLimitInclusive = 'off';
            app.InnereffectivebrakediscdiametermmEditField.UpperLimitInclusive = 'off';
            app.InnereffectivebrakediscdiametermmEditField.Limits = [0 Inf];
            app.InnereffectivebrakediscdiametermmEditField.ValueDisplayFormat = '%.0f';
            app.InnereffectivebrakediscdiametermmEditField.Value = 1;
            app.BrakeForceNEditFieldLabel = uilabel(app.BrakesPanel);
            app.BrakeForceNEditFieldLabel.Text = 'Brake Force [N]';
            app.BrakeForceNEditField = uieditfield(app.BrakesPanel, 'numeric');
            app.BrakeForceNEditField.UpperLimitInclusive = 'off';
            app.BrakeForceNEditField.Limits = [0 Inf];
            app.BrakeForceNEditField.ValueDisplayFormat = '%.0f';
            app.ABSPanel = uipanel(app.BrakesPanel);
            app.ABSPanel.Title = 'ABS';
            app.BrakeBiasFrontEditFieldLabel = uilabel(app.ABSPanel);
            app.BrakeBiasFrontEditFieldLabel.Text = 'Brake Bias Front [%]';
            app.BrakeBiasFrontEditField = uieditfield(app.ABSPanel, 'numeric');
            app.BrakeBiasFrontEditField.Limits = [0 100];
            app.BrakeBiasFrontEditField.ValueDisplayFormat = '%.0f';
            app.BrakeBiasFrontEditField.Value = 1;
            app.ABSSwitch = uiswitch(app.ABSPanel, 'slider');
            app.ABSSwitch.ValueChangedFcn = createCallbackFcn(app, @ABSSwitchValueChanged, true);
            app.ResetButton = uibutton(app.GeneralTab, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.SaveButton = uibutton(app.GeneralTab, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.SteeringPanel = uipanel(app.GeneralTab);
            app.SteeringPanel.Title = 'Steering';
            app.SteeringWheelRadiusmmEditFieldLabel = uilabel(app.SteeringPanel);
            app.SteeringWheelRadiusmmEditFieldLabel.Text = 'Steering Wheel Radius [mm]';
            app.SteeringWheelRadiusmmEditField = uieditfield(app.SteeringPanel, 'numeric');
            app.SteeringWheelRadiusmmEditField.Value = 110;
            app.SteeringLeverLengthmmEditFieldLabel = uilabel(app.SteeringPanel);
            app.SteeringLeverLengthmmEditFieldLabel.Text = 'Steering Lever Length [mm]';
            app.SteeringLeverLengthmmEditField = uieditfield(app.SteeringPanel, 'numeric');
            app.SteeringLeverLengthmmEditField.Value = 60.95;
            app.SteeringWheelangleEditFieldLabel = uilabel(app.SteeringPanel);
            app.SteeringWheelangleEditFieldLabel.Text = 'Steering Wheel angle [°]';
            app.SteeringWheelangleEditField = uieditfield(app.SteeringPanel, 'numeric');
            app.SteeringWheelangleEditField.LowerLimitInclusive = 'off';
            app.SteeringWheelangleEditField.UpperLimitInclusive = 'off';
            app.SteeringWheelangleEditField.Limits = [0 Inf];
            app.SteeringWheelangleEditField.Tooltip = {'Complete steering wheel angle from full left to full right lock.'};
            app.SteeringWheelangleEditField.Value = 220;
            app.PiniondiamatermmEditFieldLabel = uilabel(app.SteeringPanel);
            app.PiniondiamatermmEditFieldLabel.Text = 'Pinion diamater [mm]';
            app.PiniondiamatermmEditField = uieditfield(app.SteeringPanel, 'numeric');
            app.PiniondiamatermmEditField.Value = 22.5;
            app.AckermannEditFieldLabel = uilabel(app.SteeringPanel);
            app.AckermannEditFieldLabel.Text = 'Ackermann [%]';
            app.AckermannEditField = uieditfield(app.SteeringPanel, 'numeric');
            app.DrawRearRollCenterCheckBox = uicheckbox(app.GeneralTab);
            app.DrawRearRollCenterCheckBox.ValueChangedFcn = createCallbackFcn(app, @DrawRearRollCenterCheckBoxValueChanged, true);
            app.DrawRearRollCenterCheckBox.Text = 'Draw Rear Roll Center';
            app.DrawPitchCenterCheckBox = uicheckbox(app.GeneralTab);
            app.DrawPitchCenterCheckBox.ValueChangedFcn = createCallbackFcn(app, @DrawPitchCenterCheckBoxValueChanged, true);
            app.DrawPitchCenterCheckBox.Text = 'Draw Pitch Center';
            app.KinematicPointsTab = uitab(app.TabGroup);
            app.KinematicPointsTab.Title = 'Kinematic Points';
            app.RollCenterMomentRadiusLabel = uilabel(app.KinematicPointsTab);
            app.RollCenterMomentRadiusLabel.Text = 'Roll Center Moment Radius';
            app.RollCenterheightfrontLabel = uilabel(app.KinematicPointsTab);
            app.RollCenterheightfrontLabel.Text = 'Roll Center height front';
            app.UITable = uitable(app.KinematicPointsTab);
            app.UITable.ColumnName = {'Point Name'; 'X'; 'Y'; 'Z'};
            app.UITable.RowName = {};
            app.UITable.ColumnEditable = [false true true true];
            app.UITable.CellEditCallback = createCallbackFcn(app, @UITableCellEdit, true);
            app.UITable_2 = uitable(app.KinematicPointsTab);
            app.UITable_2.ColumnName = {'Point Name'; 'X'; 'Y'; 'Z'};
            app.UITable_2.RowName = {};
            app.UITable_2.ColumnEditable = [false true true true];
            app.UITable_2.CellEditCallback = createCallbackFcn(app, @UITable_2CellEdit, true);
            app.RollCenterheightrearLabel = uilabel(app.KinematicPointsTab);
            app.RollCenterheightrearLabel.Text = 'Roll Center height rear';
            app.KPIfrontLabel = uilabel(app.KinematicPointsTab);
            app.KPIfrontLabel.Text = 'KPI front';
            app.KPIrearLabel = uilabel(app.KinematicPointsTab);
            app.KPIrearLabel.Text = 'KPI rear';
            app.CasterfrontLabel = uilabel(app.KinematicPointsTab);
            app.CasterfrontLabel.Text = 'Caster front';
            app.CasterrearLabel = uilabel(app.KinematicPointsTab);
            app.CasterrearLabel.Text = 'Caster rear';
            app.MechanicalTrailfrontLabel = uilabel(app.KinematicPointsTab);
            app.MechanicalTrailfrontLabel.Text = 'Mechanical Trail front';
            app.MechanicalTrailrearLabel = uilabel(app.KinematicPointsTab);
            app.MechanicalTrailrearLabel.Text = 'Mechanical Trail rear';
            app.FrontSuspensionLabel = uilabel(app.KinematicPointsTab);
            app.FrontSuspensionLabel.Text = 'Front Suspension';
            app.RearSuspensionLabel = uilabel(app.KinematicPointsTab);
            app.RearSuspensionLabel.Text = 'Rear Suspension';
            app.StaticValuesLabel = uilabel(app.KinematicPointsTab);
            app.StaticValuesLabel.Text = 'Static Values';
            app.ExportasxlsButton = uibutton(app.KinematicPointsTab, 'push');
            app.ExportasxlsButton.Text = 'Export as .xls';
            app.CarPlotTab = uitab(app.TabGroup);
            app.CarPlotTab.Title = 'Car Plot';
            app.UIAxes2 = uiaxes(app.CarPlotTab);
            app.ContextMenu = uicontextmenu(app.SuspensionSetupUIFigure);
            app.ViewpointsMenu = uimenu(app.ContextMenu);
            app.ViewpointsMenu.Text = 'Viewpoints';
            app.IsometricMenu = uimenu(app.ViewpointsMenu);
            app.IsometricMenu.MenuSelectedFcn = createCallbackFcn(app, @IsometricMenuSelected, true);
            app.IsometricMenu.Accelerator = 'I';
            app.IsometricMenu.Text = 'Isometric';
            app.FrontMenu = uimenu(app.ViewpointsMenu);
            app.FrontMenu.MenuSelectedFcn = createCallbackFcn(app, @FrontMenuSelected, true);
            app.FrontMenu.Accelerator = 'F';
            app.FrontMenu.Text = 'Front';
            app.RearMenu = uimenu(app.ViewpointsMenu);
            app.RearMenu.MenuSelectedFcn = createCallbackFcn(app, @RearMenuSelected, true);
            app.RearMenu.Accelerator = 'R';
            app.RearMenu.Text = 'Rear';
            app.SideMenu = uimenu(app.ViewpointsMenu);
            app.SideMenu.MenuSelectedFcn = createCallbackFcn(app, @SideMenuSelected, true);
            app.SideMenu.Accelerator = 'S';
            app.SideMenu.Text = 'Side';
            app.TopMenu = uimenu(app.ViewpointsMenu);
            app.TopMenu.MenuSelectedFcn = createCallbackFcn(app, @TopMenuSelected, true);
            app.TopMenu.Accelerator = 'T';
            app.TopMenu.Text = 'Top';
            app.VisibilityMenu = uimenu(app.ContextMenu);
            app.VisibilityMenu.Text = 'Visibility';
            app.FrontMenu_2 = uimenu(app.VisibilityMenu);
            app.FrontMenu_2.Text = 'Front';
            app.LowerAArmMenu = uimenu(app.FrontMenu_2);
            app.LowerAArmMenu.MenuSelectedFcn = createCallbackFcn(app, @LowerAArmMenuSelected, true);
            app.LowerAArmMenu.Checked = 'on';
            app.LowerAArmMenu.Text = 'Lower A-Arm';
            app.UpperAArmMenu = uimenu(app.FrontMenu_2);
            app.UpperAArmMenu.MenuSelectedFcn = createCallbackFcn(app, @UpperAArmMenuSelected, true);
            app.UpperAArmMenu.Checked = 'on';
            app.UpperAArmMenu.Text = 'Upper A-Arm';
            app.TieRodMenu = uimenu(app.FrontMenu_2);
            app.TieRodMenu.MenuSelectedFcn = createCallbackFcn(app, @TieRodMenuSelected, true);
            app.TieRodMenu.Checked = 'on';
            app.TieRodMenu.Text = 'Tie Rod';
            app.UprightMenu = uimenu(app.FrontMenu_2);
            app.UprightMenu.MenuSelectedFcn = createCallbackFcn(app, @UprightMenuSelected, true);
            app.UprightMenu.Checked = 'on';
            app.UprightMenu.Text = 'Upright';
            app.RockerMenu = uimenu(app.FrontMenu_2);
            app.RockerMenu.MenuSelectedFcn = createCallbackFcn(app, @RockerMenuSelected, true);
            app.RockerMenu.Checked = 'on';
            app.RockerMenu.Text = 'Rocker';
            app.CoiloverMenu = uimenu(app.FrontMenu_2);
            app.CoiloverMenu.MenuSelectedFcn = createCallbackFcn(app, @CoiloverMenuSelected, true);
            app.CoiloverMenu.Checked = 'on';
            app.CoiloverMenu.Text = 'Coilover';
            app.PushPullRodMenu = uimenu(app.FrontMenu_2);
            app.PushPullRodMenu.MenuSelectedFcn = createCallbackFcn(app, @PushPullRodMenuSelected, true);
            app.PushPullRodMenu.Checked = 'on';
            app.PushPullRodMenu.Text = 'Push/Pull Rod';
            app.ARBMenu = uimenu(app.FrontMenu_2);
            app.ARBMenu.MenuSelectedFcn = createCallbackFcn(app, @ARBMenuSelected, true);
            app.ARBMenu.Checked = 'on';
            app.ARBMenu.Text = 'ARB';
            app.TiresMenu = uimenu(app.FrontMenu_2);
            app.TiresMenu.MenuSelectedFcn = createCallbackFcn(app, @TiresMenuSelected, true);
            app.TiresMenu.Checked = 'on';
            app.TiresMenu.Text = 'Tires';
            app.RollCenterMenu = uimenu(app.FrontMenu_2);
            app.RollCenterMenu.MenuSelectedFcn = createCallbackFcn(app, @RollCenterMenuSelected, true);
            app.RollCenterMenu.Text = 'Roll Center';
            app.InstantCentersMenu = uimenu(app.FrontMenu_2);
            app.InstantCentersMenu.MenuSelectedFcn = createCallbackFcn(app, @InstantCentersMenuSelected, true);
            app.InstantCentersMenu.Text = 'Instant Centers';
            app.WheelAxisMenu = uimenu(app.FrontMenu_2);
            app.WheelAxisMenu.MenuSelectedFcn = createCallbackFcn(app, @WheelAxisMenuSelected, true);
            app.WheelAxisMenu.Text = 'Wheel Axis';
            app.RearMenu_2 = uimenu(app.VisibilityMenu);
            app.RearMenu_2.Text = 'Rear';
            app.LowerAArmMenu_2 = uimenu(app.RearMenu_2);
            app.LowerAArmMenu_2.MenuSelectedFcn = createCallbackFcn(app, @LowerAArmMenu_2Selected, true);
            app.LowerAArmMenu_2.Checked = 'on';
            app.LowerAArmMenu_2.Text = 'Lower A-Arm';
            app.UpperAArmMenu_2 = uimenu(app.RearMenu_2);
            app.UpperAArmMenu_2.MenuSelectedFcn = createCallbackFcn(app, @UpperAArmMenu_2Selected, true);
            app.UpperAArmMenu_2.Checked = 'on';
            app.UpperAArmMenu_2.Text = 'Upper A-Arm';
            app.TieRodMenu_2 = uimenu(app.RearMenu_2);
            app.TieRodMenu_2.MenuSelectedFcn = createCallbackFcn(app, @TieRodMenu_2Selected, true);
            app.TieRodMenu_2.Checked = 'on';
            app.TieRodMenu_2.Text = 'Tie Rod';
            app.UprightMenu_2 = uimenu(app.RearMenu_2);
            app.UprightMenu_2.MenuSelectedFcn = createCallbackFcn(app, @UprightMenu_2Selected, true);
            app.UprightMenu_2.Checked = 'on';
            app.UprightMenu_2.Text = 'Upright';
            app.RockerMenu_2 = uimenu(app.RearMenu_2);
            app.RockerMenu_2.MenuSelectedFcn = createCallbackFcn(app, @RockerMenu_2Selected, true);
            app.RockerMenu_2.Checked = 'on';
            app.RockerMenu_2.Text = 'Rocker';
            app.CoiloverMenu_2 = uimenu(app.RearMenu_2);
            app.CoiloverMenu_2.MenuSelectedFcn = createCallbackFcn(app, @CoiloverMenu_2Selected, true);
            app.CoiloverMenu_2.Checked = 'on';
            app.CoiloverMenu_2.Text = 'Coilover';
            app.PushPullRodMenu_2 = uimenu(app.RearMenu_2);
            app.PushPullRodMenu_2.MenuSelectedFcn = createCallbackFcn(app, @PushPullRodMenu_2Selected, true);
            app.PushPullRodMenu_2.Checked = 'on';
            app.PushPullRodMenu_2.Text = 'Push/Pull Rod';
            app.ARBMenu_2 = uimenu(app.RearMenu_2);
            app.ARBMenu_2.MenuSelectedFcn = createCallbackFcn(app, @ARBMenu_2Selected, true);
            app.ARBMenu_2.Text = 'ARB';
            app.TiresMenu_2 = uimenu(app.RearMenu_2);
            app.TiresMenu_2.MenuSelectedFcn = createCallbackFcn(app, @TiresMenu_2Selected, true);
            app.TiresMenu_2.Checked = 'on';
            app.TiresMenu_2.Text = 'Tires';
            app.RollCenterMenu_2 = uimenu(app.RearMenu_2);
            app.RollCenterMenu_2.MenuSelectedFcn = createCallbackFcn(app, @RollCenterMenu_2Selected, true);
            app.RollCenterMenu_2.Text = 'Roll Center';
            app.InstantCentersMenu_2 = uimenu(app.RearMenu_2);
            app.InstantCentersMenu_2.MenuSelectedFcn = createCallbackFcn(app, @InstantCentersMenu_2Selected, true);
            app.InstantCentersMenu_2.Text = 'Instant Centers';
            app.WheelAxisMenu_2 = uimenu(app.RearMenu_2);
            app.WheelAxisMenu_2.MenuSelectedFcn = createCallbackFcn(app, @WheelAxisMenu_2Selected, true);
            app.WheelAxisMenu_2.Text = 'Wheel Axis';
            app.CoGMenu = uimenu(app.VisibilityMenu);
            app.CoGMenu.MenuSelectedFcn = createCallbackFcn(app, @CoGMenuSelected, true);
            app.CoGMenu.Checked = 'on';
            app.CoGMenu.Text = 'CoG';
            app.PitchCenterMenu = uimenu(app.VisibilityMenu);
            app.PitchCenterMenu.MenuSelectedFcn = createCallbackFcn(app, @PitchCenterMenuSelected, true);
            app.PitchCenterMenu.Text = 'Pitch Center';
            app.RollAxisMenu = uimenu(app.VisibilityMenu);
            app.RollAxisMenu.MenuSelectedFcn = createCallbackFcn(app, @RollAxisMenuSelected, true);
            app.RollAxisMenu.Text = 'Roll Axis';
            % Assign app.ContextMenu
            app.CarPlotTab.ContextMenu = app.ContextMenu;
            app.UIAxes2.ContextMenu = app.ContextMenu;
            % Show the figure after all components are created
            app.SuspensionSetupUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Suspension(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.SuspensionSetupUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SuspensionSetupUIFigure)
        end
    end
end