classdef TelemetryConverter < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        TelemetryConverterUIFigure      matlab.ui.Figure
        TrackGeneratorPanel             matlab.ui.container.Panel
        InterpolateTrackPanel           matlab.ui.container.Panel
        InterpolateButton               matlab.ui.control.Button
        InterpolateexistingTrackButton  matlab.ui.control.Button
        DistancepointEditField          matlab.ui.control.NumericEditField
        DistancepointEditFieldLabel     matlab.ui.control.Label
        AddtoSimulationButton           matlab.ui.control.Button
        FilterCornerRadiusPanel         matlab.ui.container.Panel
        Switch                          matlab.ui.control.Switch
        smoothingmethodDropDown         matlab.ui.control.DropDown
        smoothingmethodDropDownLabel    matlab.ui.control.Label
        UsesmootheddataCheckBox         matlab.ui.control.CheckBox
        spanEditField                   matlab.ui.control.NumericEditField
        spanEditFieldLabel              matlab.ui.control.Label
        TrackAnalyzerButton             matlab.ui.control.Button
        DrawTrackButton                 matlab.ui.control.Button
        DrawRadiusPlotButton            matlab.ui.control.Button
        TrackDataPanel                  matlab.ui.container.Panel
        Copyright2021BalticRacingbyEricDorniedenLabel  matlab.ui.control.Label
        DataPointsLabel_2               matlab.ui.control.Label
        ElevationChangeLabel            matlab.ui.control.Label
        MinRadiusLabel                  matlab.ui.control.Label
        ApexesLabel                     matlab.ui.control.Label
        SaveTrackButton                 matlab.ui.control.Button
        GenerateTrackButton             matlab.ui.control.Button
        TelemetryConverterPanel         matlab.ui.container.Panel
        SelectionDataPanel              matlab.ui.container.Panel
        DistanceUnitButtonGroup         matlab.ui.container.ButtonGroup
        meterButton                     matlab.ui.control.RadioButton
        kilometerButton                 matlab.ui.control.RadioButton
        DistanceLabel                   matlab.ui.control.Label
        Panel                           matlab.ui.container.Panel
        DataPointsLabel                 matlab.ui.control.Label
        UpdatePlotButton                matlab.ui.control.Button
        ConvertButton                   matlab.ui.control.Button
        startEditField                  matlab.ui.control.NumericEditField
        startEditFieldLabel             matlab.ui.control.Label
        finishEditField                 matlab.ui.control.NumericEditField
        finishEditFieldLabel            matlab.ui.control.Label
        loadcsvButton                   matlab.ui.control.Button
        UIAxes                          matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        CallingApp;   % Main app object#
        csv_import;
        Data;
        TrackData;
        R_smooth;
        ApexIndexes;
        ApexIndexes_smooth;
        TrackManagerApp;    % TrackManager app
        TrackAnalyzerApp;
    end
    
    methods (Access = private)
        
        %% Draws the GPS path with the given start and end values
        function updatePlot(app)
            
            cla(app.UIAxes);
            
            start = app.startEditField.Value;
            finish = app.finishEditField.Value;
            
            if app.CallingApp.DarkModeCheckBox.Value
                plot(app.UIAxes,app.Data.GPS_Longitude(start:finish),app.Data.GPS_Latitude(start:finish),'w')
            else
                plot(app.UIAxes,app.Data.GPS_Longitude(start:finish),app.Data.GPS_Latitude(start:finish),'k')
            end

            hold(app.UIAxes, 'on'); 
            
            % Mark start point
            plot(app.UIAxes,app.Data.GPS_Longitude(start),app.Data.GPS_Latitude(start),'r*')
            % Mark end point 
            plot(app.UIAxes,app.Data.GPS_Longitude(finish),app.Data.GPS_Latitude(finish),'b*')
            
            if app.kilometerButton.Value
                app.DistanceLabel.Text = ['Distance: ' num2str(((app.Data.Distance(finish)-app.Data.Distance(start))*1000)) ' m'];
            else
                app.DistanceLabel.Text = ['Distance: ' num2str(app.Data.Distance(finish)-app.Data.Distance(start)) ' m'];
            end
        end
        
        function writeCSV(app)         
            Data_section = app.Data(app.startEditField.Value:app.finishEditField.Value,:);
            
            time = Data_section(:,1);
            distance = Data_section(:,2);
            
            Data_section(:,1) = distance;
            Data_section(:,2) = time;
            
            Data_section.Time = Data_section.Time-Data_section.Time(1);
            
            % Opens a save file dialog
            [filename, pathname] = uiputfile('*.csv','Save data as','loggedData.csv');
            
            newfilename = fullfile(pathname, filename);
            
            writetable(Data_section,newfilename) 
        end
        
        %% Creates an track from recorded GPS data
        function generateTrack(app)
            lon = app.Data.GPS_Longitude;
            lat = app.Data.GPS_Latitude;
            heigth = app.Data.GPS_Elevation;
            distance = app.Data.Distance;
            
            %% Calculate UTM Zone (Universal Transverse Mercator coordinate system)
            z1 = utmzone(lat(1),lon(1));
            
            %% Convert Data to UTM
            [ellipsoid,estr] = utmgeoid(z1);
            utmstruct = defaultm('utm');
            utmstruct.zone = z1;
            utmstruct.geoid = ellipsoid;
            utmstruct = defaultm(utmstruct);
            
            [x,y] = mfwdtran(utmstruct,lat,lon);
            
            %% Normalize Data to Zero (minimum of x and y)
            % Normalize x, y and z coordinates
            x_norm = x-min(x);
            y_norm = y-min(y);
            heigth_norm = heigth-min(heigth);
            
            % complete data for one lap
            start = app.startEditField.Value;
            finish = app.finishEditField.Value;
            
            x_lap = x_norm(start:finish);
            y_lap = y_norm(start:finish);
            z_lap = heigth_norm(start:finish);
            
            if app.meterButton.Value
                distance_lap = distance(start:finish)-min(distance(start:finish));
            else
                distance_lap = (distance(start:finish)-min(distance(start:finish))).*1000;
            end
            
            % Calculate Corner radiuses
            X = [x_lap, y_lap];       
            [L2,R2,K2] = curvature(app,X);
            
            % Add missing first and last corner radius
            R2(1) = R2(2);
            R2(end) = R2(end-1);
            
            %% Smooth the generated data
            %% a good beginning is a moving average filter with a span of 5,
            %% try from there and adjust as needed.
            if (app.Switch.Value == "smooth")
                app.R_smooth = smooth(R2, app.spanEditField.Value, app.smoothingmethodDropDown.Value);
            else
                app.R_smooth = smoothdata(R2);
            end  
            
            direction = zeros(1,length(x_lap));
            
            % Determine direction of the radius R
            for k =2:length(x_lap)-1
                
                m(k) = (y_lap(k)-y_lap(k-1))/(x_lap(k)-x_lap(k-1));
                
                if x_lap(k) < x_lap(k-1)
                    Yfiktiv = m(k)*abs((x_lap(k+1)-x_lap(k)))+y_lap(k+1);
                else
                   Yfiktiv = -m(k)*abs((x_lap(k+1)-x_lap(k)))+y_lap(k+1);
                end
                
                if x_lap(k) < x_lap(k-1) && Yfiktiv < y_lap(k)
                    direction(k) = -1;
                    
                elseif x_lap(k) < x_lap(k-1) && Yfiktiv > y_lap(k)
                    direction(k) = 1;
                    
                elseif x_lap(k) > x_lap(k-1) && Yfiktiv < y_lap(k)
                    direction(k) = 1;   
                    
                elseif x_lap(k) > x_lap(k-1) && Yfiktiv > y_lap(k)
                    direction(k) = -1;
                end                    
            end         
            
            direction(1) = direction(2);
            direction(end) = direction(end-1);
            app.R_smooth = app.R_smooth.*direction';  % Adding the direction of the corner (+/-)
            
            % Save track file
            app.TrackData = [x_lap, y_lap, z_lap, distance_lap, R2];
            
            % Calls the .m file 'Apexes' to calculate the Apexes of the given track
            app.ApexIndexes = Apexes(abs(app.TrackData(:,5)));   
            app.ApexIndexes_smooth = Apexes(abs(app.R_smooth));  
            
            %% Track Data labels
            % Apexes Label
            app.ApexesLabel.Text = ['Apexes: ' num2str(length(app.ApexIndexes)) ', smooth: ' num2str(length(app.ApexIndexes_smooth))];
            % Min Radius Label
            app.MinRadiusLabel.Text = ['Min Radius: ' num2str(min(R2)) ' [m], smooth: ' num2str(min(app.R_smooth)) ' [m]'];
            % Elevation Change Label
            app.ElevationChangeLabel.Text = ['Elevation Change: ' num2str(max(z_lap)-min(z_lap)) ' [m], (' num2str(min(z_lap)) '-' num2str(max(z_lap)) ' [m])'];
            % Data Points Label
            app.DataPointsLabel_2.Text = ['Data Points: ' num2str(length(x_lap))];
        end
        
        function [L,R,k] = curvature(app,X)
            % Radius of curvature and curvature vector for 2D or 3D curve
            %  [L,R,k] = curvature(X)
            %   X:   2 or 3 column array of x, y (and possibly z) coordiates
            %   L:   Cumulative arc length
            %   R:   Radius of curvature
            %   k:   Curvature vector
            % The scalar curvature value is 1./R
            % Version 2.6: Calculates end point values for closed curve
              N = size(X,1);
              dims = size(X,2);
              if dims == 2
                X = [X,zeros(N,1)];  % Use 3D expressions for 2D as well
              end
              L = zeros(N,1);
              R = NaN(N,1);
              k = NaN(N,3);
              for i = 2:N-1
                [R(i),~,k(i,:)] = circumcenter(app,X(i,:)',X(i-1,:)',X(i+1,:)');
                L(i) = L(i-1)+norm(X(i,:)-X(i-1,:));
              end
              if norm(X(1,:)-X(end,:)) < 1e-10 % Closed curve. 
                [R(1),~,k(1,:)] = circumcenter(app,X(end-1,:)',X(1,:)',X(2,:)');
                R(end) = R(1);
                k(end,:) = k(1,:);
                L(end) = L(end-1) + norm(X(end,:)-X(end-1,:));
              end
              i = N;
              L(i) = L(i-1)+norm(X(i,:)-X(i-1,:));
              if dims == 2
                k = k(:,1:2);
              end
        end
        
        function [R,M,k] = circumcenter(~,A,B,C)
            % Center and radius of the circumscribed circle for the triangle ABC
            %  A,B,C  3D coordinate vectors for the triangle corners
            %  R      Radius
            %  M      3D coordinate vector for the center
            %  k      Vector of length 1/R in the direction from A towards M
            %         (Curvature vector)
              D = cross(B-A,C-A);
              b = norm(A-C);
              c = norm(A-B);
              if nargout == 1
                a = norm(B-C);     % slightly faster if only R is required
                R = a*b*c/2/norm(D);
                return
              end
              E = cross(D,B-A);
              F = cross(D,C-A);  
              G = (b^2*E-c^2*F)/norm(D)^2/2;
              M = A + G;
              R = norm(G);  % Radius of curvature
              if R == 0
                k = G;
              else
                k = G'/R^2;   % Curvature vector
              end
        end


        function [R,L2] = CalculateCornerRadius(app, x_lap,y_lap)
            % Calculate Corner radiuses
            X = [x_lap, y_lap];       
            [L2,R2,K2] = curvature(app,X);
            
            % Add missing first and last corner radius
            R2(1) = R2(2);
            R2(end) = R2(end-1);
            
            % Add missing first and last Distance
            L2(1) = 0;
            L2(end) = L2(end-1);
            
            %% Smooth the generated data
            %% a good beginning is a moving average filter with a span of 5,
            %% try from there and adjust as needed.
            if (app.Switch.Value == "smooth")
                R_smoothed = smooth(R2, app.spanEditField.Value, app.smoothingmethodDropDown.Value);
            else
                R_smoothed = smoothdata(R2);
            end            
            
            direction = zeros(1,length(x_lap));
            
            % Determine direction of the radius R
            for k =2:length(x_lap)-1
                
                m(k) = (y_lap(k)-y_lap(k-1))/(x_lap(k)-x_lap(k-1));
                
                if x_lap(k) < x_lap(k-1)
                    Yfiktiv = m(k)*abs((x_lap(k+1)-x_lap(k)))+y_lap(k+1);
                else
                   Yfiktiv = -m(k)*abs((x_lap(k+1)-x_lap(k)))+y_lap(k+1);
                end
                
                if x_lap(k) < x_lap(k-1) && Yfiktiv < y_lap(k)
                    direction(k) = -1;
                    
                elseif x_lap(k) < x_lap(k-1) && Yfiktiv > y_lap(k)
                    direction(k) = 1;
                    
                elseif x_lap(k) > x_lap(k-1) && Yfiktiv < y_lap(k)
                    direction(k) = 1;   
                    
                elseif x_lap(k) > x_lap(k-1) && Yfiktiv > y_lap(k)
                    direction(k) = -1;
                end                    
            end         
            
            direction(1) = direction(2);
            direction(end) = direction(end-1);
            R = R_smoothed.*direction';  % Adding the direction of the corner (+/-)
        end
        
        function saveTrack(app)
            % Opens a save file dialog
            [filename, pathname] = uiputfile('*.mat','Save track as','Track.mat');
            
            newfilename = fullfile(pathname, filename);
            
            % Saves file with the given parameters
            Track = app.TrackData;
            
          % Allready checked in CalculateCornerRadius        
            % Check if smoothed or normal data should be saved
            if app.UsesmootheddataCheckBox.Value
                Track(:,5) = app.R_smooth;
                %Track(:,5) = app.R_smooth(1:end-1);
            end

            save(newfilename,'Track'); 
        end
        
        %% Interpolates the track Coordinates
        function [x,y,z] = InterpolateTrackData(app,x_Track,y_Track,z_Track,s_Track)
            stepSize = app.DistancepointEditField.Value;
            
            TrackLength = max(s_Track);
            
            numSteps = round(TrackLength/stepSize);
            
            for i = 1:stepSize:TrackLength
                x(i) = scatteredInterpolant(s_Track,x_Track,i);
                y(i) = interp1(s_Track,y_Track,i);
                z(i) = interp1(s_Track,z_Track,i);
            end
        end
    end
    
    methods (Access = public)
        
        function changeColorScheme(app)
            PrimaryColor = app.CallingApp.PrimaryColor;
            OnPrimaryColor = app.CallingApp.OnPrimaryColor;   
            PrimaryVariantColor = app.CallingApp.PrimaryVariantColor;
            OnPrimaryVariantColor = app.CallingApp.OnPrimaryVariantColor;
            SecondaryColor = app.CallingApp.SecondaryColor;
            OnSecondaryColor = app.CallingApp.OnSecondaryColor;
            BackgroundColor = app.CallingApp.BackgroundColor;
            OnBackgroundColor = app.CallingApp.OnBackgroundColor;
            SurfaceColor = app.CallingApp.SurfaceColor;
            OnSurfaceColor = app.CallingApp.OnSurfaceColor;
            ErrorColor = app.CallingApp.ErrorColor;
            OnErrorColor = app.CallingApp.OnErrorColor;
            BorderType = app.CallingApp.BorderType;                 
            Host_Logo = app.CallingApp.Host_Logo;

            %% Panels
            app.TelemetryConverterUIFigure.Color = BackgroundColor;

            app.TelemetryConverterPanel.BackgroundColor = BackgroundColor;
            app.TelemetryConverterPanel.ForegroundColor = OnSurfaceColor;
            app.TelemetryConverterPanel.BorderType = BorderType;

            app.TrackGeneratorPanel.BackgroundColor = SurfaceColor;
            app.TrackGeneratorPanel.ForegroundColor = OnSurfaceColor;
            app.TrackGeneratorPanel.BorderType = BorderType;

            app.Panel.BackgroundColor = SurfaceColor;
            app.Panel.ForegroundColor = OnSurfaceColor;
            app.Panel.BorderType = BorderType;

            app.InterpolateTrackPanel.BackgroundColor = SurfaceColor;
            app.InterpolateTrackPanel.ForegroundColor = OnSurfaceColor;
            app.InterpolateTrackPanel.BorderType = BorderType;

            app.FilterCornerRadiusPanel.BackgroundColor = SurfaceColor;
            app.FilterCornerRadiusPanel.ForegroundColor = OnSurfaceColor;
            app.FilterCornerRadiusPanel.BorderType = BorderType;

            app.TrackDataPanel.BackgroundColor = SurfaceColor;
            app.TrackDataPanel.ForegroundColor = OnSurfaceColor;
            app.TrackDataPanel.BorderType = BorderType;

            app.SelectionDataPanel.BackgroundColor = SurfaceColor;
            app.SelectionDataPanel.ForegroundColor = OnSurfaceColor;
            app.SelectionDataPanel.BorderType = BorderType;

            app.DistanceUnitButtonGroup.ForegroundColor = OnSurfaceColor;
            app.DistanceUnitButtonGroup.BackgroundColor = SurfaceColor;
            app.DistanceUnitButtonGroup.BorderType = BorderType;

            %% Buttons
            app.loadcsvButton.FontColor = OnPrimaryColor;
            app.loadcsvButton.BackgroundColor = PrimaryColor;

            app.UpdatePlotButton.FontColor = OnPrimaryColor;
            app.UpdatePlotButton.BackgroundColor = PrimaryColor;

            app.ConvertButton.FontColor = OnPrimaryColor;
            app.ConvertButton.BackgroundColor = PrimaryColor;

            app.GenerateTrackButton.FontColor = OnPrimaryColor;
            app.GenerateTrackButton.BackgroundColor = PrimaryColor;

            app.DrawRadiusPlotButton.FontColor = OnPrimaryColor;
            app.DrawRadiusPlotButton.BackgroundColor = PrimaryColor;

            app.DrawTrackButton.FontColor = OnPrimaryColor;
            app.DrawTrackButton.BackgroundColor = PrimaryColor;

            app.SaveTrackButton.FontColor = OnPrimaryColor;
            app.SaveTrackButton.BackgroundColor = PrimaryColor;

            app.AddtoSimulationButton.FontColor = OnPrimaryColor;
            app.AddtoSimulationButton.BackgroundColor = PrimaryColor;

            app.TrackAnalyzerButton.FontColor = OnPrimaryColor;
            app.TrackAnalyzerButton.BackgroundColor = PrimaryColor;

            app.InterpolateButton.FontColor = OnPrimaryColor;
            app.InterpolateButton.BackgroundColor = PrimaryColor;
            
            app.InterpolateexistingTrackButton.FontColor = OnPrimaryColor;
            app.InterpolateexistingTrackButton.BackgroundColor = PrimaryColor;

            %% Labels
            app.DataPointsLabel.FontColor = OnPrimaryColor;
            app.DistanceLabel.FontColor = OnPrimaryColor;
            app.kilometerButton.FontColor = OnPrimaryColor;
            app.meterButton.FontColor = OnPrimaryColor;
            app.ApexesLabel.FontColor = OnPrimaryColor;
            app.MinRadiusLabel.FontColor = OnPrimaryColor;
            app.ElevationChangeLabel.FontColor = OnPrimaryColor;
            app.DataPointsLabel_2.FontColor = OnPrimaryColor;
            app.Copyright2021BalticRacingbyEricDorniedenLabel.FontColor = OnPrimaryColor;

            app.DistancepointEditFieldLabel.FontColor = OnPrimaryColor;
            app.DistancepointEditField.FontColor = OnPrimaryColor;
            app.DistancepointEditField.BackgroundColor = PrimaryColor;

            app.startEditField.FontColor = OnPrimaryColor;
            app.startEditField.BackgroundColor = PrimaryColor;

            app.finishEditField.FontColor = OnPrimaryColor;
            app.finishEditField.BackgroundColor = PrimaryColor;

            app.smoothingmethodDropDownLabel.FontColor = OnPrimaryColor;
            app.smoothingmethodDropDown.FontColor = OnPrimaryColor;
            app.smoothingmethodDropDown.BackgroundColor = PrimaryColor;

            app.spanEditFieldLabel.FontColor = OnPrimaryColor;
            app.spanEditField.FontColor = OnPrimaryColor;
            app.spanEditField.BackgroundColor = PrimaryColor;

            app.UsesmootheddataCheckBox.FontColor = OnPrimaryColor;

            app.Switch.FontColor = OnPrimaryColor;

            %% UIaxes
            app.UIAxes.Title.Color = OnPrimaryColor;
            app.UIAxes.XColor = OnPrimaryColor;
            app.UIAxes.YColor = OnPrimaryColor;
            app.UIAxes.Color = SurfaceColor;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            % Store main app object
            app.CallingApp = mainapp;

            changeColorScheme(app);
        end

        % Button pushed function: loadcsvButton
        function loadcsvButtonPushed(app, event)
            app.Data = [];
            app.TrackData = [];
            
            
            [app.csv_import, ~] = uigetfile('*.csv');  %opens a .csv file
            
            %Data = readtable(app.csv_import,'NumHeaderLines',17);

            opts = detectImportOptions(app.csv_import,'NumHeaderLines',17); % number of header lines which are to be ignored
            opts.VariableNamesLine = 13; % row number which has variable names
            opts.DataLine = 18; % row number from which the actual data starts
            opts.VariableUnitsLine = 15; % row number which has units specified
            app.Data = readtable(app.csv_import,opts);
            
            % Set the limits for the start and finish edit field 
            app.startEditField.Limits = [1 height(app.Data)-1];
            app.finishEditField.Limits = [1 height(app.Data)];
            
            % Pre initalize 
            app.finishEditField.Value = height(app.Data);
            
            % Sets the Datapoints label text
            app.DataPointsLabel.Text = ['Data Points: ' num2str(height(app.Data))];
            
            
            updatePlot(app);
        end

        % Button pushed function: UpdatePlotButton
        function UpdatePlotButtonPushed(app, event)
            updatePlot(app);
        end

        % Button pushed function: ConvertButton
        function ConvertButtonPushed(app, event)
           writeCSV(app);         
        end

        % Button pushed function: GenerateTrackButton
        function GenerateTrackButtonPushed(app, event)
            generateTrack(app);
        end

        % Button pushed function: SaveTrackButton
        function SaveTrackButtonPushed(app, event)
            saveTrack(app); 
        end

        % Button pushed function: DrawRadiusPlotButton
        function DrawRadiusPlotButtonPushed(app, event)
            %plot(app.TrackData(:,5), app.R_smooth(:));
            x = 1:length(app.R_smooth);
            
            plot(x,app.R_smooth(:),x,app.TrackData(:,5));
            legend('Smoothed Corner Radius [m]','Corner Radius [m]')
            title('Corner Radius Plot','FontSize',12)
            xlabel('Track Length [m]','FontSize',10) 
            ylabel('Corner Radius [m]','FontSize',10)
            grid on
            box on
        end

        % Button pushed function: DrawTrackButton
        function DrawTrackButtonPushed(app, event)
            
            % Checks if smoothed or normal data should be used for drawn
            % track
            if app.UsesmootheddataCheckBox.Value
                ApexList = app.ApexIndexes_smooth;
            else
                ApexList = app.ApexIndexes;
            end
            
            % Creates an array of every apex on the track
            k = 1;
            for i = 1:length(app.TrackData(:,1))
                if ismember(i,ApexList)
                    apex(k,1) = app.TrackData(i,1);
                    apex(k,2) = app.TrackData(i,2);
                    isApex(i) = 1;
                    k = k + 1;
                else
                    isApex(i) = 0;
                end
            end
            
            scatter(app.TrackData(1:end,1),app.TrackData(1:end,2),40,app.TrackData(:,5),'filled')
            hold on
            
            scatter(apex(1:end,1),apex(1:end,2),300)
            hold off
            
        end

        % Selection changed function: DistanceUnitButtonGroup
        function DistanceUnitButtonGroupSelectionChanged(app, event)
            start = app.startEditField.Value;
            finish = app.finishEditField.Value;
            
            if app.kilometerButton.Value
                app.DistanceLabel.Text = ['Distance: ' num2str(((app.Data.Distance(finish)-app.Data.Distance(start))*1000)) ' m'];
            else
                app.DistanceLabel.Text = ['Distance: ' num2str(app.Data.Distance(finish)-app.Data.Distance(start)) ' m'];
            end
        end

        % Button pushed function: AddtoSimulationButton
        function AddtoSimulationButtonPushed(app, event)
            if app.CallingApp.TrackManagerButton.Enable
                app.CallingApp.TrackManagerButton.Enable = 'off';
                app.TrackManagerApp = TrackManager(app.CallingApp);
            end
            
        end

        % Button pushed function: TrackAnalyzerButton
        function TrackAnalyzerButtonPushed(app, event)
            app.TrackAnalyzerApp = TrackAnalyzer(app.CallingApp);
        end

        % Button pushed function: InterpolateexistingTrackButton
        function InterpolateexistingTrackButtonPushed(app, event)
            [TrackName, ~] = uigetfile('*.mat');  %opens a .csv file
            
            load(TrackName,'Track');
             
            x_Track = Track(:,1);           % [m] X-Coordinate of the Track
            y_Track = Track(:,2);           % [m] Y-Coordinate of the Track
            z_Track = Track(:,3);           % [m] Z-Coordinate of the Track
            s_Track = Track(:,4);
            
%             stepSize = app.DistancepointEditField.Value;
%             
%             TrackLength = max(s_Track);
%             
%             numSteps = round(TrackLength/stepSize);
%             
%             for i = 0:stepSize:TrackLength
%                 x(i) = gridedInterpolant(s_Track,x_Track,i);
%                 y(i) = interp1(s_Track,y_Track,i);
%                 z(i) = interp1(s_Track,z_Track,i);
%             end
             
            %% Interpolate X and Y Coordinates
            %[x,y,z] = InterpolateTrackData(app,x_Track,y_Track,z_Track);

            %% Generate new Corner Radius and Distance
            [R,L] = CalculateCornerRadius(app,x_Track,y_Track);
            
            % Interpolate Track Data
            x_Track_inerp = interp(x_Track, 2);
            y_Track_inerp = interp(y_Track, 2);
            z_Track_inerp = interp(z_Track, 2);
            
            L_step = max(L) / length(x_Track_inerp);
            L_inerp(1) = 0;
            
%             for i=2:1:length(x_Track_inerp)
%                 L_inerp(i) = L_inerp(end) + L_step;
%             end
            
            for i=1:1:length(Track)-1
                L_d(i) = L(i)+(L(i+1)-L(i))/2;
            end
            
            L_new = [];
            
            for i=1:1:length(Track)-1
                L_new(end+1) = L(i);
            end
            
            %L_inerp = interp(L,2);
            R_inerp = interp(R,2);
            
            % Save track file
            app.TrackData = [x_Track_inerp, y_Track_inerp, z_Track_inerp, L_inerp', R_inerp];
             
%             %% Calculate extra points (higher resolution)
%             for i=1:1:length(Track)-1
%                 L_d(i) = L(i)+(L(i+1)-L(i))/2;
%                 R_d(i) = R(i);
%                 x(i) = Track(i,1)+(Track(i+1,1)-Track(i,1))/2;
%                 y(i) = Track(i,2)+(Track(i+1,2)-Track(i,2))/2;
%                 z(i) = Track(i,3)+(Track(i+1,3)-Track(i,3))/2;
%             end
%             
%             L_new = [];
%             R_new = [];
%             x_new = [];
%             y_new = [];
%             z_new = [];
%             
%             for i=1:1:length(Track)-1
%                 L_new(end+1) = L(i);
%                 L_new(end+1) = L_d(i);
%                 R_new(end+1) = R(i);
%                 R_new(end+1) = R_d(i);
%                 x_new(end+1) = x_Track(i);
%                 x_new(end+1) = x(i);
%                 y_new(end+1) = y_Track(i);
%                 y_new(end+1) = y(i);
%                 z_new(end+1) = z_Track(i);
%                 z_new(end+1) = z(i);
%             end
%              
%             % Save track file
%             app.TrackData = [x_new', y_new', z_new', L_new', R_new'];
            
            saveTrack(app);
        end

        % Button pushed function: InterpolateButton
        function InterpolateButtonPushed(app, event)
            %% Interpolate X and Y Coordinates
            [x,y,z] = InterpolateTrackData(app,app.TrackData(:,1),app.TrackData(:,2),app.TrackData(:,3),app.TrackData(:,4));
            
            %% Generate new Corner Radius and Distance
            [R,L] = CalculateCornerRadius(app,x,y);
            
            % Save track file
            app.TrackData = [x, y, z, L, R];
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.TelemetryConverterUIFigure = uifigure('Visible', 'off');
            app.TelemetryConverterUIFigure.Name = 'Telemetry Converter';
            app.TelemetryConverterUIFigure.Icon = 'Baltic.png';
            app.TelemetryConverterPanel = uipanel(app.TelemetryConverterUIFigure);
            app.TelemetryConverterPanel.Title = 'Telemetry Converter';
            app.UIAxes = uiaxes(app.TelemetryConverterPanel);
            title(app.UIAxes, 'Track')
            xlabel(app.UIAxes, 'X-Coordinate')
            ylabel(app.UIAxes, 'Y-Coordinate')
            zlabel(app.UIAxes, 'Z')
            app.Panel = uipanel(app.TelemetryConverterPanel);
            app.Panel.Title = 'Panel';
            app.loadcsvButton = uibutton(app.Panel, 'push');
            app.loadcsvButton.ButtonPushedFcn = createCallbackFcn(app, @loadcsvButtonPushed, true);
            app.loadcsvButton.Text = 'load .csv';
            app.finishEditFieldLabel = uilabel(app.Panel);
            app.finishEditFieldLabel.Text = 'finish';
            app.finishEditField = uieditfield(app.Panel, 'numeric');
            app.finishEditField.LowerLimitInclusive = 'off';
            app.finishEditField.Limits = [1 Inf];
            app.finishEditField.RoundFractionalValues = 'on';
            app.finishEditField.ValueDisplayFormat = '%.0f';
            app.finishEditField.Value = 2;
            app.startEditFieldLabel = uilabel(app.Panel);
            app.startEditFieldLabel.Text = 'start';
            app.startEditField = uieditfield(app.Panel, 'numeric');
            app.startEditField.Limits = [1 Inf];
            app.startEditField.RoundFractionalValues = 'on';
            app.startEditField.ValueDisplayFormat = '%.0f';
            app.startEditField.Value = 1;
            app.ConvertButton = uibutton(app.Panel, 'push');
            app.ConvertButton.ButtonPushedFcn = createCallbackFcn(app, @ConvertButtonPushed, true);
            app.ConvertButton.Text = 'Convert';
            app.UpdatePlotButton = uibutton(app.Panel, 'push');
            app.UpdatePlotButton.ButtonPushedFcn = createCallbackFcn(app, @UpdatePlotButtonPushed, true);
            app.UpdatePlotButton.Text = 'Update Plot';
            app.DataPointsLabel = uilabel(app.Panel);
            app.DataPointsLabel.Text = 'Data Points:';
            app.SelectionDataPanel = uipanel(app.TelemetryConverterPanel);
            app.SelectionDataPanel.Title = 'Selection Data';
            app.DistanceLabel = uilabel(app.SelectionDataPanel);
            app.DistanceLabel.Text = 'Distance:';
            app.DistanceUnitButtonGroup = uibuttongroup(app.SelectionDataPanel);
            app.DistanceUnitButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @DistanceUnitButtonGroupSelectionChanged, true);
            app.DistanceUnitButtonGroup.Title = 'Distance Unit';
            app.kilometerButton = uiradiobutton(app.DistanceUnitButtonGroup);
            app.kilometerButton.Text = 'kilometer';
            app.kilometerButton.Value = true;
            app.meterButton = uiradiobutton(app.DistanceUnitButtonGroup);
            app.meterButton.Text = 'meter';
            app.TrackGeneratorPanel = uipanel(app.TelemetryConverterUIFigure);
            app.TrackGeneratorPanel.Title = 'Track Generator';
            app.GenerateTrackButton = uibutton(app.TrackGeneratorPanel, 'push');
            app.GenerateTrackButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateTrackButtonPushed, true);
            app.GenerateTrackButton.Text = 'Generate Track';
            app.SaveTrackButton = uibutton(app.TrackGeneratorPanel, 'push');
            app.SaveTrackButton.ButtonPushedFcn = createCallbackFcn(app, @SaveTrackButtonPushed, true);
            app.SaveTrackButton.Text = 'Save Track';
            app.TrackDataPanel = uipanel(app.TrackGeneratorPanel);
            app.TrackDataPanel.Title = 'Track Data:';
            app.ApexesLabel = uilabel(app.TrackDataPanel);
            app.ApexesLabel.Text = 'Apexes:';
            app.MinRadiusLabel = uilabel(app.TrackDataPanel);
            app.MinRadiusLabel.Text = 'Min Radius:';
            app.ElevationChangeLabel = uilabel(app.TrackDataPanel);
            app.ElevationChangeLabel.Text = 'Elevation Change:';
            app.DataPointsLabel_2 = uilabel(app.TrackDataPanel);
            app.DataPointsLabel_2.Text = 'Data Points:';
            app.Copyright2021BalticRacingbyEricDorniedenLabel = uilabel(app.TrackDataPanel);
            app.Copyright2021BalticRacingbyEricDorniedenLabel.Text = 'Copyright © 2021 Baltic Racing by Eric Dornieden';
            app.DrawRadiusPlotButton = uibutton(app.TrackGeneratorPanel, 'push');
            app.DrawRadiusPlotButton.ButtonPushedFcn = createCallbackFcn(app, @DrawRadiusPlotButtonPushed, true);
            app.DrawRadiusPlotButton.Text = 'Draw Radius Plot';
            app.DrawTrackButton = uibutton(app.TrackGeneratorPanel, 'push');
            app.DrawTrackButton.ButtonPushedFcn = createCallbackFcn(app, @DrawTrackButtonPushed, true);
            app.DrawTrackButton.Text = 'Draw Track';
            app.TrackAnalyzerButton = uibutton(app.TrackGeneratorPanel, 'push');
            app.TrackAnalyzerButton.ButtonPushedFcn = createCallbackFcn(app, @TrackAnalyzerButtonPushed, true);
            app.TrackAnalyzerButton.Text = 'Track Analyzer';
            app.FilterCornerRadiusPanel = uipanel(app.TrackGeneratorPanel);
            app.FilterCornerRadiusPanel.Title = 'Filter Corner Radius';
            app.spanEditFieldLabel = uilabel(app.FilterCornerRadiusPanel);
            app.spanEditFieldLabel.Text = 'span';
            app.spanEditField = uieditfield(app.FilterCornerRadiusPanel, 'numeric');
            app.spanEditField.Value = 5;
            app.UsesmootheddataCheckBox = uicheckbox(app.FilterCornerRadiusPanel);
            app.UsesmootheddataCheckBox.Text = 'Use smoothed data';
            app.UsesmootheddataCheckBox.Value = true;
            app.smoothingmethodDropDownLabel = uilabel(app.FilterCornerRadiusPanel);
            app.smoothingmethodDropDownLabel.Text = 'smoothing method';
            app.smoothingmethodDropDown = uidropdown(app.FilterCornerRadiusPanel);
            app.smoothingmethodDropDown.Items = {'moving', 'lowess', 'loess', 'sgolay', 'rlowess', 'rloess'};
            app.smoothingmethodDropDown.Value = 'moving';
            app.Switch = uiswitch(app.FilterCornerRadiusPanel, 'slider');
            app.Switch.Items = {'smooth', 'smoothdata'};
            app.Switch.Value = 'smooth';
            app.AddtoSimulationButton = uibutton(app.TrackGeneratorPanel, 'push');
            app.AddtoSimulationButton.ButtonPushedFcn = createCallbackFcn(app, @AddtoSimulationButtonPushed, true);
            app.AddtoSimulationButton.Text = 'Add to Simulation';
            app.InterpolateTrackPanel = uipanel(app.TrackGeneratorPanel);
            app.InterpolateTrackPanel.Title = 'Interpolate Track';
            app.DistancepointEditFieldLabel = uilabel(app.InterpolateTrackPanel);
            app.DistancepointEditFieldLabel.Text = 'Distance / point';
            app.DistancepointEditField = uieditfield(app.InterpolateTrackPanel, 'numeric');
            app.DistancepointEditField.Value = 0.1;
            app.InterpolateexistingTrackButton = uibutton(app.InterpolateTrackPanel, 'push');
            app.InterpolateexistingTrackButton.ButtonPushedFcn = createCallbackFcn(app, @InterpolateexistingTrackButtonPushed, true);
            app.InterpolateexistingTrackButton.Text = 'Interpolate existing Track';
            app.InterpolateButton = uibutton(app.InterpolateTrackPanel, 'push');
            app.InterpolateButton.ButtonPushedFcn = createCallbackFcn(app, @InterpolateButtonPushed, true);
            app.InterpolateButton.Text = 'Interpolate';
            % Show the figure after all components are created
            app.TelemetryConverterUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = TelemetryConverter(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.TelemetryConverterUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.TelemetryConverterUIFigure)
        end
    end
end