classdef TrackAnalyzer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        TrackAnalyzerUIFigure         matlab.ui.Figure
        ShowonlyapexesCheckBox        matlab.ui.control.CheckBox
        ExportTrackButton             matlab.ui.control.Button
        ScatterVariableDropDown       matlab.ui.control.DropDown
        ScatterVariableDropDownLabel  matlab.ui.control.Label
        TimemapButton                 matlab.ui.control.Button
        CornerPlotButton              matlab.ui.control.Button
        HeightProfileButton           matlab.ui.control.Button
        TrackReportPanel              matlab.ui.container.Panel
        PercentStraightsLabel         matlab.ui.control.Label
        DataPointsLabel               matlab.ui.control.Label
        TrackLengthLabel              matlab.ui.control.Label
        NumberofApexesLabel           matlab.ui.control.Label
        MaximumCornerRadiusLabel      matlab.ui.control.Label
        MinimumCornerRadiusLabel      matlab.ui.control.Label
        UITable                       matlab.ui.control.Table
        TrackAnalyzerPanel            matlab.ui.container.Panel
        drawApexes                    matlab.ui.control.CheckBox
        maxLabel                      matlab.ui.control.Label
        minLabel                      matlab.ui.control.Label
        ApexSizeEditField             matlab.ui.control.NumericEditField
        ApexSizeEditFieldLabel        matlab.ui.control.Label
        UpdateDataButton              matlab.ui.control.Button
        LoadResultFileButton          matlab.ui.control.Button
        UpperLimitEditField           matlab.ui.control.NumericEditField
        UpperLimitEditFieldLabel      matlab.ui.control.Label
        LowerLimitEditField           matlab.ui.control.NumericEditField
        LowerLimitEditFieldLabel      matlab.ui.control.Label
        Copyright2021BalticRacingbyEricDorniedenLabel  matlab.ui.control.Label
        UIAxes                        matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        CallingApp   % Main app object#
        file_run_1;
        result;
        pointHandle;
    end
    
    methods (Access = public)
        
        function changeColorScheme(app)
            PrimaryColor = app.CallingApp.PrimaryColor;
            OnPrimaryColor = app.CallingApp.OnPrimaryColor;   
            PrimaryVariantColor = app.CallingApp.PrimaryVariantColor;
            OnPrimaryVariantColor = app.CallingApp.OnPrimaryVariantColor;
            SecondaryColor = app.CallingApp.SecondaryColor;
            OnSecondaryColor = app.CallingApp.OnSecondaryColor;
            BackgroundColor = app.CallingApp.BackgroundColor;
            OnBackgroundColor = app.CallingApp.OnBackgroundColor;
            SurfaceColor = app.CallingApp.SurfaceColor;
            OnSurfaceColor = app.CallingApp.OnSurfaceColor;
            ErrorColor = app.CallingApp.ErrorColor;
            OnErrorColor = app.CallingApp.OnErrorColor;
            BorderType = app.CallingApp.BorderType;                 
            Host_Logo = app.CallingApp.Host_Logo;

            app.TrackAnalyzerUIFigure.Color = BackgroundColor;
            
            %% Panel
            app.TrackAnalyzerPanel.BackgroundColor = SurfaceColor;
            app.TrackAnalyzerPanel.ForegroundColor = OnSurfaceColor;
            app.TrackAnalyzerPanel.BorderType = BorderType;

            app.TrackReportPanel.BackgroundColor = SurfaceColor;
            app.TrackReportPanel.ForegroundColor = OnSurfaceColor;
            app.TrackReportPanel.BorderType = BorderType;

            %% Labels
            app.Copyright2021BalticRacingbyEricDorniedenLabel.FontColor = OnPrimaryColor;
            app.MinimumCornerRadiusLabel.FontColor = OnPrimaryColor;
            app.MaximumCornerRadiusLabel.FontColor = OnPrimaryColor;
            app.NumberofApexesLabel.FontColor = OnPrimaryColor;
            app.TrackLengthLabel.FontColor = OnPrimaryColor;
            app.DataPointsLabel.FontColor = OnPrimaryColor;
            app.PercentStraightsLabel.FontColor = OnPrimaryColor;
            app.minLabel.FontColor = OnPrimaryColor;
            app.maxLabel.FontColor = OnPrimaryColor;

            app.ShowonlyapexesCheckBox.FontColor = OnPrimaryColor;

            app.ScatterVariableDropDownLabel.FontColor = OnPrimaryColor;
            app.ScatterVariableDropDown.FontColor = OnPrimaryColor;
            app.ScatterVariableDropDown.BackgroundColor = PrimaryColor;

            app.ApexSizeEditFieldLabel.FontColor = OnPrimaryColor;
            app.ApexSizeEditField.FontColor = OnPrimaryColor;
            app.ApexSizeEditField.BackgroundColor = PrimaryColor;

            app.LowerLimitEditFieldLabel.FontColor = OnPrimaryColor;
            app.LowerLimitEditField.FontColor = OnPrimaryColor;
            app.LowerLimitEditField.BackgroundColor = PrimaryColor;

            app.UpperLimitEditFieldLabel.FontColor = OnPrimaryColor;
            app.UpperLimitEditField.FontColor = OnPrimaryColor;
            app.UpperLimitEditField.BackgroundColor = PrimaryColor;

            %% Buttons
            app.LoadResultFileButton.FontColor = OnPrimaryColor;
            app.LoadResultFileButton.BackgroundColor = PrimaryColor;

            app.UpdateDataButton.FontColor = OnPrimaryColor;
            app.UpdateDataButton.BackgroundColor = PrimaryColor;

            app.UpdateDataButton.FontColor = OnPrimaryColor;
            app.UpdateDataButton.BackgroundColor = PrimaryColor;

            app.HeightProfileButton.FontColor = OnPrimaryColor;
            app.HeightProfileButton.BackgroundColor = PrimaryColor;

            app.CornerPlotButton.FontColor = OnPrimaryColor;
            app.CornerPlotButton.BackgroundColor = PrimaryColor;

            app.TimemapButton.FontColor = OnPrimaryColor;
            app.TimemapButton.BackgroundColor = PrimaryColor;
            
            app.ExportTrackButton.FontColor = OnPrimaryColor;
            app.ExportTrackButton.BackgroundColor = PrimaryColor;

            %% UIaxes
            app.UIAxes.Title.Color = OnPrimaryColor;
            app.UIAxes.XColor = OnPrimaryColor;
            app.UIAxes.YColor = OnPrimaryColor;
            app.UIAxes.Color = SurfaceColor;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            % Store main app object
            app.CallingApp = mainapp;
                       
            changeColorScheme(app);
        end

        % Button pushed function: LoadResultFileButton
        function LoadResultFileButtonPushed(app, event)
            [app.file_run_1, ~] = uigetfile('*.mat');  %open a mat file
        
            app.result = load(app.file_run_1, '-mat');
            
%             app.UpdateDataButtonPushed(app, event);
        end

        % Button pushed function: UpdateDataButton
        function UpdateDataButtonPushed(app, event)
            % Clears the UIAxes so it can be recreated
            cla(app.UIAxes,'reset');
            
            % Get the dark / bright mode colors
            OnPrimaryColor = app.CallingApp.OnPrimaryColor;  
            SurfaceColor = app.CallingApp.SurfaceColor;

            % set the color scheme after the reset
            app.UIAxes.Title.Color = OnPrimaryColor;
            app.UIAxes.XColor = OnPrimaryColor;
            app.UIAxes.YColor = OnPrimaryColor;          

            % Checks if the track file is 4 or 5 columns and read it acordingly          
            [~, columns] = size(app.result.Track);
            
            if (columns == 4)
                x_Track = app.result.Track(:,1);           % [m] X-Koordinate der Strecke
                y_Track = app.result.Track(:,2);           % [m] Y-Koordinate der Strecke
                z_Track = [];                              % [m] Z-Koordinate der Strecke
                s = app.result.Track(:,3);                 % [m] Verlauf der Streckenlänge
                R_Track = app.result.Track(:,4);                 % [m] Kurvenradien
            else
                x_Track = app.result.Track(:,1);           % [m] X-Koordinate der Strecke
                y_Track = app.result.Track(:,2);           % [m] Y-Koordinate der Strecke
                z_Track = app.result.Track(:,3);           % [m] Z-Koordinate der Strecke
                s = app.result.Track(:,4);                 % [m] Verlauf der Streckenlänge
                R_Track = app.result.Track(:,5);                 % [m] Kurvenradien
            end   
            
            apex = (zeros(1,length(app.result.ApexIndexes)));
            
            % Creates an array of every apex on the track
            k = 1;
            for i = 1:length(R_Track)
                if ismember(i,app.result.ApexIndexes)
                    apex(k,1) = app.result.Track(i,1);
                    apex(k,2) = app.result.Track(i,2);
                    isApex(i) = 1;
                    k = k + 1;
                else
                    isApex(i) = 0;
                end
            end
            
            % Scatters the track with Radius
            switch app.ScatterVariableDropDown.Value
                case '1' % Radius
                    scatter(app.UIAxes, app.result.Track(1:end,1),app.result.Track(1:end,2),40,R_Track,'filled')
                    
                    % prints the minimum and the maximum value of the
                    % variable
                    app.minLabel.Text = ['min: ' num2str(min(R_Track))];
                    app.maxLabel.Text = ['max: ' num2str(max(R_Track))];
                case '2' % Time/Point
                    for i = 1:size(app.result.Track,1)-1
                        s_corr(i) = app.result.Track(i+1,4)-app.result.Track(i,4);
                        timeP(i) = s_corr(i)./app.result.vV(i);
                    end
                              
                    scatter(app.UIAxes, app.result.Track(1:end-1,1),app.result.Track(1:end-1,2),40,timeP,'filled')
                    
                    % prints the minimum and the maximum value of the
                    % variable
                    app.minLabel.Text = ['min: ' num2str(min(timeP))];
                    app.maxLabel.Text = ['max: ' num2str(max(timeP))];
                case '3' % Elevation
                    scatter(app.UIAxes, app.result.Track(1:end,1),app.result.Track(1:end,2),40,z_Track,'filled')
                    
                    % prints the minimum and the maximum value of the
                    % variable
                    app.minLabel.Text = ['min: ' num2str(min(z_Track))];
                    app.maxLabel.Text = ['max: ' num2str(max(z_Track))];
            end
            
%             annotation('textbox', [0.6, 0.8, 0.1, 0.1],...
%                 'String', {['minimum R = ' num2str(min(R_Track)) ' m'],...
%                 ['maximum R = ' num2str(max(R_Track)) ' m'],...             
%                 ['num of apexes = ' num2str(length(apex))]})  
            
            title(app.UIAxes,'Track Analyzer','FontSize',12,'Color',OnPrimaryColor)           
            xlabel(app.UIAxes,'X-Coordinate [m]','FontSize',10,'Color',OnPrimaryColor)       
            ylabel(app.UIAxes,'Y-Coordinate [m]','FontSize',10,'Color',OnPrimaryColor)
            colormap(app.UIAxes,jet)
            caxis(app.UIAxes, [app.LowerLimitEditField.Value app.UpperLimitEditField.Value]) % Sets the minimum and maximum value for the colorbar 
            c = colorbar(app.UIAxes);   
            c.Label.String = 'Corner Radius';
            c.Color = OnPrimaryColor;
            
            % Hold plot to scatter the apexes of the track
            hold(app.UIAxes);
            
            if app.drawApexes.Value % Only draw apexes if drawApexes checkbox is checked
                try % Try catch is needed because of tracks without apexes (Accel)
                    scatter(app.UIAxes,apex(1:end,1),apex(1:end,2),app.ApexSizeEditField.Value) % Draws points for every apex on the track
                catch
                end
            end
            
            if columns > 5
                x_inner = app.result.Track(:,6);
                y_inner = app.result.Track(:,7);
                
                x_outer = app.result.Track(:,8);
                y_outer = app.result.Track(:,9);
                
                hold(app.UIAxes,'on')
                plot(app.UIAxes,x_inner,y_inner,'b');
                plot(app.UIAxes,x_outer,y_outer,'b');
            end 
            
            %% Calculate percent straights 
            straights = 0;
            for i = 1:size(x_Track,1)
                if (abs(R_Track(i)) > 200)
                    straights = straights + 1;
                end
            end
            straights_percent = straights / i;
            
            
            %% Generate and write Track Report data
            app.MinimumCornerRadiusLabel.Text = ['Minimum Corner Radius: ' num2str(min(R_Track)) ' m'];
            app.MaximumCornerRadiusLabel.Text = ['Maximum Corner Radius: ' num2str(max(R_Track)) ' m'];
            app.NumberofApexesLabel.Text = ['Number of Apexes: ' num2str(length(apex))];
            app.TrackLengthLabel.Text = ['Track Length: ' num2str(s(end)) ' m'];
            app.DataPointsLabel.Text = ['Data Points: ' num2str(size(app.result.Track,1))];
            app.PercentStraightsLabel.Text = ['Percent Straights: ' num2str(straights_percent*100) ' %'];
            
            %% Creates Track Table
            if app.ShowonlyapexesCheckBox.Value     % Checks if the 'Show only apexes' box is ticked.            
                app.UITable.Data = [];              % Clear UITable data
                
                % Checks if an apex is found and adds it to the table, if
                % one is found
                for i = 1:length(x_Track)           
                    if isApex(i) == 1
                        app.UITable.Data = [app.UITable.Data; [x_Track(i), y_Track(i), z_Track(i), R_Track(i), s(i), isApex(i), app.result.vV(i)]];
                    end
                end
            else
                %% Create full track table data
                app.UITable.Data = [x_Track y_Track z_Track R_Track s isApex.' app.result.vV'];
            end

            app.UIAxes.Color = SurfaceColor;
        end

        % Button pushed function: HeightProfileButton
        function HeightProfileButtonPushed(app, event)
            if(size(app.result.Track,2) == 5)
                plot(app.result.Track(:,4), app.result.Track(:,3));
                xlabel('Track Length [m]');
                ylabel('Elevation [m]');
                title('Track Height Profile Plot')
            else
                errordlg('Track has no height profile!');
            end
        end

        % Button pushed function: CornerPlotButton
        function CornerPlotButtonPushed(app, event)
            if(size(app.result.Track,2) == 5)
                plot(app.result.Track(:,4), app.result.Track(:,5));
                xlabel('Track Length [m]');
                ylabel('Corner Radius [m]');
                ylim([app.LowerLimitEditField.Value app.UpperLimitEditField.Value]);
            else
                plot(app.result.Track(:,3), app.result.Track(:,4));
                xlabel('Track Length [m]');
                ylabel('Corner Radius [m]');
                ylim([app.LowerLimitEditField.Value app.UpperLimitEditField.Value]);
            end
        end

        % Button pushed function: TimemapButton
        function TimemapButtonPushed(app, event)
            if(size(app.result.Track,2) == 5)
                
                for i = 1:size(app.result.Track,1)-1
                    s_corr(i) = app.result.Track(i+1,4)-app.result.Track(i,4);
                end
                
                plot(app.result.Track(:,4), s_corr./app.result.vV(1:end));
                title('Time per Data Point');
                xlabel('Track Length [m]');
                ylabel('Distance/Velocity [1/sec]');
                
                yyaxis('right')
                hold on
                plot(app.result.Track(:,4), app.result.Track(:,5));
                ylabel('Corner Radius [m]');
                ylim([app.LowerLimitEditField.Value app.UpperLimitEditField.Value]);
            else
                for i = 1:size(app.result.Track,1)-1
                    s_corr = app.result.Track(i+1,3)-app.result.Track(i,3);
                end
                
                plot(app.result.Track(:,3), s_corr./app.result.vV(1:end));
                title('Time per Data Point');
                xlabel('Track Length [m]');
                ylabel('Distance/Velocity [1/sec]');
                
                yyaxis('right')
                hold on
                plot(app.result.Track(:,3), app.result.Track(:,4));
                ylabel('Corner Radius [m]');
                ylim([app.LowerLimitEditField.Value app.UpperLimitEditField.Value]);
            end
            
        end

        % Button pushed function: ExportTrackButton
        function ExportTrackButtonPushed(app, event)
            Track.Track(:,1) = app.UITable.Data(:,1);
            Track.Track(:,2) = app.UITable.Data(:,2);
            Track.Track(:,3) = app.UITable.Data(:,3);
            Track.Track(:,4) = app.UITable.Data(:,5);
            Track.Track(:,5) = app.UITable.Data(:,4);
        
            % Opens a save file dialog
            [filename, pathname] = uiputfile('*.mat','Save track as','Track.mat');
            
            newfilename = fullfile(pathname, filename);
            
            % Saves file with the given parameters
            save(newfilename, '-struct','Track');   
        end

        % Cell selection callback: UITable
        function UITableCellSelection(app, event)
            indices = event.Indices;                % indices of the selected point in the UITable
            
            x = app.UITable.Data(indices(1,1),1);   % x-Coordinate of selected point
            y = app.UITable.Data(indices(1,1),2);   % y-Coordinate of selected point
                        
            delete(app.pointHandle)                 % Deletes previous pointHandle
            
            hold(app.UIAxes,'on')
            app.pointHandle = plot(app.UIAxes, x, y, 'Marker', 'x', 'LineWidth',5,'MarkerSize',11);   % Creates pointHandle (marks selected point from UITable in the UIAxes)         
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.TrackAnalyzerUIFigure = uifigure('Visible', 'off');
            app.TrackAnalyzerUIFigure.Name = 'Track Analyzer';
            app.TrackAnalyzerUIFigure.Icon = 'Baltic.png';
            app.UIAxes = uiaxes(app.TrackAnalyzerUIFigure);
            title(app.UIAxes, 'Track Analyzer')
            xlabel(app.UIAxes, 'x-Coordinate')
            ylabel(app.UIAxes, 'y-Coordinate')
            zlabel(app.UIAxes, 'Z')
            app.Copyright2021BalticRacingbyEricDorniedenLabel = uilabel(app.TrackAnalyzerUIFigure);
            app.Copyright2021BalticRacingbyEricDorniedenLabel.Text = 'Copyright © 2021 Baltic Racing by Eric Dornieden';
            app.TrackAnalyzerPanel = uipanel(app.TrackAnalyzerUIFigure);
            app.TrackAnalyzerPanel.Title = 'Track Analyzer';
            app.LowerLimitEditFieldLabel = uilabel(app.TrackAnalyzerPanel);
            app.LowerLimitEditFieldLabel.Text = 'Lower Limit';
            app.LowerLimitEditField = uieditfield(app.TrackAnalyzerPanel, 'numeric');
            app.LowerLimitEditField.UpperLimitInclusive = 'off';
            app.UpperLimitEditFieldLabel = uilabel(app.TrackAnalyzerPanel);
            app.UpperLimitEditFieldLabel.Text = 'Upper Limit';
            app.UpperLimitEditField = uieditfield(app.TrackAnalyzerPanel, 'numeric');
            app.UpperLimitEditField.UpperLimitInclusive = 'off';
            app.UpperLimitEditField.Limits = [0 Inf];
            app.UpperLimitEditField.Value = 100;
            app.LoadResultFileButton = uibutton(app.TrackAnalyzerPanel, 'push');
            app.LoadResultFileButton.ButtonPushedFcn = createCallbackFcn(app, @LoadResultFileButtonPushed, true);
            app.LoadResultFileButton.Text = 'Load Result File';
            app.UpdateDataButton = uibutton(app.TrackAnalyzerPanel, 'push');
            app.UpdateDataButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateDataButtonPushed, true);
            app.UpdateDataButton.Text = 'Update Data';
            app.ApexSizeEditFieldLabel = uilabel(app.TrackAnalyzerPanel);
            app.ApexSizeEditFieldLabel.Text = 'ApexSize';
            app.ApexSizeEditField = uieditfield(app.TrackAnalyzerPanel, 'numeric');
            app.ApexSizeEditField.Value = 120;
            app.minLabel = uilabel(app.TrackAnalyzerPanel);
            app.minLabel.Text = 'min: ';
            app.maxLabel = uilabel(app.TrackAnalyzerPanel);
            app.maxLabel.Text = 'max: ';
            app.drawApexes = uicheckbox(app.TrackAnalyzerPanel);
            app.drawApexes.Text = '';
            app.drawApexes.Value = true;
            app.UITable = uitable(app.TrackAnalyzerUIFigure);
            app.UITable.ColumnName = {'x-Coordinate'; 'y-Coordinate'; 'z-Coordinate'; 'Radius'; 'Distance'; 'isApex'; 'Velocity'};
            app.UITable.RowName = {};
            app.UITable.ColumnEditable = [true true true true true false false];
            app.UITable.CellSelectionCallback = createCallbackFcn(app, @UITableCellSelection, true);
            app.TrackReportPanel = uipanel(app.TrackAnalyzerUIFigure);
            app.TrackReportPanel.Title = 'Track Report';
            app.MinimumCornerRadiusLabel = uilabel(app.TrackReportPanel);
            app.MinimumCornerRadiusLabel.Text = 'Minimum Corner Radius: ';
            app.MaximumCornerRadiusLabel = uilabel(app.TrackReportPanel);
            app.MaximumCornerRadiusLabel.Text = 'Maximum Corner Radius: ';
            app.NumberofApexesLabel = uilabel(app.TrackReportPanel);
            app.NumberofApexesLabel.Text = 'Number of Apexes:';
            app.TrackLengthLabel = uilabel(app.TrackReportPanel);
            app.TrackLengthLabel.Text = 'Track Length:';
            app.DataPointsLabel = uilabel(app.TrackReportPanel);
            app.DataPointsLabel.Text = 'Data Points:';
            app.PercentStraightsLabel = uilabel(app.TrackReportPanel);
            app.PercentStraightsLabel.Text = 'Percent Straights:';
            app.HeightProfileButton = uibutton(app.TrackAnalyzerUIFigure, 'push');
            app.HeightProfileButton.ButtonPushedFcn = createCallbackFcn(app, @HeightProfileButtonPushed, true);
            app.HeightProfileButton.Text = 'Height Profile';
            app.CornerPlotButton = uibutton(app.TrackAnalyzerUIFigure, 'push');
            app.CornerPlotButton.ButtonPushedFcn = createCallbackFcn(app, @CornerPlotButtonPushed, true);
            app.CornerPlotButton.Text = 'Corner Plot';
            app.TimemapButton = uibutton(app.TrackAnalyzerUIFigure, 'push');
            app.TimemapButton.ButtonPushedFcn = createCallbackFcn(app, @TimemapButtonPushed, true);
            app.TimemapButton.Text = 'Time map';
            app.ScatterVariableDropDownLabel = uilabel(app.TrackAnalyzerUIFigure);
            app.ScatterVariableDropDownLabel.Text = 'Scatter Variable';
            app.ScatterVariableDropDown = uidropdown(app.TrackAnalyzerUIFigure);
            app.ScatterVariableDropDown.Items = {'Radius', 'Time/Point', 'Elevation'};
            app.ScatterVariableDropDown.ItemsData = {'1', '2', '3'};
            app.ScatterVariableDropDown.Value = '1';
            app.ExportTrackButton = uibutton(app.TrackAnalyzerUIFigure, 'push');
            app.ExportTrackButton.ButtonPushedFcn = createCallbackFcn(app, @ExportTrackButtonPushed, true);
            app.ExportTrackButton.Text = 'Export Track';
            app.ShowonlyapexesCheckBox = uicheckbox(app.TrackAnalyzerUIFigure);
            app.ShowonlyapexesCheckBox.Text = 'Show only apexes';
            % Show the figure after all components are created
            app.TrackAnalyzerUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = TrackAnalyzer(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.TrackAnalyzerUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.TrackAnalyzerUIFigure)
        end
    end
end