classdef TrackManager < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        TrackManagerUIFigure  matlab.ui.Figure
        TrackManagerPanel     matlab.ui.container.Panel
        RowEditField          matlab.ui.control.NumericEditField
        RowEditFieldLabel     matlab.ui.control.Label
        SaveButton            matlab.ui.control.Button
        Copyright2021BalticRacingbyEricDorniedenLabel  matlab.ui.control.Label
        RemoveTrackButton     matlab.ui.control.Button
        AddTrackButton        matlab.ui.control.Button
        UITable               matlab.ui.control.Table
    end

    
    properties (Access = private)
        CallingApp;   % Main app object
        TracksData; % Tracks
        TrackFile;
    end
    
    methods (Access = private)
        
        function saveData(app) 

            %Data = table2array(app.UITable.Data);
            Data = app.UITable.Data;

            save('Tracks','Data');  
        end
    end
    
    methods (Access = public)
        
        function changeColorScheme(app)
            PrimaryColor = app.CallingApp.PrimaryColor;
            OnPrimaryColor = app.CallingApp.OnPrimaryColor;   
            PrimaryVariantColor = app.CallingApp.PrimaryVariantColor;
            OnPrimaryVariantColor = app.CallingApp.OnPrimaryVariantColor;
            SecondaryColor = app.CallingApp.SecondaryColor;
            OnSecondaryColor = app.CallingApp.OnSecondaryColor;
            BackgroundColor = app.CallingApp.BackgroundColor;
            OnBackgroundColor = app.CallingApp.OnBackgroundColor;
            SurfaceColor = app.CallingApp.SurfaceColor;
            OnSurfaceColor = app.CallingApp.OnSurfaceColor;
            ErrorColor = app.CallingApp.ErrorColor;
            OnErrorColor = app.CallingApp.OnErrorColor;
            BorderType = app.CallingApp.BorderType;                 
            Host_Logo = app.CallingApp.Host_Logo;
            
            app.TrackManagerUIFigure.Color = BackgroundColor;
            
            app.TrackManagerPanel.BackgroundColor = SurfaceColor;
            app.TrackManagerPanel.ForegroundColor = OnSurfaceColor;
            app.TrackManagerPanel.BorderType = BorderType;          
            
            app.AddTrackButton.FontColor = OnPrimaryColor;
            app.AddTrackButton.BackgroundColor = PrimaryVariantColor;
            
            app.SaveButton.FontColor = OnPrimaryColor;
            app.SaveButton.BackgroundColor = PrimaryVariantColor;
            
            app.RemoveTrackButton.FontColor = OnPrimaryColor;
            app.RemoveTrackButton.BackgroundColor = PrimaryVariantColor;
            
            app.RowEditFieldLabel.FontColor = OnPrimaryColor;
            app.RowEditField.BackgroundColor = SurfaceColor;
            app.RowEditField.FontColor = OnSurfaceColor;
            
            
            app.Copyright2021BalticRacingbyEricDorniedenLabel.FontColor = OnBackgroundColor;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            % Store main app object
            app.CallingApp = mainapp;    
            
            %try
                load('Tracks.mat','Data')
                
                app.UITable.Data = Data;
            %catch ME
            
            changeColorScheme(app);
                
           % end
        end

        % Button pushed function: AddTrackButton
        function AddTrackButtonPushed(app, event)
            [app.TrackFile, ~] = uigetfile('*.mat');  %open a mat file
        
            FileName = string(app.TrackFile);
            TrackName = strrep(FileName,'.mat','');
            
            Track = load(app.TrackFile, '-mat');
            
            TrackLength = Track.Track(end,4);
            
            %app.UITable.Data = [app.UITable.Data [TrackName,Track(end,4),size(Track,1),TrackName]];
            app.UITable.Data = [app.UITable.Data; [TrackName,TrackLength,size(Track.Track,1),1,1,0,FileName]];
            
            app.TracksData = app.UITable.Data;
        end

        % Close request function: TrackManagerUIFigure
        function TrackManagerUIFigureCloseRequest(app, event)
             % Saves all entered data
            saveData(app);
            
            % Disable Aerodynamik Options button while dialog is open
            app.CallingApp.TrackManagerButton.Enable = 'on';
            
            delete(app)   
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            saveData(app);
        end

        % Button pushed function: RemoveTrackButton
        function RemoveTrackButtonPushed(app, event)
            index = app.RowEditField.Value;
            
            Data = app.UITable.Data;
            Data(index,:) = [];
            
            for i = index+1:size(Data,1)
                Data(i-1,:) = Data(i,:);
            end
            
            app.UITable.Data = Data;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.TrackManagerUIFigure = uifigure('Visible', 'off');
            app.TrackManagerUIFigure.Name = 'Track Manager';
            app.TrackManagerUIFigure.Icon = 'Baltic.png';
            app.TrackManagerUIFigure.CloseRequestFcn = createCallbackFcn(app, @TrackManagerUIFigureCloseRequest, true);
            app.TrackManagerPanel = uipanel(app.TrackManagerUIFigure);
            app.TrackManagerPanel.Title = 'Track Manager';
            app.UITable = uitable(app.TrackManagerPanel);
            app.UITable.ColumnName = {'Track Name'; 'Length [m]'; 'Data Points'; 'isEnduranceTrack'; 'EnduranceLaps'; 'Starting Speed [m/s]'; 'Filename'};
            app.UITable.RowName = {};
            app.UITable.ColumnEditable = [true false false true true];
            app.AddTrackButton = uibutton(app.TrackManagerPanel, 'push');
            app.AddTrackButton.ButtonPushedFcn = createCallbackFcn(app, @AddTrackButtonPushed, true);
            app.AddTrackButton.Text = 'Add Track';
            app.RemoveTrackButton = uibutton(app.TrackManagerPanel, 'push');
            app.RemoveTrackButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveTrackButtonPushed, true);
            app.RemoveTrackButton.Text = 'Remove Track';
            app.Copyright2021BalticRacingbyEricDorniedenLabel = uilabel(app.TrackManagerPanel);
            app.Copyright2021BalticRacingbyEricDorniedenLabel.Text = 'Copyright © 2021 Baltic Racing by Eric Dornieden';
            app.SaveButton = uibutton(app.TrackManagerPanel, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.RowEditFieldLabel = uilabel(app.TrackManagerPanel);
            app.RowEditFieldLabel.Text = 'Row';
            app.RowEditField = uieditfield(app.TrackManagerPanel, 'numeric');
            % Show the figure after all components are created
            app.TrackManagerUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = TrackManager(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.TrackManagerUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.TrackManagerUIFigure)
        end
    end
end