classdef laptime_Simulation_GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        LaptimeSimulationUIFigure    matlab.ui.Figure
        TabGroup                     matlab.ui.container.TabGroup
        HowtoTab                     matlab.ui.container.Tab
        HowToLaptimeSimulationPanel  matlab.ui.container.Panel
        EditthecarsetupintheCarSetuptab2SaveCarSetupLabel  matlab.ui.control.Label
        DailyUpdateFeatureRequestsButton  matlab.ui.control.Button
        Copyright2021BalticRacingbyEricDorniedenLabel  matlab.ui.control.Label
        Image                        matlab.ui.control.Image
        Image2                       matlab.ui.control.Image
        ContactmeButton              matlab.ui.control.Button
        PatchNotesPanel              matlab.ui.container.Panel
        TextArea                     matlab.ui.control.TextArea
        CarSetupTab                  matlab.ui.container.Tab
        Copyright2021BalticRacingbyEricDorniedenLabel_2  matlab.ui.control.Label
        Image2_2                     matlab.ui.control.Image
        EditSetupPanel               matlab.ui.container.Panel
        CarParametersPanel           matlab.ui.container.Panel
        SuspensionButton             matlab.ui.control.Button
        ChassisButton                matlab.ui.control.Button
        DrivetrainButton             matlab.ui.control.Button
        AerodynamikButton            matlab.ui.control.Button
        AccumulatorButton            matlab.ui.control.Button
        EnviromentPanel              matlab.ui.container.Panel
        EnvironmentButton            matlab.ui.control.Button
        LoadButton                   matlab.ui.control.Button
        SaveButton                   matlab.ui.control.Button
        Image_2                      matlab.ui.control.Image
        SimulationSetupTab           matlab.ui.container.Tab
        UIAxes2                      matlab.ui.control.UIAxes
        StartSimulationButton        matlab.ui.control.Button
        SensitivityAnalysisPanel     matlab.ui.container.Panel
        DropDownLabel                matlab.ui.control.Label
        DropDown                     matlab.ui.control.DropDown
        MinEditFieldLabel            matlab.ui.control.Label
        MinEditField                 matlab.ui.control.NumericEditField
        StepsizeEditFieldLabel       matlab.ui.control.Label
        StepsizeEditField            matlab.ui.control.NumericEditField
        activatedCheckBox            matlab.ui.control.CheckBox
        NumStepsEditFieldLabel       matlab.ui.control.Label
        NumStepsEditField            matlab.ui.control.NumericEditField
        ScenarioPanel                matlab.ui.container.Panel
        TrackDropDownLabel           matlab.ui.control.Label
        TrackDropDown                matlab.ui.control.DropDown
        DisciplineDropDownLabel      matlab.ui.control.Label
        DisciplineDropDown           matlab.ui.control.DropDown
        LengthLabel                  matlab.ui.control.Label
        SimulationStatusPanel        matlab.ui.container.Panel
        SimulationTextArea           matlab.ui.control.TextArea
        Copyright2021BalticRacingbyEricDorniedenLabel_3  matlab.ui.control.Label
        SimulationResultsTab         matlab.ui.container.Tab
        Load1RunButton               matlab.ui.control.Button
        Results1RunPanel             matlab.ui.container.Panel
        t_lapLabel                   matlab.ui.control.Label
        EndurancetimeLabel           matlab.ui.control.Label
        LapDistanceLabel             matlab.ui.control.Label
        EnergyconsumptionWOrecuperationLabel  matlab.ui.control.Label
        WrecuperationLabel           matlab.ui.control.Label
        ComputationtimeLabel         matlab.ui.control.Label
        PowerLimitLabel              matlab.ui.control.Label
        Load2RunButton               matlab.ui.control.Button
        DrawCustomPlotPanel          matlab.ui.container.Panel
        UIAxes                       matlab.ui.control.UIAxes
        xaxisDropDownLabel           matlab.ui.control.Label
        xaxisDropDown                matlab.ui.control.DropDown
        yaxisDropDownLabel           matlab.ui.control.Label
        yaxisDropDown                matlab.ui.control.DropDown
        y_2axisDropDownLabel         matlab.ui.control.Label
        y_2axisDropDown              matlab.ui.control.DropDown
        zaxisDropDownLabel           matlab.ui.control.Label
        zaxisDropDown                matlab.ui.control.DropDown
        DrawPlotButton               matlab.ui.control.Button
        DrawPlotinnewWindowButton    matlab.ui.control.Button
        Results2RunPanel             matlab.ui.container.Panel
        t_lapLabel_2                 matlab.ui.control.Label
        EndurancetimeLabel_2         matlab.ui.control.Label
        LapDistanceLabel_2           matlab.ui.control.Label
        EnergyconsumptionWOrecuperationLabel_2  matlab.ui.control.Label
        WrecuperationLabel_2         matlab.ui.control.Label
        ComputationtimeLabel_2       matlab.ui.control.Label
        PowerLimitLabel_2            matlab.ui.control.Label
        DeltaSecondRunFirstRunPanel  matlab.ui.container.Panel
        t_lapLabel_3                 matlab.ui.control.Label
        EndurancetimeLabel_3         matlab.ui.control.Label
        LapDistanceLabel_3           matlab.ui.control.Label
        EnergyconsumptionWOrecuperationLabel_3  matlab.ui.control.Label
        WrecuperationLabel_3         matlab.ui.control.Label
        SensitivityAnalysisPanel_2   matlab.ui.container.Panel
        xaxisDropDown_2Label         matlab.ui.control.Label
        xaxisDropDown_2              matlab.ui.control.DropDown
        yaxisDropDown_2Label         matlab.ui.control.Label
        yaxisDropDown_2              matlab.ui.control.DropDown
        DrawSensitivityPlotButton    matlab.ui.control.Button
        DotsCheckBox                 matlab.ui.control.CheckBox
        PlotResultsPanel             matlab.ui.container.Panel
        SelectPlotDropDownLabel      matlab.ui.control.Label
        SelectPlotDropDown           matlab.ui.control.DropDown
        DrawPlot1RunButton           matlab.ui.control.Button
        DrawPlot2RunButton           matlab.ui.control.Button
        RunnumberEditFieldLabel      matlab.ui.control.Label
        RunnumberEditField           matlab.ui.control.NumericEditField
        CompareRunsPanel             matlab.ui.container.Panel
        CompareRunPlotsButton        matlab.ui.control.Button
        SelectPlotDropDown_3Label    matlab.ui.control.Label
        SelectPlotDropDown_2         matlab.ui.control.DropDown
        Copyright2021BalticRacingbyEricDorniedenLabel_4  matlab.ui.control.Label
    end

    
    properties (Access = public)
        
        %% Chassis Parameters
        m_ges = 280; % Description
        h_cog = 200; % Description
        x_cog = 1700;
        x_va = 470;
        m_driver = 0;
        h_cog_driver = 0;
        x_cog_driver = 0;
        m_ballast = 0;
        h_cog_ballast = 0;
        x_cog_ballast = 0;
        thetaV_X = 29.3828;  % [kg*m²] Trägheitsmoment Fahrzeug um X-Achse x
        thetaV_Y = 104.837;  % [kg*m²] Trägheitsmoment Fahrzeug um Y-Achse x
        thetaV_Z = 119.2005; % [kg*m²] Trägheitsmoment Fahrzeug um Z-Achse x
        
        %% Suspension Parameters
        wheelbase = 1525;
        track = 1500;
        J_Tire = 0.2;
        p_Tire = 65000;
        LMUX = 0.65;
        LMUY = 0.65;
        k_R = 0.01;
        FB = 7000;
        camber = 0;
        m_ph = 50;
        
        %% Drivetrain Parameters
        ptype = 1; % powertrain type 1 = Electric; 0 = Combustion
        %Electric
        p_max = 80;
        n_maxE = 6000;
        drivetrain_eff = 0.95;
        invertor_eff = 0.98;
        z_chaindriveE = 66;        
        z_sprocketE = 17;
        trq_multiplier = 1;
        engine_paramE = [0 140; 1000 140; 2000 138; 3000 136; 4000 135; 5000 127; 6000 115];
        num_motors = 2;
        i_PE = 1;
        %Combustion
        n_maxC = 10500;
        engine_paramC = [2500 47.36; 3000 49.55; 3500 52.34; 4000 52.46; 5000 52.46; 5500 52.82; 6000 52.08; 6500 52.04; 7000 52.24; 7500 52.47; 8000 52.82; 8500 53.6; 9000 52.15; 9500 49.67; 10000 48.15; 10500 56.82; 11000 43.75; 11272 39.69; 11500 0];
        z_chaindriveC = 37;
        z_sprocketC = 11;
        i_PC = 1.848;
        i_param = [1.857; 1.565; 1.35; 1.238; 1.136];
        n_shift = 10500;
        t_shift = 0.035;


        %% Aerodynamic Parameters
        c_w = 1.463;
        c_l = 3;
        A = 0.5428548*2;
        downforce_data = [0 0; 30 162; 50 456; 60 642];
        downforce_multiplier = 1;
        aero_pv = 0.5;
        DRS = false;
        c_d_DRS = 1.2;
        
        %% Accumulator Parameters       
        V_i = 550;
        Energy_i = 7.5;
        nZellen_Parallel = 5; 
        nZellen_Reihe = 131;
        capacity_cell = 10.8;
        
        %% Environment Parameters
        t_L = 30;
        p_L = 101300;
        R_L = 287;
        g = 9.81;      
        
        %% Result Parameters Run 1
        file_run_1;
        result;          % Contains all values from the selected result file
        x_Track;         % [m] X-Koordinate der Strecke
        y_Track;         % [m] Y-Koordinate der Strecke
        z_Track;         % [m] Z-Koordinate der Strecke
        s;               % [m] Verlauf der Streckenlänge
        R; 
        t_ges;
        ni;
        
        %% Result Parameters Run 2
        file_run_2;
        result_2;          % Contains all values from the selected result file
        x_Track_2;         % [m] X-Koordinate der Strecke
        y_Track_2;         % [m] Y-Koordinate der Strecke
        z_Track_2;         % [m] Z-Koordinate der Strecke
        s_2;               % [m] Verlauf der Streckenlänge
        R_2; 
        t_ges_2;
        ni_2;        
    end    
    
    properties (Access = private)
        ChassisApp      % Chassis options app
        SuspensionApp   % Suspension options app
        DrivetrainApp   % Drivetrain options app
        EnvironmentApp  % Environment options app
        AerodynamikApp  % Aerodynamic options app
        AccumulatorApp   % Acumulator options app
    end
       
    methods (Access = private)
        
        function compareRuns(app)
            %% Set Result Labels
            
            % Lap time Delta
            delta_t_lap = app.result_2.t(end)-app.result.t(end);
            
            % Sets FontColor to red if lap is slower
            if(delta_t_lap > 0)
                app.t_lapLabel_3.FontColor = 'red';
            else
                app.t_lapLabel_3.FontColor = 'green';
            end
            % Sets text label
            app.t_lapLabel_3.Text = "Lap time = " + delta_t_lap + " s";
                        
            % Endurance time Label
            delta_t_overall = app.t_ges_2-app.t_ges;
            % Sets FontColor to red if time is slower
            if(delta_t_overall > 0)
                app.EndurancetimeLabel_3.FontColor = 'red';
            else
                app.EndurancetimeLabel_3.FontColor = 'green';
            end
            app.EndurancetimeLabel_3.Text = "Endurance time = " + delta_t_overall + " s";
            
            % Lap Distance Label
            delta_distance = app.s_2(end)-app.s(end);
            % Sets FontColor to red if lap distance is not equal zero
            if(delta_distance ~= 0)
                app.LapDistanceLabel_3.FontColor = 'red';
            else
                app.LapDistanceLabel_3.FontColor = 'green';
            end
            app.LapDistanceLabel_3.Text = "Lap Distance = " + delta_distance + " m";
            
            % E Accumulator w/o recu
            delta_E_Akku = (app.result_2.E_Akku(end) * (22000/app.s_2(end)))-(app.result.E_Akku(end) * (22000/app.s(end)));
            % Sets FontColor to red if Energy consumption is higher
            if(delta_E_Akku > 0)
                app.EnergyconsumptionWOrecuperationLabel_3.FontColor = 'red';
            else
                app.EnergyconsumptionWOrecuperationLabel_3.FontColor = 'green';
            end
            app.EnergyconsumptionWOrecuperationLabel_3.Text = "Energy consumption W/O recuperation = " + delta_E_Akku + " kWh";
            
            % E Accumulator w recu
            delta_E_Akku_ges = (app.result_2.E_ges(end) * (22000/app.s_2(end)))-(app.result.E_ges(end) * (22000/app.s(end)));
            % Sets FontColor to red if Energy consumption is higher
            if(delta_E_Akku_ges > 0)
                app.WrecuperationLabel_3.FontColor = 'red';
            else
                app.WrecuperationLabel_3.FontColor = 'green';
            end
            app.WrecuperationLabel_3.Text = "W/O recuperator = " + delta_E_Akku_ges + " kWh";
        end
        
        function renderSelectedTrack(app)
            
            switch str2num(app.TrackDropDown.Value)
                case 1
                    load('EnduranceTrack.mat','Track');     % Streckendaten Endurance Track
                    x_Track_preview = Track(:,1);           % [m] X-Koordinate der Strecke
                    y_Track_preview = Track(:,2);           % [m] Y-Koordinate der Strecke
                    s_preview = Track(:,4);                 % [m] Verlauf der Streckenlänge
                    TrackName = "FSG Endurance Track";
                    
                    % Enables the selection dropdown for the discipline
                    app.DisciplineDropDown.Enable = true;
                case 2
                    load('AutoXTrack.mat','Track');         % Streckendaten AutoX Track
                    x_Track_preview = Track(:,1);           % [m] X-Koordinate der Strecke
                    y_Track_preview = Track(:,2);           % [m] Y-Koordinate der Strecke
                    s_preview = Track(:,4);                 % [m] Verlauf der Streckenlänge
                    TrackName = "FSG AutoX Track";
                    
                    % Enables the selection dropdown for the discipline
                    app.DisciplineDropDown.Enable = true;
                case 3
                    load('Testtrack_Data.mat','Track');     % Streckendaten Test Track    
                    x_Track_preview = Track(:,1);           % [m] X-Koordinate der Strecke
                    y_Track_preview = Track(:,2);           % [m] Y-Koordinate der Strecke
                    s_preview = Track(:,3);                 % [m] Verlauf der Streckenlänge
                    TrackName = "Test Track";   
                    
                    % Enables the selection dropdown for the discipline
                    app.DisciplineDropDown.Enable = true;
                case 4
                    x_Track_preview(1) = 0;
                    y_Track_preview(1) = 0;
                    s_preview(1) = 0;
                    
                    TrackName = "Accel Track";
                    
                    for i = 2:301
                        y_Track_preview(i) = 0;
                        s_preview(i) = s_preview(i-1) + 0.25;
                        x_Track_preview(i) = s_preview(i);
                    end
                    
                    app.DisciplineDropDown.Enable = false;
            end


            app.UIAxes2.reset;
            
            for z = 1:24
                [~,Index50(z)] = min(abs(z*50-s_preview)); 
            end    
            plot(app.UIAxes2,x_Track_preview,y_Track_preview,'k')
            title(app.UIAxes2,TrackName,'FontSize',12);
            app.UIAxes2.LineWidth = 1.5;
           
            
            app.LengthLabel.Text = "Track Length: " + max(s_preview) + " m";

            
%             hold on
%             scatter(app.UIAxes2,x_Track_preview(1),y_Track_preview(1),10,'r','filled')
%             text(app.UIAxes2,x_Track_preview(1),y_Track_preview(1),' Start/Ziel')
%             title(app.UIAxes2,'Endurance-Strecke Formula Student Germany','FontSize',12) 
%             for z = 1:24
%                scatter(app.UIAxes2,x_Track_preview(Index50(z)),y_Track_preview(Index50(z)),20,'b','filled')
%                hold on
%                text(app.UIAxes2,x_Track_preview(Index50(z)),y_Track_preview(Index50(z)),['  ' num2str(z*50) 'm'])
%             end
%             annotation('textbox',[0.6, 0.8, 0.25, 0.05],'String',...
%                 ['Track Length: ' num2str(max(s_preview)) 'm'],'FitBoxToText','on')
%             hold off
%             axis equal
%             box on
        end            
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            addpath('.\Simulation')
            
            % parameters for sensitivity analysis
            sensitvityData = {'A','FB','LMUX','LMUY','aero_pv','c_l','c_w','camber','downforce_multiplier','drivetrain_eff','m_balast','m_driver','m_ges','m_ph','n_max','num_motors','p_Tire','p_max','t_L','thetaV_X','thetaV_Y','thetaV_Z','track','trq_multiplier','wheelbase','h_cog','x_cog','x_cog_balast','x_cog_driver','x_va','z_chaindrive','z_sprocket','i_g'};
            
            app.DropDown.Items = sensitvityData;
            app.DropDown.ItemsData = [1 2 3 4 5 6 7 8 9 10 11 12 13 14 15 16 17 18 19 20 21 22 23 24 25 26 27 28 29 30 31 32 33];
            
            app.xaxisDropDown_2.Items = ['t_tot' sensitvityData];
            app.xaxisDropDown_2.ItemsData = [1 2 3 4 5 6 7 8 9 10 11 12 13 14 15 16 17 18 19 20 21 22 23 24 25 26 27 28 29 30 31 32 33 34];
            app.yaxisDropDown_2.Items = ['t_tot' sensitvityData];
            app.yaxisDropDown_2.ItemsData = [1 2 3 4 5 6 7 8 9 10 11 12 13 14 15 16 17 18 19 20 21 22 23 24 25 26 27 28 29 30 31 32 33 34];
            
            plotNames = ["Acceleration g","Accelerations m/s","Accelerations Plot","Accumulator Voltage","Aero Tire Load",...
                "Axle Load","Brake Map","Cell Current","Conti Plot","Draw Track","DRS status map","Dynamic Tire Radius","g-g-V Plot",...
                "Lateral Acceleration Track","Lateral Forces Front","Lateral Forces Rear","Longitudinal Acceleration Track",...
                "Motor Efficency","P_el","P_tractive","Power Map (P_el)","Power Map (P_M)",'RPM',"RPM Map","Slip","Tire Load Front",...
                "Tire Load Rear","Torque","Torque Map","Track Analyzer","Traction Limit","Tractive Power Rear","Used Energy",...
                "Velocity km/h","Velocity m/s","Velocity Map","Virtual Current Cellpack"];
            
            app.SelectPlotDropDown.Items = plotNames;
            
            app.SelectPlotDropDown.ItemsData = [27 22 28 30 20 8 5 1 12 15 33 9 17 25 10 11 26 21 24 31 23 3 4 35 2 6 7 37 36 34 14 13 16 19 18 32 29];
            
            % Disable Options before data is loaded
            app.DrawCustomPlotPanel.Visible = 'off';
            app.Load2RunButton.Enable = 'off';
            app.DrawPlot1RunButton.Enable = 'off';
            app.DrawPlot2RunButton.Enable = 'off';
            app.CompareRunPlotsButton.Enable = 'off';
            app.DrawSensitivityPlotButton.Enable = 'off';
            
            % Renders the selected track in the Simulation Setup
            renderSelectedTrack(app);          
        end

        % Button pushed function: SuspensionButton
        function SuspensionButtonPushed(app, event)
            app.SuspensionButton.Enable = 'off';
            
            app.SuspensionApp = Suspension(app);
        end

        % Button pushed function: ChassisButton
        function ChassisButtonPushed(app, event)
            % Disable Plot Options button while dialog is open
            app.ChassisButton.Enable = 'off';
            
            % starts the chassis options app
            app.ChassisApp = Chassis(app);
        end

        % Button pushed function: DrivetrainButton
        function DrivetrainButtonPushed(app, event)
            app.DrivetrainButton.Enable = 'off';
            
            app.DrivetrainApp = Drivetrain(app);
        end

        % Button pushed function: EnvironmentButton
        function EnvironmentButtonPushed(app, event)
            app.EnvironmentButton.Enable = 'off';
            
            app.EnvironmentApp = Environment(app);
        end

        % Button pushed function: AerodynamikButton
        function AerodynamikButtonPushed(app, event)
            app.AerodynamikButton.Enable = 'off';
            
            app.AerodynamikApp = Aerodynamik(app);
        end

        % Button pushed function: AccumulatorButton
        function AccumulatorButtonPushed(app, event)
            app.AccumulatorButton.Enable = 'off';
            
            app.AccumulatorApp = Accumulator(app);
        end

        % Close request function: LaptimeSimulationUIFigure
        function LaptimeSimulationUIFigureCloseRequest(app, event)
            % deletes app           
            delete(app.ChassisApp);
            delete(app.AccumulatorApp);
            delete(app.AerodynamikApp);
            delete(app.EnvironmentApp);
            delete(app.DrivetrainApp);
            delete(app.SuspensionApp);
            delete(app);
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            try
                % Saves all data to an .mat file
                %% Chassis Parameters
                setup.m_ges = app.m_ges;
                setup.h_cog = app.h_cog;
                setup.x_cog = app.x_cog;
                setup.x_va = app.x_va;
                setup.m_driver = app.m_driver;
                setup.h_cog_driver = app.h_cog_driver;
                setup.x_cog_driver = app.x_cog_driver;
                setup.m_ballast = app.m_ballast;
                setup.h_cog_ballast = app.h_cog_ballast;
                setup.x_cog_ballast = app.x_cog_ballast;
                setup.thetaV_X = app.thetaV_X;
                setup.thetaV_Y = app.thetaV_Y;
                setup.thetaV_Z = app.thetaV_Z;      
                
                %% Suspension Parameters
                setup.wheelbase = app.wheelbase;
                setup.track = app.track;
                setup.J_Tire = app.J_Tire;
                setup.p_Tire = app.p_Tire;
                setup.LMUX = app.LMUX;
                setup.LMUY = app.LMUY;
                setup.k_R = app.k_R;
                setup.FB = app.FB;
                setup.camber = app.camber;
                setup.m_ph = app.m_ph;
                
                %% Drivetrain Parameters
                setup.p_max = app.p_max;
                setup.drivetrain_eff = app.drivetrain_eff;
                setup.invertor_eff = app.invertor_eff; 
                setup.trq_multiplier = app.trq_multiplier;
                setup.num_motors = app.num_motors;
                setup.t_shift = app.t_shift;
                setup.n_shift = app.n_shift;
                setup.i_param = app.i_param;
                
                if app.ptype
                    setup.z_chaindrive = app.z_chaindriveE;
                    setup.z_sprocket = app.z_sprocketE;
                    setup.n_max = app.n_maxE;
                    setup.engine_param = app.engine_paramE;
                    setup.i_P = app.i_PE;
                    setup.ptype = 1; 
                else
                    setup.z_chaindrive = app.z_chaindriveC;
                    setup.z_sprocket = app.z_sprocketC;
                    setup.n_max = app.n_maxC;
                    setup.engine_param = app.engine_paramC;
                    setup.i_P = app.i_PC;
                    setup.ptype = 0;
                end
                
                %% Aerodynamic Parameters
                setup.c_w = app.c_w;
                setup.c_l = app.c_l;
                setup.A = app.A;
                setup.downforce_data = app.downforce_data;
                setup.downforce_multiplier = app.downforce_multiplier;
                setup.aero_pv = app.aero_pv;
                setup.DRS = app.DRS;
                setup.c_d_DRS = app.c_d_DRS;
                
                %% Accumulator Parameters
                setup.V_i = app.V_i;
                setup.Energy_i = app.Energy_i;
                setup.nZellen_Parallel = app.nZellen_Parallel;
                setup.nZellen_Reihe = app.nZellen_Reihe;
                setup.capacity_cell = app.capacity_cell;             
        
                %% Environment Parameters
                setup.t_L = app.t_L;
                setup.p_L = app.p_L;
                setup.R_L = app.R_L;
                setup.g = app.g;
                 
                % Opens a save file dialog
                [filename, pathname] = uiputfile('*.mat','Save Car Variables As','CarSetup.mat');
                
                newfilename = fullfile(pathname, filename);
                
                % Saves file with the given parameters
                save(newfilename, '-struct','setup');
                %save("CarData.mat", '-struct','var');
                
                app.Lamp.Color = 'r';
            catch
            end
        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            try 
                [file,~] = uigetfile('*.mat');  %open a mat file
                
                setup = load(file, '-mat');     
                
               %% Chassis Parameters
                app.m_ges = setup.m_ges;
                app.h_cog = setup.h_cog;
                app.x_cog = setup.x_cog;
                app.x_va = setup.x_va;
                app.m_driver = setup.m_driver;
                app.h_cog_driver = setup.h_cog_driver;
                app.x_cog_driver = setup.x_cog_driver;
                app.m_ballast = setup.m_ballast;
                app.h_cog_ballast = setup.h_cog_ballast;
                app.x_cog_ballast = setup.x_cog_ballast;
                app.thetaV_X = setup.thetaV_X;
                app.thetaV_Y = setup.thetaV_Y;
                app.thetaV_Z = setup.thetaV_Z;      
                
                %% Suspension Parameters
                app.wheelbase = setup.wheelbase;
                app.track = setup.track;
                app.J_Tire = setup.J_Tire;
                app.p_Tire = setup.p_Tire;
                app.LMUX = setup.LMUX;
                app.LMUY = setup.LMUY;
                app.k_R = setup.k_R;
                app.FB = setup.FB;
                app.camber = setup.camber;
                app.m_ph = setup.m_ph;
                
                %% Drivetrain Parameters
                app.p_max = setup.p_max;
                
                app.drivetrain_eff = setup.drivetrain_eff;
                app.invertor_eff = setup.invertor_eff;
                app.trq_multiplier = setup.trq_multiplier;
                app.num_motors = setup.num_motors;
                app.t_shift = setup.t_shift;
                app.n_shift = setup.n_shift;
                app.i_param = setup.i_param;
               
                if app.ptype
                    app.n_maxE = setup.n_max;
                    app.engine_paramE = setup.engine_param;
                    app.z_chaindriveE = setup.z_chaindrive;
                    app.z_sprocketE = setup.z_sprocket;
                    app.i_PE = setup.i_P;
                else
                    app.n_maxC = setup.n_max;
                    app.engine_paramC = setup.engine_param;
                    app.z_chaindriveC = setup.z_chaindrive;
                    app.z_sprocketC = setup.z_sprocket;
                    app.i_PC = setup.i_P;
                end
       
        
                %% Aerodynamic Parameters
                app.c_w = setup.c_w;
                app.c_l = setup.c_l;
                app.A = setup.A;
                app.downforce_data = setup.downforce_data;
                app.downforce_multiplier = setup.downforce_multiplier;
                app.aero_pv = setup.aero_pv;
                app.DRS = setup.DRS;
                app.c_d_DRS = setup.c_d_DRS;
                
                %% Accumulator Parameters
                app.V_i = setup.V_i;
                app.Energy_i = setup.Energy_i;
                app.nZellen_Parallel = setup.nZellen_Parallel;
                app.nZellen_Reihe = setup.nZellen_Reihe;
                app.capacity_cell = setup.capacity_cell;  
            
                %% Environment Parameters
                app.t_L = setup.t_L;
                app.p_L = setup.p_L;
                app.R_L = setup.R_L;
                app.g = setup.g;
                          
            catch  
            end
            
        end

        % Button pushed function: StartSimulationButton
        function StartSimulationButtonPushed(app, event)
            [carDatafile, path] = uigetfile('*.mat');  %open a mat file
                
            % Gets the current value of the track and discipline
            % DropDown
            trackID = str2num(app.TrackDropDown.Value);
            disciplineID = str2num(app.DisciplineDropDown.Value);
           
            if (app.activatedCheckBox.Value)
               Vehiclesim_Endurance_GUI_Version(carDatafile, path, trackID, disciplineID, app.DropDown.Value, app.MinEditField.Value, app.StepsizeEditField.Value, app.NumStepsEditField.Value, app.StartSimulationButton, app.SimulationTextArea);
            else 
                Vehiclesim_Endurance_GUI_Version(carDatafile, path, trackID, disciplineID, 0, 0, 0, 1, app.StartSimulationButton, app.SimulationTextArea);  
            end
             
        end

        % Button pushed function: DrawPlot1RunButton
        function DrawPlot1RunButtonPushed(app, event)
            plotID = app.SelectPlotDropDown.Value;
            runID = app.RunnumberEditField.Value;
            % Plots the Plot with the given ID and the given result file
            PlotResults(app.file_run_1,plotID,runID,1);
        end

        % Button pushed function: Load1RunButton
        function Load1RunButtonPushed(app, event)
            try
                [app.file_run_1, ~] = uigetfile('*.mat');  %open a mat file
        
                app.result = load(app.file_run_1, '-mat');
                
                % Checks if the track file is 4 or 5 columns and read it acordingly          
                [rows, columns] = size(app.result.Track);
                
                if (columns == 4)
                    app.x_Track = app.result.Track(:,1);           % [m] X-Koordinate der Strecke
                    app.y_Track = app.result.Track(:,2);           % [m] Y-Koordinate der Strecke
                    app.z_Track = [];                              % [m] Z-Koordinate der Strecke
                    app.s = app.result.Track(:,3);                 % [m] Verlauf der Streckenlänge
                    app.R = app.result.Track(:,4);                 % [m] Kurvenradien
                else
                    app.x_Track = app.result.Track(:,1);           % [m] X-Koordinate der Strecke
                    app.y_Track = app.result.Track(:,2);           % [m] Y-Koordinate der Strecke
                    app.z_Track = app.result.Track(:,3);           % [m] Z-Koordinate der Strecke
                    app.s = app.result.Track(:,4);                 % [m] Verlauf der Streckenlänge
                    app.R = app.result.Track(:,5);                 % [m] Kurvenradien
                end          
                
                app.t_ges = app.result.t(end) *(22000/app.s(end));
                
                %% Set Result Labels
                % Lap time Label
                app.t_lapLabel.Text = "Lap time = " + app.result.t(end) + " s";
                % Endurance time Label
                app.EndurancetimeLabel.Text = "Endurance time = " + app.t_ges + " s";
                % Lap Distance Label
                app.LapDistanceLabel.Text = "Lap Distance = " + app.s(end) + " m";
                % E Accumulator w/o recu
                app.EnergyconsumptionWOrecuperationLabel.Text = "Energy consumption W/O recuperator =  " + (app.result.E_Akku(end) * (22000/app.s(end))) + " kWh";
                % E Accumulator w recu
                app.WrecuperationLabel.Text = "W recuperator =  " + (app.result.E_ges(end) * (22000/app.s(end))) + " kWh";
                
                % Computation Time
                 if (length(app.result.tEnd) > 1)
                     app.ComputationtimeLabel.Text = "Computation time = " + app.result.tEnd(end) + " s" + " (Ø " + app.result.tEnd(end)/length(app.result.tEnd) + " s)";
                 else
                     app.ComputationtimeLabel.Text = "Computation time = " + app.result.tEnd(end) + " s";
                 end
                
                % Powerlimit
                app.PowerLimitLabel.Text = "Power Limit = " + app.result.P_max(1)/1000 + " kW";
                
                 
                % Enables button for second run and draws panel
                app.DrawCustomPlotPanel.Visible = 'on';
                app.Load2RunButton.Enable = 'on';
                app.DrawPlot1RunButton.Enable = 'on';
                app.DrawSensitivityPlotButton.Enable = 'on';
                
                if (~isnan(app.file_run_2))
                    % Compares both runs in the third panel (Run_2-Run_1)
                    app.compareRuns;
                end
            catch
            end
        end

        % Button pushed function: Load2RunButton
        function Load2RunButtonPushed(app, event)
            try
                [app.file_run_2, ~] = uigetfile('*.mat');  %open a mat file
        
                app.result_2 = load(app.file_run_2, '-mat');
                
                % Checks if the track file is 4 or 5 columns and read it acordingly          
                [rows, columns] = size(app.result_2.Track);
                
                if (columns == 4)
                    app.x_Track_2 = app.result_2.Track(:,1);           % [m] X-Koordinate der Strecke
                    app.y_Track_2 = app.result_2.Track(:,2);           % [m] Y-Koordinate der Strecke
                    app.z_Track_2 = [];                              % [m] Z-Koordinate der Strecke
                    app.s_2 = app.result_2.Track(:,3);                 % [m] Verlauf der Streckenlänge
                    app.R_2 = app.result_2.Track(:,4);                 % [m] Kurvenradien
                else
                    app.x_Track_2 = app.result_2.Track(:,1);           % [m] X-Koordinate der Strecke
                    app.y_Track_2 = app.result_2.Track(:,2);           % [m] Y-Koordinate der Strecke
                    app.z_Track_2 = app.result_2.Track(:,3);           % [m] Z-Koordinate der Strecke
                    app.s_2 = app.result_2.Track(:,4);                 % [m] Verlauf der Streckenlänge
                    app.R_2 = app.result_2.Track(:,5);                 % [m] Kurvenradien
                end    
                
                app.t_ges_2 = app.result_2.t(end) *(22000/app.s_2(end));
                
                %% Set Result Labels
                % Lap time Label
                app.t_lapLabel_2.Text = "Lap time: " + app.result_2.t(end) + " s";
                % Endurance time Label
                app.EndurancetimeLabel_2.Text = "Endurance time: " + app.t_ges_2 + " s";
                % Lap Distance Label
                app.LapDistanceLabel_2.Text = "Lap Distance: " + app.s_2(end) + " m";
                % E Accumulator w/o recu
                app.EnergyconsumptionWOrecuperationLabel_2.Text = "Energy consumption W/O recuperation =  " + (app.result_2.E_Akku(end) * (22000/app.s_2(end))) + " kWh";
                % E Accumulator w recu
                app.WrecuperationLabel_2.Text = "W recuperator =  " + (app.result_2.E_ges(end) * (22000/app.s_2(end))) + " kWh";

                % Computation Time
                 if (length(app.result_2.tEnd) > 1)
                     app.ComputationtimeLabel_2.Text = "Computation time = " + app.result_2.tEnd(end) + " s" + " (Ø " + app.result_2.tEnd(end)/length(app.result_2.tEnd) + " s)";
                 else
                     app.ComputationtimeLabel_2.Text = "Computation time = " + app.result_2.tEnd(end) + " s";
                 end
                 
                % Powerlimit
                app.PowerLimitLabel_2.Text = "Power Limit = " + app.result_2.P_max(1)/1000 + " kW";
                
                % Compares both runs in the third panel (Run_2-Run_1)
                app.compareRuns;
                
                app.DrawPlot2RunButton.Enable = 'on';
                app.CompareRunPlotsButton.Enable = 'on';
            catch
            end 
        end

        % Button pushed function: DrawPlot2RunButton
        function DrawPlot2RunButtonPushed(app, event)
            % Plots the Plot with the given ID and the given result file
            plotID = app.SelectPlotDropDown.Value;
            runID = app.RunnumberEditField.Value;
            PlotResults(app.file_run_2,plotID,runID,2); % Plots the Plot with the given ID and the given result file
        end

        % Button pushed function: CompareRunPlotsButton
        function CompareRunPlotsButtonPushed(app, event)
            plotID = str2num(app.SelectPlotDropDown_2.Value);
            % Plots the Plot with the given ID and the given result file
            runID = app.RunnumberEditField.Value;
            PlotResults(app.file_run_1,plotID, runID,1,app.file_run_2);
        end

        % Value changed function: TrackDropDown
        function TrackDropDownValueChanged(app, event)
            % Change currently rendered track
            renderSelectedTrack(app);           
        end

        % Value changed function: activatedCheckBox
        function activatedCheckBoxValueChanged(app, event)
            value = app.activatedCheckBox.Value;
            
            if (app.activatedCheckBox.Value)
                app.DropDown.Enable = true;
                app.DropDownLabel.Enable = true;
                app.MinEditField.Enable = true;
                app.MinEditFieldLabel.Enable = true;
                app.NumStepsEditField.Enable = true;
                app.NumStepsEditFieldLabel.Enable = true;
                app.StepsizeEditField.Enable = true;
                app.StepsizeEditFieldLabel.Enable = true;
            else
                app.DropDown.Enable = false;
                app.DropDownLabel.Enable = false;
                app.MinEditField.Enable = false;
                app.MinEditFieldLabel.Enable = false;
                app.NumStepsEditField.Enable = false;
                app.NumStepsEditFieldLabel.Enable = false;
                app.StepsizeEditField.Enable = false;
                app.StepsizeEditFieldLabel.Enable = false;
            end
        end

        % Button pushed function: DrawSensitivityPlotButton
        function DrawSensitivityPlotButtonPushed(app, event)
            PlotSensitivity(app.file_run_1, app.xaxisDropDown_2.Value, app.yaxisDropDown_2.Value, app.DotsCheckBox.Value);
        end

        % Button pushed function: DailyUpdateFeatureRequestsButton
        function DailyUpdateFeatureRequestsButtonPushed(app, event)
            web('https://hochschulestralsundde-my.sharepoint.com/:w:/g/personal/racing_hochschule-stralsund_de/EWT1kDvSA0RBt-WC8mDQciYBJrAmHY1KO04tbeP0GFqoDQ?e=MaODu7');
        end

        % Image clicked function: Image2, Image2_2
        function Image2Clicked(app, event)
            web('https://balticracing.hochschule-stralsund.de/');
        end

        % Image clicked function: Image, Image_2
        function ImageClicked(app, event)
            web('https://www.hochschule-stralsund.de/');
        end

        % Button pushed function: ContactmeButton
        function ContactmeButtonPushed(app, event)
            web mailto:e.dornieden@web.de;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.LaptimeSimulationUIFigure = uifigure('Visible', 'off');
            app.LaptimeSimulationUIFigure.Name = 'Laptime Simulation';
            app.LaptimeSimulationUIFigure.CloseRequestFcn = createCallbackFcn(app, @LaptimeSimulationUIFigureCloseRequest, true);
            app.TabGroup = uitabgroup(app.LaptimeSimulationUIFigure);
            app.HowtoTab = uitab(app.TabGroup);
            app.HowtoTab.Title = 'How-to';
            app.HowToLaptimeSimulationPanel = uipanel(app.HowtoTab);
            app.HowToLaptimeSimulationPanel.Title = 'How-To Laptime Simulation';
            app.EditthecarsetupintheCarSetuptab2SaveCarSetupLabel = uilabel(app.HowToLaptimeSimulationPanel);
            app.EditthecarsetupintheCarSetuptab2SaveCarSetupLabel.Text = {'1. Load a Setup or use the baseline setup in the ''Car Setup'' tab'; '2. Edit the car setup if needed'; '3. Save the car setup'; '4. In the ''Simulation Setup'' tab change the desired mission and activate sensitivity analysis if needed'; '5. Click on the ''Start Simulation'' button and the select the desired setup file'; '6. The simulation will start and a ''_result'' file with the same name as the setup will be created'; '7. The result file can now be opened in the ''Simulation Results'' tab there you can load up to two result'; 'files and draw plots for both of them and compare them'};
            app.DailyUpdateFeatureRequestsButton = uibutton(app.HowtoTab, 'push');
            app.DailyUpdateFeatureRequestsButton.ButtonPushedFcn = createCallbackFcn(app, @DailyUpdateFeatureRequestsButtonPushed, true);
            app.DailyUpdateFeatureRequestsButton.Text = 'Daily Update / Feature Requests';
            app.Copyright2021BalticRacingbyEricDorniedenLabel = uilabel(app.HowtoTab);
            app.Copyright2021BalticRacingbyEricDorniedenLabel.Text = 'Copyright © 2021 Baltic Racing by Eric Dornieden';
            app.Image = uiimage(app.HowtoTab);
            app.Image.ImageClickedFcn = createCallbackFcn(app, @ImageClicked, true);
            app.Image.ImageSource = 'Logo_Ship_HKS_Ranged.png';
            app.Image2 = uiimage(app.HowtoTab);
            app.Image2.ImageClickedFcn = createCallbackFcn(app, @Image2Clicked, true);
            app.Image2.ImageSource = 'balticracing_logo_transparent.png';
            app.ContactmeButton = uibutton(app.HowtoTab, 'push');
            app.ContactmeButton.ButtonPushedFcn = createCallbackFcn(app, @ContactmeButtonPushed, true);
            app.ContactmeButton.Text = 'Contact me';
            app.PatchNotesPanel = uipanel(app.HowtoTab);
            app.PatchNotesPanel.Title = 'Patch Notes';
            app.TextArea = uitextarea(app.PatchNotesPanel);
            app.TextArea.Editable = 'off';
            app.TextArea.Value = {'-----------------------------------------------------------------------------------------------------------------------------------'; '-----------------------------------------------------Laptime Simulation----------------------------------------------------'; '-----------------------------------------------------------------------------------------------------------------------------------'; ''; ''; '-----------------------------------------------------------------------------------------------------------------------------------'; '-------------------------------------------------------------V1.0.0-------------------------------------------------------------'; '-----------------------------------------------------------------------------------------------------------------------------------'; ''; 'Release V1.0.0 (Inital release)'; 'Baltic Racing Laptime Simulation'; ''; 'Features:'; '- Endurance Simulation'; '- AutoX Simulation'; '- Acceleration Simulation'; '- Plot results over track'; '- Customisable Setup with many features like custom DRS'; ''; 'Known-Bugs:'; '- Test track not working'; '- Draw custom plot not yet implemented'; ''; 'Planned Features/Improvments:'; '- Improved Brake implementation'; '- Tooltips for all buttons and setup parameters'; ''; '-----------------------------------------------------------------------------------------------------------------------------------'; ''; '-----------------------------------------------------------------------------------------------------------------------------------'; ''; '2021 Baltic Racing developed by: '; ''; 'Lead Developer:'; 'Eric Dornieden (e.dornieden@web.de)'; ''; 'Lukas Deeken'; 'Danesh Umarani'; 'Laura Noll'; ''; 'Initial version of the simulation itself written by Sven Weißhaupt (Alumnus Baltic Racing)'; ''; '-----------------------------------------------------------------------------------------------------------------------------------'; ''; '-----------------------------------------------------------------------------------------------------------------------------------'};
            app.CarSetupTab = uitab(app.TabGroup);
            app.CarSetupTab.Title = 'Car Setup';
            app.Copyright2021BalticRacingbyEricDorniedenLabel_2 = uilabel(app.CarSetupTab);
            app.Copyright2021BalticRacingbyEricDorniedenLabel_2.Text = 'Copyright © 2021 Baltic Racing by Eric Dornieden';
            app.Image2_2 = uiimage(app.CarSetupTab);
            app.Image2_2.ImageClickedFcn = createCallbackFcn(app, @Image2Clicked, true);
            app.Image2_2.ImageSource = 'balticracing_logo_transparent.png';
            app.EditSetupPanel = uipanel(app.CarSetupTab);
            app.EditSetupPanel.Title = 'Edit Setup';
            app.CarParametersPanel = uipanel(app.EditSetupPanel);
            app.CarParametersPanel.Title = 'Car Parameters';
            app.SuspensionButton = uibutton(app.CarParametersPanel, 'push');
            app.SuspensionButton.ButtonPushedFcn = createCallbackFcn(app, @SuspensionButtonPushed, true);
            app.SuspensionButton.Text = 'Suspension';
            app.ChassisButton = uibutton(app.CarParametersPanel, 'push');
            app.ChassisButton.ButtonPushedFcn = createCallbackFcn(app, @ChassisButtonPushed, true);
            app.ChassisButton.Text = 'Chassis';
            app.DrivetrainButton = uibutton(app.CarParametersPanel, 'push');
            app.DrivetrainButton.ButtonPushedFcn = createCallbackFcn(app, @DrivetrainButtonPushed, true);
            app.DrivetrainButton.Text = 'Drivetrain';
            app.AerodynamikButton = uibutton(app.CarParametersPanel, 'push');
            app.AerodynamikButton.ButtonPushedFcn = createCallbackFcn(app, @AerodynamikButtonPushed, true);
            app.AerodynamikButton.Text = 'Aerodynamik';
            app.AccumulatorButton = uibutton(app.CarParametersPanel, 'push');
            app.AccumulatorButton.ButtonPushedFcn = createCallbackFcn(app, @AccumulatorButtonPushed, true);
            app.AccumulatorButton.Text = 'Accumulator';
            app.EnviromentPanel = uipanel(app.EditSetupPanel);
            app.EnviromentPanel.Title = 'Enviroment';
            app.EnvironmentButton = uibutton(app.EnviromentPanel, 'push');
            app.EnvironmentButton.ButtonPushedFcn = createCallbackFcn(app, @EnvironmentButtonPushed, true);
            app.EnvironmentButton.Text = 'Environment';
            app.LoadButton = uibutton(app.EditSetupPanel, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load';
            app.SaveButton = uibutton(app.EditSetupPanel, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.Image_2 = uiimage(app.CarSetupTab);
            app.Image_2.ImageClickedFcn = createCallbackFcn(app, @ImageClicked, true);
            app.Image_2.ImageSource = 'Logo_Ship_HKS_Ranged.png';
            app.SimulationSetupTab = uitab(app.TabGroup);
            app.SimulationSetupTab.Title = 'Simulation Setup';
            app.UIAxes2 = uiaxes(app.SimulationSetupTab);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, '')
            ylabel(app.UIAxes2, '')
            app.StartSimulationButton = uibutton(app.SimulationSetupTab, 'push');
            app.StartSimulationButton.ButtonPushedFcn = createCallbackFcn(app, @StartSimulationButtonPushed, true);
            app.StartSimulationButton.Text = 'Start Simulation';
            app.SensitivityAnalysisPanel = uipanel(app.SimulationSetupTab);
            app.SensitivityAnalysisPanel.Title = 'Sensitivity Analysis';
            app.DropDownLabel = uilabel(app.SensitivityAnalysisPanel);
            app.DropDownLabel.Enable = 'off';
            app.DropDownLabel.Text = 'Drop Down';
            app.DropDown = uidropdown(app.SensitivityAnalysisPanel);
            app.DropDown.Enable = 'off';
            app.MinEditFieldLabel = uilabel(app.SensitivityAnalysisPanel);
            app.MinEditFieldLabel.Enable = 'off';
            app.MinEditFieldLabel.Text = 'Min';
            app.MinEditField = uieditfield(app.SensitivityAnalysisPanel, 'numeric');
            app.MinEditField.Enable = 'off';
            app.StepsizeEditFieldLabel = uilabel(app.SensitivityAnalysisPanel);
            app.StepsizeEditFieldLabel.Enable = 'off';
            app.StepsizeEditFieldLabel.Text = 'Step size';
            app.StepsizeEditField = uieditfield(app.SensitivityAnalysisPanel, 'numeric');
            app.StepsizeEditField.Enable = 'off';
            app.activatedCheckBox = uicheckbox(app.SensitivityAnalysisPanel);
            app.activatedCheckBox.ValueChangedFcn = createCallbackFcn(app, @activatedCheckBoxValueChanged, true);
            app.activatedCheckBox.Text = 'activated';
            app.NumStepsEditFieldLabel = uilabel(app.SensitivityAnalysisPanel);
            app.NumStepsEditFieldLabel.Enable = 'off';
            app.NumStepsEditFieldLabel.Text = 'Num Steps';
            app.NumStepsEditField = uieditfield(app.SensitivityAnalysisPanel, 'numeric');
            app.NumStepsEditField.Limits = [1 Inf];
            app.NumStepsEditField.Enable = 'off';
            app.NumStepsEditField.Value = 1;
            app.ScenarioPanel = uipanel(app.SimulationSetupTab);
            app.ScenarioPanel.Title = 'Scenario';
            app.TrackDropDownLabel = uilabel(app.ScenarioPanel);
            app.TrackDropDownLabel.Text = 'Track';
            app.TrackDropDown = uidropdown(app.ScenarioPanel);
            app.TrackDropDown.Items = {'FSG Endurance', 'FSG AutoX', 'Testtrack', 'Accel'};
            app.TrackDropDown.ItemsData = {'1', '2', '3', '4'};
            app.TrackDropDown.ValueChangedFcn = createCallbackFcn(app, @TrackDropDownValueChanged, true);
            app.TrackDropDown.Value = '1';
            app.DisciplineDropDownLabel = uilabel(app.ScenarioPanel);
            app.DisciplineDropDownLabel.Text = 'Discipline';
            app.DisciplineDropDown = uidropdown(app.ScenarioPanel);
            app.DisciplineDropDown.Items = {'Endurance', 'AutoX'};
            app.DisciplineDropDown.ItemsData = {'1', '2'};
            app.DisciplineDropDown.Value = '1';
            app.LengthLabel = uilabel(app.SimulationSetupTab);
            app.LengthLabel.Text = 'Length';
            app.SimulationStatusPanel = uipanel(app.SimulationSetupTab);
            app.SimulationStatusPanel.Title = 'Simulation Status';
            app.SimulationTextArea = uitextarea(app.SimulationStatusPanel);
            app.SimulationTextArea.Editable = 'off';
            app.SimulationTextArea.Value = {'The status of the current simulation is displayed here.'};
            app.Copyright2021BalticRacingbyEricDorniedenLabel_3 = uilabel(app.SimulationSetupTab);
            app.Copyright2021BalticRacingbyEricDorniedenLabel_3.Text = 'Copyright © 2021 Baltic Racing by Eric Dornieden';
            app.SimulationResultsTab = uitab(app.TabGroup);
            app.SimulationResultsTab.Title = 'Simulation Results';
            app.Load1RunButton = uibutton(app.SimulationResultsTab, 'push');
            app.Load1RunButton.ButtonPushedFcn = createCallbackFcn(app, @Load1RunButtonPushed, true);
            app.Load1RunButton.Text = 'Load 1. Run';
            app.Results1RunPanel = uipanel(app.SimulationResultsTab);
            app.Results1RunPanel.Title = 'Results 1. Run';
            app.t_lapLabel = uilabel(app.Results1RunPanel);
            app.t_lapLabel.Text = 'Lap time =';
            app.EndurancetimeLabel = uilabel(app.Results1RunPanel);
            app.EndurancetimeLabel.Text = 'Endurance time =';
            app.LapDistanceLabel = uilabel(app.Results1RunPanel);
            app.LapDistanceLabel.Text = 'Lap Distance =';
            app.EnergyconsumptionWOrecuperationLabel = uilabel(app.Results1RunPanel);
            app.EnergyconsumptionWOrecuperationLabel.Text = 'Energy consumption W/O recuperation =';
            app.WrecuperationLabel = uilabel(app.Results1RunPanel);
            app.WrecuperationLabel.Text = 'W recuperation =';
            app.ComputationtimeLabel = uilabel(app.Results1RunPanel);
            app.ComputationtimeLabel.Text = 'Computation time:';
            app.PowerLimitLabel = uilabel(app.Results1RunPanel);
            app.PowerLimitLabel.Text = 'Power Limit =';
            app.Load2RunButton = uibutton(app.SimulationResultsTab, 'push');
            app.Load2RunButton.ButtonPushedFcn = createCallbackFcn(app, @Load2RunButtonPushed, true);
            app.Load2RunButton.Text = 'Load 2. Run';
            app.DrawCustomPlotPanel = uipanel(app.SimulationResultsTab);
            app.DrawCustomPlotPanel.Title = 'Draw Custom Plot';
            app.UIAxes = uiaxes(app.DrawCustomPlotPanel);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.xaxisDropDownLabel = uilabel(app.DrawCustomPlotPanel);
            app.xaxisDropDownLabel.Text = 'x-axis';
            app.xaxisDropDown = uidropdown(app.DrawCustomPlotPanel);
            app.yaxisDropDownLabel = uilabel(app.DrawCustomPlotPanel);
            app.yaxisDropDownLabel.Text = 'y-axis';
            app.yaxisDropDown = uidropdown(app.DrawCustomPlotPanel);
            app.y_2axisDropDownLabel = uilabel(app.DrawCustomPlotPanel);
            app.y_2axisDropDownLabel.Text = 'y_2-axis';
            app.y_2axisDropDown = uidropdown(app.DrawCustomPlotPanel);
            app.zaxisDropDownLabel = uilabel(app.DrawCustomPlotPanel);
            app.zaxisDropDownLabel.Text = 'z-axis';
            app.zaxisDropDown = uidropdown(app.DrawCustomPlotPanel);
            app.DrawPlotButton = uibutton(app.DrawCustomPlotPanel, 'push');
            app.DrawPlotButton.Text = 'Draw Plot';
            app.DrawPlotinnewWindowButton = uibutton(app.DrawCustomPlotPanel, 'push');
            app.DrawPlotinnewWindowButton.Text = 'Draw Plot in new Window';
            app.Results2RunPanel = uipanel(app.SimulationResultsTab);
            app.Results2RunPanel.Title = ' Results 2. Run';
            app.t_lapLabel_2 = uilabel(app.Results2RunPanel);
            app.t_lapLabel_2.Text = 'Lap time =';
            app.EndurancetimeLabel_2 = uilabel(app.Results2RunPanel);
            app.EndurancetimeLabel_2.Text = 'Endurance time =';
            app.LapDistanceLabel_2 = uilabel(app.Results2RunPanel);
            app.LapDistanceLabel_2.Text = 'Lap Distance =';
            app.EnergyconsumptionWOrecuperationLabel_2 = uilabel(app.Results2RunPanel);
            app.EnergyconsumptionWOrecuperationLabel_2.Text = 'Energy consumption W/O recuperation = ';
            app.WrecuperationLabel_2 = uilabel(app.Results2RunPanel);
            app.WrecuperationLabel_2.Text = 'W recuperation =';
            app.ComputationtimeLabel_2 = uilabel(app.Results2RunPanel);
            app.ComputationtimeLabel_2.Text = 'Computation time:';
            app.PowerLimitLabel_2 = uilabel(app.Results2RunPanel);
            app.PowerLimitLabel_2.Text = 'Power Limit =';
            app.DeltaSecondRunFirstRunPanel = uipanel(app.SimulationResultsTab);
            app.DeltaSecondRunFirstRunPanel.Title = 'Delta (Second Run - First Run)';
            app.t_lapLabel_3 = uilabel(app.DeltaSecondRunFirstRunPanel);
            app.t_lapLabel_3.Text = 'Lap time =';
            app.EndurancetimeLabel_3 = uilabel(app.DeltaSecondRunFirstRunPanel);
            app.EndurancetimeLabel_3.Text = 'Endurance time =';
            app.LapDistanceLabel_3 = uilabel(app.DeltaSecondRunFirstRunPanel);
            app.LapDistanceLabel_3.Text = 'Lap Distance =';
            app.EnergyconsumptionWOrecuperationLabel_3 = uilabel(app.DeltaSecondRunFirstRunPanel);
            app.EnergyconsumptionWOrecuperationLabel_3.Text = 'Energy consumption W/O recuperation =';
            app.WrecuperationLabel_3 = uilabel(app.DeltaSecondRunFirstRunPanel);
            app.WrecuperationLabel_3.Text = ' W recuperation =';
            app.SensitivityAnalysisPanel_2 = uipanel(app.SimulationResultsTab);
            app.SensitivityAnalysisPanel_2.Title = 'Sensitivity Analysis';
            app.xaxisDropDown_2Label = uilabel(app.SensitivityAnalysisPanel_2);
            app.xaxisDropDown_2Label.Text = 'x-axis';
            app.xaxisDropDown_2 = uidropdown(app.SensitivityAnalysisPanel_2);
            app.xaxisDropDown_2.ItemsData = {'1', '2', '3'};
            app.xaxisDropDown_2.Value = '1';
            app.yaxisDropDown_2Label = uilabel(app.SensitivityAnalysisPanel_2);
            app.yaxisDropDown_2Label.Text = 'y-axis';
            app.yaxisDropDown_2 = uidropdown(app.SensitivityAnalysisPanel_2);
            app.yaxisDropDown_2.ItemsData = {'1', '2', '3'};
            app.yaxisDropDown_2.Value = '1';
            app.DrawSensitivityPlotButton = uibutton(app.SensitivityAnalysisPanel_2, 'push');
            app.DrawSensitivityPlotButton.ButtonPushedFcn = createCallbackFcn(app, @DrawSensitivityPlotButtonPushed, true);
            app.DrawSensitivityPlotButton.Text = 'Draw Sensitivity Plot';
            app.DotsCheckBox = uicheckbox(app.SensitivityAnalysisPanel_2);
            app.DotsCheckBox.Text = 'Dots';
            app.PlotResultsPanel = uipanel(app.SimulationResultsTab);
            app.PlotResultsPanel.Title = 'Plot Results';
            app.SelectPlotDropDownLabel = uilabel(app.PlotResultsPanel);
            app.SelectPlotDropDownLabel.Text = 'Select Plot';
            app.SelectPlotDropDown = uidropdown(app.PlotResultsPanel);
            app.SelectPlotDropDown.Items = {'Cell Current', 'Slip', 'Power Map (P_M)', 'RPM', 'Brake', 'Tire Load Front', 'Tire Load Rear', 'Axle Load', 'Dynamic Tire Radius', 'Lateral Forces Front', 'Lateral Forces Rear', 'Conti Plot', 'Tractive Power Rear', 'Traction limit', 'Draw Track', 'Used Energy', 'g-g-V Plot', 'Velocity m/s', 'Velocity km/h', 'Aero Tire Load', 'Motor Efficency', 'Accelerations m/s', 'Power Map (P_el)', 'P_el', 'Lateral Acceleration Track', 'Longitudinal Acceleration Track', 'Acceleration g', 'Accelerations Plot', 'Virtual Current Cellpack', 'Accumulator Voltage', 'P_tractive', 'Velocity Map', 'DRS Status Map', 'Track Analyzer', 'RPM Map', 'Torque Map', 'Torque Plot'};
            app.SelectPlotDropDown.ItemsData = {'1', '2', '3', '4', '5', '6', '7', '8', '9', '10', '11', '12', '13', '14', '15', '16', '17', '18', '19', '20', '21', '22', '23', '24', '25', '26', '27', '28', '29', '30', '31', '32', '33', '34', '35', '36', '37'};
            app.SelectPlotDropDown.Value = '1';
            app.DrawPlot1RunButton = uibutton(app.PlotResultsPanel, 'push');
            app.DrawPlot1RunButton.ButtonPushedFcn = createCallbackFcn(app, @DrawPlot1RunButtonPushed, true);
            app.DrawPlot1RunButton.Text = 'Draw Plot 1. Run';
            app.DrawPlot2RunButton = uibutton(app.PlotResultsPanel, 'push');
            app.DrawPlot2RunButton.ButtonPushedFcn = createCallbackFcn(app, @DrawPlot2RunButtonPushed, true);
            app.DrawPlot2RunButton.Text = 'Draw Plot 2. Run';
            app.RunnumberEditFieldLabel = uilabel(app.PlotResultsPanel);
            app.RunnumberEditFieldLabel.Text = 'Run number';
            app.RunnumberEditField = uieditfield(app.PlotResultsPanel, 'numeric');
            app.RunnumberEditField.Limits = [1 Inf];
            app.RunnumberEditField.Value = 1;
            app.CompareRunsPanel = uipanel(app.SimulationResultsTab);
            app.CompareRunsPanel.Title = 'Compare Runs';
            app.CompareRunPlotsButton = uibutton(app.CompareRunsPanel, 'push');
            app.CompareRunPlotsButton.ButtonPushedFcn = createCallbackFcn(app, @CompareRunPlotsButtonPushed, true);
            app.CompareRunPlotsButton.Text = 'Compare Run Plots';
            app.SelectPlotDropDown_3Label = uilabel(app.CompareRunsPanel);
            app.SelectPlotDropDown_3Label.Text = 'Select Plot';
            app.SelectPlotDropDown_2 = uidropdown(app.CompareRunsPanel);
            app.SelectPlotDropDown_2.Items = {'RPM', 'Downforce', 'Torque', 'Power', 'Drag', 'Driving Resistance', 'Velocity m/s', 'Velocity km/h', 'Cellcapacity', 'Motor efficiency', 'Accelerations g'};
            app.SelectPlotDropDown_2.ItemsData = {'1', '2', '3', '4', '5', '6', '7', '8', '9', '10', '11'};
            app.SelectPlotDropDown_2.Value = '1';
            app.Copyright2021BalticRacingbyEricDorniedenLabel_4 = uilabel(app.SimulationResultsTab);
            app.Copyright2021BalticRacingbyEricDorniedenLabel_4.Text = 'Copyright © 2021 Baltic Racing by Eric Dornieden';
            % Show the figure after all components are created
            app.LaptimeSimulationUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = laptime_Simulation_GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.LaptimeSimulationUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.LaptimeSimulationUIFigure)
        end
    end
end